classdef EnvironmentandPerturbations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EnvironmentPerturbationsPanel  matlab.ui.container.Panel
        AtmosphericDragLabel           matlab.ui.control.Label
        SolarRadiationPressureLabel    matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        Switch_2                       matlab.ui.control.Switch
        ConfirmButton                  matlab.ui.control.Button
        GravityModelOrderEGM2008EditFieldLabel  matlab.ui.control.Label
        GravityModelOrderEGM2008EditField  matlab.ui.control.EditField
        ThirdBodyPerturbationLabel     matlab.ui.control.Label
        SunSwitchLabel                 matlab.ui.control.Label
        SunSwitch                      matlab.ui.control.Switch
        JpiterSwitchLabel              matlab.ui.control.Label
        JpiterSwitch                   matlab.ui.control.Switch
        MoonSwitchLabel                matlab.ui.control.Label
        MoonSwitch                     matlab.ui.control.Switch
    end

    
    properties (Access = public)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            srp_enable = 0;
            drag_enable = 0;
            degree=100;
            assignin('base','SRP_enable',srp_enable)
            assignin('base','Drag_enable',drag_enable)
            assignin('base','DegreeGrav',degree)
            assignin('base','Jupiter',0)
            assignin('base','Moon',0)
            assignin('base','Sun',0)
            
        end

        % Callback function
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            if strcmp(value,'On')
                srp_enable = 1;  
            else
                srp_enable = 0;
            end             
            
            assignin('base','SRP_enable',srp_enable)
            
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value2 = app.Switch_2.Value;
            
            if strcmp(value2,'On')
                drag_enable = 1;
            else
                drag_enable = 0;
            end
            
            assignin('base','Drag_enable',drag_enable)
            
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
          
            delete(app.UIFigure)
            
        end

        % Value changed function: GravityModelOrderEGM2008EditField
        function GravityModelOrderEGM2008EditFieldValueChanged(app, event)
            degree = app.GravityModelOrderEGM2008EditField.Value;
            
            assignin('base','DegreeGrav',str2double(degree)) 
            
        end

        % Value changed function: SunSwitch
        function SunSwitchValueChanged(app, event)
            value = app.SunSwitch.Value;
            
            if strcmp(value,'On')
                sun = 1;
            else
                sun = 0;
            end
            
            assignin('base','Sun',sun)
        end

        % Value changed function: MoonSwitch
        function MoonSwitchValueChanged(app, event)
            value = app.MoonSwitch.Value;
            
            if strcmp(value,'On')
                moon = 1;
            else
                moon = 0;
            end
            
            assignin('base','Moon',moon)
        end

        % Value changed function: JpiterSwitch
        function JpiterSwitchValueChanged(app, event)
            value = app.JpiterSwitch.Value;
            
            if strcmp(value,'On')
                jupiter = 1;
            else
                jupiter = 0;
            end
            
            assignin('base','Jupiter',jupiter)
        end

        % Value changed function: Switch
        function SwitchValueChanged2(app, event)
            value2 = app.Switch.Value;
            
            if strcmp(value2,'On')
                SRP_enable = 1;
            else
                SRP_enable = 0;
            end
            
            assignin('base','SRP_enable',SRP_enable)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EnvironmentPerturbationsPanel = uipanel(app.UIFigure);
            app.EnvironmentPerturbationsPanel.Title = 'Environment & Perturbations';
            app.AtmosphericDragLabel = uilabel(app.EnvironmentPerturbationsPanel);
            app.AtmosphericDragLabel.Text = 'Atmospheric Drag:';
            app.SolarRadiationPressureLabel = uilabel(app.EnvironmentPerturbationsPanel);
            app.SolarRadiationPressureLabel.Text = 'Solar Radiation Pressure:';
            app.Switch = uiswitch(app.EnvironmentPerturbationsPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged2, true);
            app.Switch.Value = 'On';
            app.Switch_2 = uiswitch(app.EnvironmentPerturbationsPanel, 'slider');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'On';
            app.ConfirmButton = uibutton(app.EnvironmentPerturbationsPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.GravityModelOrderEGM2008EditFieldLabel = uilabel(app.EnvironmentPerturbationsPanel);
            app.GravityModelOrderEGM2008EditFieldLabel.Text = 'Gravity Model Order (EGM-2008)';
            app.GravityModelOrderEGM2008EditField = uieditfield(app.EnvironmentPerturbationsPanel, 'text');
            app.GravityModelOrderEGM2008EditField.ValueChangedFcn = createCallbackFcn(app, @GravityModelOrderEGM2008EditFieldValueChanged, true);
            app.GravityModelOrderEGM2008EditField.Value = '120';
            app.ThirdBodyPerturbationLabel = uilabel(app.EnvironmentPerturbationsPanel);
            app.ThirdBodyPerturbationLabel.Text = 'Third-Body Perturbation';
            app.SunSwitchLabel = uilabel(app.EnvironmentPerturbationsPanel);
            app.SunSwitchLabel.Text = 'Sun';
            app.SunSwitch = uiswitch(app.EnvironmentPerturbationsPanel, 'slider');
            app.SunSwitch.ValueChangedFcn = createCallbackFcn(app, @SunSwitchValueChanged, true);
            app.JpiterSwitchLabel = uilabel(app.EnvironmentPerturbationsPanel);
            app.JpiterSwitchLabel.Text = 'Júpiter';
            app.JpiterSwitch = uiswitch(app.EnvironmentPerturbationsPanel, 'slider');
            app.JpiterSwitch.ValueChangedFcn = createCallbackFcn(app, @JpiterSwitchValueChanged, true);
            app.MoonSwitchLabel = uilabel(app.EnvironmentPerturbationsPanel);
            app.MoonSwitchLabel.Text = 'Moon';
            app.MoonSwitch = uiswitch(app.EnvironmentPerturbationsPanel, 'slider');
            app.MoonSwitch.ValueChangedFcn = createCallbackFcn(app, @MoonSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EnvironmentandPerturbations

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end