classdef F_SS_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SaturationcountsLabel          matlab.ui.control.Label
        FaceCoordinatesLabel           matlab.ui.control.Label
        DirectionCossineMatrixfrombodycoordinatesLabel  matlab.ui.control.Label
        UnitVectorLabel                matlab.ui.control.Label
        FineSunSensorSettingsLabel     matlab.ui.control.Label
        SensitivityLSBradEditFieldLabel  matlab.ui.control.Label
        SensitivityLSBradEditField     matlab.ui.control.NumericEditField
        UpperLimitEditFieldLabel       matlab.ui.control.Label
        UpperLimitEditField            matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel       matlab.ui.control.Label
        LowerLimitEditField            matlab.ui.control.NumericEditField
        UpdateRatesEditFieldLabel      matlab.ui.control.Label
        UpdateRatesEditField           matlab.ui.control.NumericEditField
        XdirectionEditFieldLabel       matlab.ui.control.Label
        XdirectionEditField            matlab.ui.control.NumericEditField
        YdirectionEditFieldLabel       matlab.ui.control.Label
        YdirectionEditField            matlab.ui.control.NumericEditField
        ZdirectionEditFieldLabel       matlab.ui.control.Label
        ZdirectionEditField            matlab.ui.control.NumericEditField
        DCM_1_1EditFieldLabel          matlab.ui.control.Label
        DCM_1_1EditField               matlab.ui.control.NumericEditField
        DCM_1_2EditFieldLabel          matlab.ui.control.Label
        DCM_1_2EditField               matlab.ui.control.NumericEditField
        DCM_1_3EditFieldLabel          matlab.ui.control.Label
        DCM_1_3EditField               matlab.ui.control.NumericEditField
        DCM_2_1EditFieldLabel          matlab.ui.control.Label
        DCM_2_1EditField               matlab.ui.control.NumericEditField
        DCM_2_2EditFieldLabel          matlab.ui.control.Label
        DCM_2_2EditField               matlab.ui.control.NumericEditField
        DCM_2_3EditFieldLabel          matlab.ui.control.Label
        DCM_2_3EditField               matlab.ui.control.NumericEditField
        DCM_3_1EditFieldLabel          matlab.ui.control.Label
        DCM_3_1EditField               matlab.ui.control.NumericEditField
        DCM_3_2EditFieldLabel          matlab.ui.control.Label
        DCM_3_2EditField               matlab.ui.control.NumericEditField
        DCM_3_3EditFieldLabel          matlab.ui.control.Label
        DCM_3_3EditField               matlab.ui.control.NumericEditField
        NoiseStdDeviationradEditFieldLabel  matlab.ui.control.Label
        NoiseStdDeviationradEditField  matlab.ui.control.NumericEditField
        FieldofViewEditFieldLabel      matlab.ui.control.Label
        FieldofViewEditField           matlab.ui.control.NumericEditField
        ConfirmButton                  matlab.ui.control.Button
        KgLabel                        matlab.ui.control.Label
        cmLabel                        matlab.ui.control.Label
        cmLabel_2                      matlab.ui.control.Label
        cmLabel_3                      matlab.ui.control.Label
        WLabel                         matlab.ui.control.Label
        LengthEditField                matlab.ui.control.NumericEditField
        LengthEditFieldLabel           matlab.ui.control.Label
        MassEditFieldLabel             matlab.ui.control.Label
        MassEditField                  matlab.ui.control.NumericEditField
        WidthEditFieldLabel            matlab.ui.control.Label
        WidthEditField                 matlab.ui.control.NumericEditField
        HeightEditFieldLabel           matlab.ui.control.Label
        HeightEditField                matlab.ui.control.NumericEditField
        PowerEditFieldLabel            matlab.ui.control.Label
        PowerEditField                 matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        num
        
        face_x;
        face_y;
        face_z;
        
        dcm_1_1
        dcm_1_2
        dcm_1_3
        dcm_2_1
        dcm_2_2
        dcm_2_3
        dcm_3_1
        dcm_3_2
        dcm_3_3
        mass_fss;
        m=0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, val, flag)
            app.num = val;
            aux = rem(floor(flag/10^(str2double(app.num)-1)),10);
            
            textLabel = sprintf('Fine Sun Sensor %s - Settings', app.num);
            app.FineSunSensorSettingsLabel.Text = textLabel;
            
            if aux == 1
                sensitivity_F_SS = evalin('base',['Sensitivity_F_SS_',app.num]);
                app.SensitivityLSBradEditField.Value = sensitivity_F_SS;
                
                upper_F_SS = evalin('base',['Upper_F_SS_',app.num]);
                app.UpperLimitEditField.Value = upper_F_SS;
                
                lower_F_SS = evalin('base',['Lower_F_SS_',app.num]);
                app.LowerLimitEditField.Value = lower_F_SS;
                
                noise_F_SS = evalin('base',['Noise_F_SS_',app.num]);
                app.NoiseStdDeviationradEditField.Value = noise_F_SS;
                
                rate_F_SS = evalin('base',['Rate_F_SS_',app.num]);
                app.UpdateRatesEditField.Value = rate_F_SS;
                
                fov_F_SS = evalin('base',['FoV_F_SS_',app.num]);
                app.FieldofViewEditField.Value = fov_F_SS;
                
                aux = evalin('base',['Face_F_SS_',app.num]);
                [app.face_x,app.face_y,app.face_z] = deal(aux(1),aux(2),aux(3));
                app.XdirectionEditField.Value = app.face_x;
                app.YdirectionEditField.Value = app.face_y;
                app.ZdirectionEditField.Value = app.face_z;
                
                aux = evalin('base',['DCM_F_SS_',app.num]);
                [app.dcm_1_1,app.dcm_1_2,app.dcm_1_3,app.dcm_2_1,app.dcm_2_2,app.dcm_2_3,app.dcm_3_1,app.dcm_3_2,app.dcm_3_3] = deal(aux(1,1),aux(1,2),aux(1,3),aux(2,1),aux(2,2),aux(2,3),aux(3,1),aux(3,2),aux(3,3));
                app.DCM_1_1EditField.Value = app.dcm_1_1;
                app.DCM_1_2EditField.Value = app.dcm_1_2;
                app.DCM_1_3EditField.Value = app.dcm_1_3;
                app.DCM_2_1EditField.Value = app.dcm_2_1;
                app.DCM_2_2EditField.Value = app.dcm_2_2;
                app.DCM_2_3EditField.Value = app.dcm_2_3;
                app.DCM_3_1EditField.Value = app.dcm_3_1;
                app.DCM_3_2EditField.Value = app.dcm_3_2;
                app.DCM_3_3EditField.Value = app.dcm_3_3;
            end
            
            sensitivity_F_SS = app.SensitivityLSBradEditField.Value;
            assignin('base',['Sensitivity_F_SS_',app.num],sensitivity_F_SS)
            
            upper_F_SS = app.UpperLimitEditField.Value;
            assignin('base',['Upper_F_SS_',app.num],upper_F_SS)
            
            lower_F_SS = app.LowerLimitEditField.Value;
            assignin('base',['Lower_F_SS_',app.num],lower_F_SS)
            
            noise_F_SS = app.NoiseStdDeviationradEditField.Value;
            assignin('base',['Noise_F_SS_',app.num],noise_F_SS)
            
            rate_F_SS = app.UpdateRatesEditField.Value;
            assignin('base',['Rate_F_SS_',app.num],rate_F_SS)
            
            fov_F_SS = app.FieldofViewEditField.Value;
            assignin('base',['FoV_F_SS_',app.num],fov_F_SS)
            
            app.face_x = app.XdirectionEditField.Value;
            app.face_y = app.YdirectionEditField.Value;
            app.face_z = app.ZdirectionEditField.Value;
            assignin('base',['Face_F_SS_',app.num],[app.face_x app.face_y app.face_z])
            
            app.dcm_1_1 = app.DCM_1_1EditField.Value;
            app.dcm_1_2 = app.DCM_1_2EditField.Value;
            app.dcm_1_3 = app.DCM_1_3EditField.Value;
            app.dcm_2_1 = app.DCM_2_1EditField.Value;
            app.dcm_2_2 = app.DCM_2_2EditField.Value;
            app.dcm_2_3 = app.DCM_2_3EditField.Value;
            app.dcm_3_1 = app.DCM_3_1EditField.Value;
            app.dcm_3_2 = app.DCM_3_2EditField.Value;
            app.dcm_3_3 = app.DCM_3_3EditField.Value;
            assignin('base',['DCM_F_SS_',app.num],[app.dcm_1_1 app.dcm_1_2 app.dcm_1_3; app.dcm_2_1 app.dcm_2_2 app.dcm_2_3; app.dcm_3_1 app.dcm_3_2 app.dcm_3_3])
        end

        % Value changed function: SensitivityLSBradEditField
        function SensitivityLSBradEditFieldValueChanged(app, event)
            sensitivity_F_SS = app.SensitivityLSBradEditField.Value;
            assignin('base',['Sensitivity_F_SS_',app.num],sensitivity_F_SS)
        end

        % Value changed function: UpperLimitEditField
        function UpperLimitEditFieldValueChanged(app, event)
            upper_F_SS = app.UpperLimitEditField.Value;
            assignin('base',['Upper_F_SS_',app.num],upper_F_SS)
        end

        % Value changed function: LowerLimitEditField
        function LowerLimitEditFieldValueChanged(app, event)
            lower_F_SS = app.LowerLimitEditField.Value;
            assignin('base',['Lower_F_SS_',app.num],lower_F_SS)
        end

        % Value changed function: NoiseStdDeviationradEditField
        function NoiseStdDeviationradEditFieldValueChanged(app, event)
            noise_F_SS = app.NoiseStdDeviationradEditField.Value;
            assignin('base',['Noise_F_SS_',app.num],noise_F_SS)
        end

        % Value changed function: UpdateRatesEditField
        function UpdateRatesEditFieldValueChanged(app, event)
            rate_F_SS = app.UpdateRatesEditField.Value;
            assignin('base',['Rate_F_SS_',app.num],rate_F_SS)
        end

        % Value changed function: XdirectionEditField
        function XdirectionEditFieldValueChanged(app, event)
            app.face_x = app.XdirectionEditField.Value;
            assignin('base',['Face_F_SS_',app.num],[app.face_x app.face_y app.face_z])
        end

        % Value changed function: YdirectionEditField
        function YdirectionEditFieldValueChanged(app, event)
            app.face_y = app.YdirectionEditField.Value;
            assignin('base',['Face_F_SS_',app.num],[app.face_x app.face_y app.face_z])
        end

        % Value changed function: ZdirectionEditField
        function ZdirectionEditFieldValueChanged(app, event)
            app.face_z = app.ZdirectionEditField.Value;
            assignin('base',['Face_F_SS_',app.num],[app.face_x app.face_y app.face_z])
        end

        % Value changed function: FieldofViewEditField
        function FieldofViewEditFieldValueChanged(app, event)
            fov_F_SS = app.FieldofViewEditField.Value;
            assignin('base',['FoV_F_SS_',app.num],fov_F_SS)
        end

        % Value changed function: DCM_1_1EditField
        function DCM_1_1EditFieldValueChanged(app, event)
            app.dcm_1_1 = app.DCM_1_1EditField.Value;
            assignin('base',['DCM_F_SS_',app.num],[app.dcm_1_1 app.dcm_1_2 app.dcm_1_3; app.dcm_2_1 app.dcm_2_2 app.dcm_2_3; app.dcm_3_1 app.dcm_3_2 app.dcm_3_3])
        end

        % Value changed function: DCM_1_2EditField
        function DCM_1_2EditFieldValueChanged(app, event)
            app.dcm_1_2 = app.DCM_1_2EditField.Value;
            assignin('base',['DCM_F_SS_',app.num],[app.dcm_1_1 app.dcm_1_2 app.dcm_1_3; app.dcm_2_1 app.dcm_2_2 app.dcm_2_3; app.dcm_3_1 app.dcm_3_2 app.dcm_3_3])
        end

        % Value changed function: DCM_1_3EditField
        function DCM_1_3EditFieldValueChanged(app, event)
            app.dcm_1_3 = app.DCM_1_3EditField.Value;
            assignin('base',['DCM_F_SS_',app.num],[app.dcm_1_1 app.dcm_1_2 app.dcm_1_3; app.dcm_2_1 app.dcm_2_2 app.dcm_2_3; app.dcm_3_1 app.dcm_3_2 app.dcm_3_3])
        end

        % Value changed function: DCM_2_1EditField
        function DCM_2_1EditFieldValueChanged(app, event)
            app.dcm_2_1 = app.DCM_2_1EditField.Value;
            assignin('base',['DCM_F_SS_',app.num],[app.dcm_1_1 app.dcm_1_2 app.dcm_1_3; app.dcm_2_1 app.dcm_2_2 app.dcm_2_3; app.dcm_3_1 app.dcm_3_2 app.dcm_3_3])
        end

        % Value changed function: DCM_2_2EditField
        function DCM_2_2EditFieldValueChanged(app, event)
            app.dcm_2_2 = app.DCM_2_2EditField.Value;
            assignin('base',['DCM_F_SS_',app.num],[app.dcm_1_1 app.dcm_1_2 app.dcm_1_3; app.dcm_2_1 app.dcm_2_2 app.dcm_2_3; app.dcm_3_1 app.dcm_3_2 app.dcm_3_3])
        end

        % Value changed function: DCM_2_3EditField
        function DCM_2_3EditFieldValueChanged(app, event)
            app.dcm_2_3 = app.DCM_2_3EditField.Value;
            assignin('base',['DCM_F_SS_',app.num],[app.dcm_1_1 app.dcm_1_2 app.dcm_1_3; app.dcm_2_1 app.dcm_2_2 app.dcm_2_3; app.dcm_3_1 app.dcm_3_2 app.dcm_3_3])
        end

        % Value changed function: DCM_3_1EditField
        function DCM_3_1EditFieldValueChanged(app, event)
            app.dcm_3_1 = app.DCM_3_1EditField.Value;
            assignin('base',['DCM_F_SS_',app.num],[app.dcm_1_1 app.dcm_1_2 app.dcm_1_3; app.dcm_2_1 app.dcm_2_2 app.dcm_2_3; app.dcm_3_1 app.dcm_3_2 app.dcm_3_3])
        end

        % Value changed function: DCM_3_2EditField
        function DCM_3_2EditFieldValueChanged(app, event)
            app.dcm_3_2 = app.DCM_3_2EditField.Value;
            assignin('base',['DCM_F_SS_',app.num],[app.dcm_1_1 app.dcm_1_2 app.dcm_1_3; app.dcm_2_1 app.dcm_2_2 app.dcm_2_3; app.dcm_3_1 app.dcm_3_2 app.dcm_3_3])
        end

        % Value changed function: DCM_3_3EditField
        function DCM_3_3EditFieldValueChanged(app, event)
            app.dcm_3_3 = app.DCM_3_3EditField.Value;
            assignin('base',['DCM_F_SS_',app.num],[app.dcm_1_1 app.dcm_1_2 app.dcm_1_3; app.dcm_2_1 app.dcm_2_2 app.dcm_2_3; app.dcm_3_1 app.dcm_3_2 app.dcm_3_3])
        end

        % Value changed function: MassEditField
        function MassEditFieldValueChanged(app, event)
            app.mass_fss = app.mass_fss+app.MassEditField.Value;
            app.m=app.MassEditField.Value;
            setappdata(0,'mass_f',app.mass_fss);
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SaturationcountsLabel = uilabel(app.UIFigure);
            app.SaturationcountsLabel.Text = 'Saturation [counts]:';
            app.FaceCoordinatesLabel = uilabel(app.UIFigure);
            app.FaceCoordinatesLabel.Text = 'Face Coordinates*:';
            app.DirectionCossineMatrixfrombodycoordinatesLabel = uilabel(app.UIFigure);
            app.DirectionCossineMatrixfrombodycoordinatesLabel.Text = {'Direction Cossine Matrix from body coordinates '; 'to sensor coordinates:'};
            app.UnitVectorLabel = uilabel(app.UIFigure);
            app.UnitVectorLabel.Text = '*Unit Vector';
            app.FineSunSensorSettingsLabel = uilabel(app.UIFigure);
            app.FineSunSensorSettingsLabel.Text = 'Fine Sun Sensor - Settings';
            app.SensitivityLSBradEditFieldLabel = uilabel(app.UIFigure);
            app.SensitivityLSBradEditFieldLabel.Text = 'Sensitivity [LSB/rad]';
            app.SensitivityLSBradEditField = uieditfield(app.UIFigure, 'numeric');
            app.SensitivityLSBradEditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityLSBradEditFieldValueChanged, true);
            app.SensitivityLSBradEditField.Value = 71428571.4285714;
            app.UpperLimitEditFieldLabel = uilabel(app.UIFigure);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.UpperLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperLimitEditFieldValueChanged, true);
            app.UpperLimitEditField.Value = 32767;
            app.LowerLimitEditFieldLabel = uilabel(app.UIFigure);
            app.LowerLimitEditFieldLabel.Text = 'Lower Limit';
            app.LowerLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @LowerLimitEditFieldValueChanged, true);
            app.LowerLimitEditField.Value = -32768;
            app.UpdateRatesEditFieldLabel = uilabel(app.UIFigure);
            app.UpdateRatesEditFieldLabel.Text = 'Update Rate [s]';
            app.UpdateRatesEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpdateRatesEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateRatesEditFieldValueChanged, true);
            app.UpdateRatesEditField.Value = 0.1;
            app.XdirectionEditFieldLabel = uilabel(app.UIFigure);
            app.XdirectionEditFieldLabel.Text = 'X direction';
            app.XdirectionEditField = uieditfield(app.UIFigure, 'numeric');
            app.XdirectionEditField.ValueChangedFcn = createCallbackFcn(app, @XdirectionEditFieldValueChanged, true);
            app.YdirectionEditFieldLabel = uilabel(app.UIFigure);
            app.YdirectionEditFieldLabel.Text = 'Y direction';
            app.YdirectionEditField = uieditfield(app.UIFigure, 'numeric');
            app.YdirectionEditField.ValueChangedFcn = createCallbackFcn(app, @YdirectionEditFieldValueChanged, true);
            app.ZdirectionEditFieldLabel = uilabel(app.UIFigure);
            app.ZdirectionEditFieldLabel.Text = 'Z direction';
            app.ZdirectionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZdirectionEditField.ValueChangedFcn = createCallbackFcn(app, @ZdirectionEditFieldValueChanged, true);
            app.ZdirectionEditField.Value = -1;
            app.DCM_1_1EditFieldLabel = uilabel(app.UIFigure);
            app.DCM_1_1EditFieldLabel.Text = 'DCM_1_1';
            app.DCM_1_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.DCM_1_1EditField.ValueChangedFcn = createCallbackFcn(app, @DCM_1_1EditFieldValueChanged, true);
            app.DCM_1_1EditField.Value = 1;
            app.DCM_1_2EditFieldLabel = uilabel(app.UIFigure);
            app.DCM_1_2EditFieldLabel.Text = 'DCM_1_2';
            app.DCM_1_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.DCM_1_2EditField.ValueChangedFcn = createCallbackFcn(app, @DCM_1_2EditFieldValueChanged, true);
            app.DCM_1_3EditFieldLabel = uilabel(app.UIFigure);
            app.DCM_1_3EditFieldLabel.Text = 'DCM_1_3';
            app.DCM_1_3EditField = uieditfield(app.UIFigure, 'numeric');
            app.DCM_1_3EditField.ValueChangedFcn = createCallbackFcn(app, @DCM_1_3EditFieldValueChanged, true);
            app.DCM_2_1EditFieldLabel = uilabel(app.UIFigure);
            app.DCM_2_1EditFieldLabel.Text = 'DCM_2_1';
            app.DCM_2_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.DCM_2_1EditField.ValueChangedFcn = createCallbackFcn(app, @DCM_2_1EditFieldValueChanged, true);
            app.DCM_2_2EditFieldLabel = uilabel(app.UIFigure);
            app.DCM_2_2EditFieldLabel.Text = 'DCM_2_2';
            app.DCM_2_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.DCM_2_2EditField.ValueChangedFcn = createCallbackFcn(app, @DCM_2_2EditFieldValueChanged, true);
            app.DCM_2_2EditField.Value = 1;
            app.DCM_2_3EditFieldLabel = uilabel(app.UIFigure);
            app.DCM_2_3EditFieldLabel.Text = 'DCM_2_3';
            app.DCM_2_3EditField = uieditfield(app.UIFigure, 'numeric');
            app.DCM_2_3EditField.ValueChangedFcn = createCallbackFcn(app, @DCM_2_3EditFieldValueChanged, true);
            app.DCM_3_1EditFieldLabel = uilabel(app.UIFigure);
            app.DCM_3_1EditFieldLabel.Text = 'DCM_3_1';
            app.DCM_3_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.DCM_3_1EditField.ValueChangedFcn = createCallbackFcn(app, @DCM_3_1EditFieldValueChanged, true);
            app.DCM_3_2EditFieldLabel = uilabel(app.UIFigure);
            app.DCM_3_2EditFieldLabel.Text = 'DCM_3_2';
            app.DCM_3_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.DCM_3_2EditField.ValueChangedFcn = createCallbackFcn(app, @DCM_3_2EditFieldValueChanged, true);
            app.DCM_3_3EditFieldLabel = uilabel(app.UIFigure);
            app.DCM_3_3EditFieldLabel.Text = 'DCM_3_3';
            app.DCM_3_3EditField = uieditfield(app.UIFigure, 'numeric');
            app.DCM_3_3EditField.ValueChangedFcn = createCallbackFcn(app, @DCM_3_3EditFieldValueChanged, true);
            app.DCM_3_3EditField.Value = 1;
            app.NoiseStdDeviationradEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseStdDeviationradEditFieldLabel.Text = 'Noise Std Deviation [rad]';
            app.NoiseStdDeviationradEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoiseStdDeviationradEditField.ValueChangedFcn = createCallbackFcn(app, @NoiseStdDeviationradEditFieldValueChanged, true);
            app.NoiseStdDeviationradEditField.Value = 5e-09;
            app.FieldofViewEditFieldLabel = uilabel(app.UIFigure);
            app.FieldofViewEditFieldLabel.Text = 'Field of View';
            app.FieldofViewEditField = uieditfield(app.UIFigure, 'numeric');
            app.FieldofViewEditField.ValueChangedFcn = createCallbackFcn(app, @FieldofViewEditFieldValueChanged, true);
            app.FieldofViewEditField.Value = 3.14159265358979;
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.KgLabel = uilabel(app.UIFigure);
            app.KgLabel.Text = 'Kg';
            app.cmLabel = uilabel(app.UIFigure);
            app.cmLabel.Text = 'cm';
            app.cmLabel_2 = uilabel(app.UIFigure);
            app.cmLabel_2.Text = 'cm';
            app.cmLabel_3 = uilabel(app.UIFigure);
            app.cmLabel_3.Text = 'cm';
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Text = 'W';
            app.LengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthEditFieldLabel = uilabel(app.UIFigure);
            app.LengthEditFieldLabel.Text = 'Length';
            app.MassEditFieldLabel = uilabel(app.UIFigure);
            app.MassEditFieldLabel.Text = ' Mass';
            app.MassEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassEditField.ValueChangedFcn = createCallbackFcn(app, @MassEditFieldValueChanged, true);
            app.WidthEditFieldLabel = uilabel(app.UIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerEditFieldLabel = uilabel(app.UIFigure);
            app.PowerEditFieldLabel.Text = ' Power';
            app.PowerEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = F_SS_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end