classdef Magnetometer_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SensitivityLSBTEditFieldLabel  matlab.ui.control.Label
        SensitivityLSBTEditField       matlab.ui.control.NumericEditField
        SaturationTLabel               matlab.ui.control.Label
        UpperLimitEditFieldLabel       matlab.ui.control.Label
        UpperLimitEditField            matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel       matlab.ui.control.Label
        LowerLimitEditField            matlab.ui.control.NumericEditField
        NoiseStdDeviationTEditFieldLabel  matlab.ui.control.Label
        NoiseStdDeviationTEditField    matlab.ui.control.NumericEditField
        UpdateRatesEditFieldLabel      matlab.ui.control.Label
        UpdateRatesEditField           matlab.ui.control.NumericEditField
        StaticBiasTLabel               matlab.ui.control.Label
        XdirectionEditFieldLabel       matlab.ui.control.Label
        XdirectionEditField            matlab.ui.control.NumericEditField
        YdirectionEditFieldLabel       matlab.ui.control.Label
        YdirectionEditField            matlab.ui.control.NumericEditField
        ZdirectionEditFieldLabel       matlab.ui.control.Label
        ZdirectionEditField            matlab.ui.control.NumericEditField
        ScaleFactorsLabel              matlab.ui.control.Label
        g_1EditFieldLabel              matlab.ui.control.Label
        g_1EditField                   matlab.ui.control.NumericEditField
        g_2EditFieldLabel              matlab.ui.control.Label
        g_2EditField                   matlab.ui.control.NumericEditField
        g_3EditFieldLabel              matlab.ui.control.Label
        g_3EditField                   matlab.ui.control.NumericEditField
        MisalignmentAnglesdegLabel     matlab.ui.control.Label
        EditFieldLabel                 matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        EditField_2Label               matlab.ui.control.Label
        EditField_2                    matlab.ui.control.NumericEditField
        EditField_3Label               matlab.ui.control.Label
        EditField_3                    matlab.ui.control.NumericEditField
        EditField_4Label               matlab.ui.control.Label
        EditField_4                    matlab.ui.control.NumericEditField
        EditField_5Label               matlab.ui.control.Label
        EditField_5                    matlab.ui.control.NumericEditField
        EditField_6Label               matlab.ui.control.Label
        EditField_6                    matlab.ui.control.NumericEditField
        DefaultvaluescanbeleftifnoinformationisavailableLabel  matlab.ui.control.Label
        MagnetometerSettingsLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SensitivityLSBTEditField
        function SensitivityLSBTEditFieldValueChanged(app, event)
            sensitivity_mag = app.SensitivityLSBTEditField.Value;
            assignin('base','Sensitivity_mag',sensitivity_mag)
        end

        % Value changed function: UpperLimitEditField
        function UpperLimitEditFieldValueChanged(app, event)
            upper_mag = app.UpperLimitEditField.Value;
            assignin('base','Upper_mag',upper_mag)
        end

        % Value changed function: LowerLimitEditField
        function LowerLimitEditFieldValueChanged(app, event)
            lower_mag = app.LowerLimitEditField.Value;
            assignin('base','Lower_mag',lower_mag)
        end

        % Value changed function: NoiseStdDeviationTEditField
        function NoiseStdDeviationTEditFieldValueChanged(app, event)
            noise_mag = app.NoiseStdDeviationTEditField.Value;
            assignin('base','Noise_mag',noise_mag)
        end

        % Value changed function: UpdateRatesEditField
        function UpdateRatesEditFieldValueChanged(app, event)
            rate_mag = app.UpdateRatesEditField.Value;
            assignin('base','Rate_mag',rate_mag)
        end

        % Value changed function: XdirectionEditField
        function XdirectionEditFieldValueChanged(app, event)
            bias_x = app.XdirectionEditField.Value;
            assignin('base','Bias_mag',[bias_x, bias_y, bias_z])
        end

        % Value changed function: YdirectionEditField
        function YdirectionEditFieldValueChanged(app, event)
            bias_y = app.YdirectionEditField.Value;
            assignin('base','Bias_mag',[bias_x, bias_y, bias_z])
        end

        % Value changed function: ZdirectionEditField
        function ZdirectionEditFieldValueChanged(app, event)
            bias_z = app.ZdirectionEditField.Value;
            assignin('base','Bias_mag',[bias_x, bias_y, bias_z])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SensitivityLSBTEditFieldLabel = uilabel(app.UIFigure);
            app.SensitivityLSBTEditFieldLabel.Text = 'Sensitivity [LSB/T]';
            app.SensitivityLSBTEditField = uieditfield(app.UIFigure, 'numeric');
            app.SensitivityLSBTEditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityLSBTEditFieldValueChanged, true);
            app.SensitivityLSBTEditField.Value = 71428571.4285714;
            app.SaturationTLabel = uilabel(app.UIFigure);
            app.SaturationTLabel.Text = 'Saturation [T]:';
            app.UpperLimitEditFieldLabel = uilabel(app.UIFigure);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.UpperLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperLimitEditFieldValueChanged, true);
            app.UpperLimitEditField.Value = 32767;
            app.LowerLimitEditFieldLabel = uilabel(app.UIFigure);
            app.LowerLimitEditFieldLabel.Text = 'Lower Limit';
            app.LowerLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @LowerLimitEditFieldValueChanged, true);
            app.LowerLimitEditField.Value = -32768;
            app.NoiseStdDeviationTEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseStdDeviationTEditFieldLabel.Text = 'Noise Std Deviation [T]';
            app.NoiseStdDeviationTEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoiseStdDeviationTEditField.ValueChangedFcn = createCallbackFcn(app, @NoiseStdDeviationTEditFieldValueChanged, true);
            app.NoiseStdDeviationTEditField.Value = 5e-09;
            app.UpdateRatesEditFieldLabel = uilabel(app.UIFigure);
            app.UpdateRatesEditFieldLabel.Text = 'Update Rate [s]';
            app.UpdateRatesEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpdateRatesEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateRatesEditFieldValueChanged, true);
            app.UpdateRatesEditField.Value = 0.1;
            app.StaticBiasTLabel = uilabel(app.UIFigure);
            app.StaticBiasTLabel.Text = 'Static Bias [T]:';
            app.XdirectionEditFieldLabel = uilabel(app.UIFigure);
            app.XdirectionEditFieldLabel.Text = 'X direction';
            app.XdirectionEditField = uieditfield(app.UIFigure, 'numeric');
            app.XdirectionEditField.ValueChangedFcn = createCallbackFcn(app, @XdirectionEditFieldValueChanged, true);
            app.XdirectionEditField.Value = 1.32234e-08;
            app.YdirectionEditFieldLabel = uilabel(app.UIFigure);
            app.YdirectionEditFieldLabel.Text = 'Y direction';
            app.YdirectionEditField = uieditfield(app.UIFigure, 'numeric');
            app.YdirectionEditField.ValueChangedFcn = createCallbackFcn(app, @YdirectionEditFieldValueChanged, true);
            app.YdirectionEditField.Value = 4.6323e-08;
            app.ZdirectionEditFieldLabel = uilabel(app.UIFigure);
            app.ZdirectionEditFieldLabel.Text = 'Z direction';
            app.ZdirectionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZdirectionEditField.ValueChangedFcn = createCallbackFcn(app, @ZdirectionEditFieldValueChanged, true);
            app.ZdirectionEditField.Value = 1.5323e-09;
            app.ScaleFactorsLabel = uilabel(app.UIFigure);
            app.ScaleFactorsLabel.Text = 'Scale Factors*:';
            app.g_1EditFieldLabel = uilabel(app.UIFigure);
            app.g_1EditFieldLabel.Text = 'g_1';
            app.g_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.g_1EditField.Value = 1;
            app.g_2EditFieldLabel = uilabel(app.UIFigure);
            app.g_2EditFieldLabel.Text = 'g_2';
            app.g_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.g_3EditFieldLabel = uilabel(app.UIFigure);
            app.g_3EditFieldLabel.Text = 'g_3';
            app.g_3EditField = uieditfield(app.UIFigure, 'numeric');
            app.MisalignmentAnglesdegLabel = uilabel(app.UIFigure);
            app.MisalignmentAnglesdegLabel.Text = 'Misalignment Angles* [deg]:';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'θ_1';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = 'θ_2';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Value = 1.00049366369586;
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = 'θ_3';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = 'φ_1';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5Label = uilabel(app.UIFigure);
            app.EditField_5Label.Text = 'φ_1';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6Label = uilabel(app.UIFigure);
            app.EditField_6Label.Text = 'φ_1';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.Value = 1;
            app.DefaultvaluescanbeleftifnoinformationisavailableLabel = uilabel(app.UIFigure);
            app.DefaultvaluescanbeleftifnoinformationisavailableLabel.Text = '*Default values can be left if no information is available';
            app.MagnetometerSettingsLabel = uilabel(app.UIFigure);
            app.MagnetometerSettingsLabel.Text = 'Magnetometer Settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Magnetometer_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end