classdef Plot_3_var < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        RollAnglevsTimePanel  matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, a, nome, label1, label2, label3, label4)
           app.RollAnglevsTimePanel.Title = nome;  
              plot(app.UIAxes,a(:,1),a(:,2)) 
              hold(app.UIAxes);
              plot(app.UIAxes,a(:,1),a(:,3)) 
              plot(app.UIAxes,a(:,1),a(:,4))  
              legend(app.UIAxes,{label1,label2,label3})
              ylabel(app.UIAxes, label4)
               
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RollAnglevsTimePanel = uipanel(app.UIFigure);
            app.RollAnglevsTimePanel.Title = 'Roll Angle vs Time';
            app.UIAxes = uiaxes(app.RollAnglevsTimePanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'info')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Plot_3_var(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end