classdef ReactionWheel_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ReactionWheelSettingsLabel      matlab.ui.control.Label
        MaximumvoltageVEditFieldLabel   matlab.ui.control.Label
        MaximumvoltageVEditField        matlab.ui.control.NumericEditField
        WheelInertiakgm2EditFieldLabel  matlab.ui.control.Label
        WheelInertiakgm2EditField       matlab.ui.control.NumericEditField
        MaxmomentumstorageEditFieldLabel  matlab.ui.control.Label
        MaxmomentumstorageEditField     matlab.ui.control.NumericEditField
        MomentumstoragecoeffEditFieldLabel  matlab.ui.control.Label
        MomentumstoragecoeffEditField   matlab.ui.control.NumericEditField
        MaxtorqueNmEditFieldLabel       matlab.ui.control.Label
        MaxtorqueNmEditField            matlab.ui.control.NumericEditField
        MaxrotationalspeedradsEditFieldLabel  matlab.ui.control.Label
        MaxrotationalspeedradsEditField  matlab.ui.control.NumericEditField
        Angularaccelerationrads2EditFieldLabel  matlab.ui.control.Label
        Angularaccelerationrads2EditField  matlab.ui.control.NumericEditField
        DirectionCossineMatrixfrombodycoordinatesLabel  matlab.ui.control.Label
        X_bEditFieldLabel               matlab.ui.control.Label
        X_bEditField                    matlab.ui.control.NumericEditField
        Y_bEditFieldLabel               matlab.ui.control.Label
        Y_bEditField                    matlab.ui.control.NumericEditField
        Z_bEditFieldLabel               matlab.ui.control.Label
        Z_bEditField                    matlab.ui.control.NumericEditField
        TorqueconstantNmAEditFieldLabel  matlab.ui.control.Label
        TorqueconstantNmAEditField      matlab.ui.control.NumericEditField
        ElectricresistanceOhmEditFieldLabel  matlab.ui.control.Label
        ElectricresistanceOhmEditField  matlab.ui.control.NumericEditField
        CoulombfrictioncoeffNmEditFieldLabel  matlab.ui.control.Label
        CoulombfrictioncoeffNmEditField  matlab.ui.control.NumericEditField
        ViscousfrictioncoeffNmsLabel    matlab.ui.control.Label
        ViscousfrictioncoeffNmsEditField  matlab.ui.control.NumericEditField
        RetroactiveconstantOhmsEditFieldLabel  matlab.ui.control.Label
        RetroactiveconstantOhmsEditField  matlab.ui.control.NumericEditField
        DirectionCossineMatrixfrombodycoordinatesLabel_2  matlab.ui.control.Label
        DirectionCossineMatrixfrombodycoordinatesLabel_3  matlab.ui.control.Label
        StepperratesEditFieldLabel      matlab.ui.control.Label
        StepperratesEditField           matlab.ui.control.NumericEditField
        ConfirmButton                   matlab.ui.control.Button
        KgLabel                         matlab.ui.control.Label
        cmLabel                         matlab.ui.control.Label
        cmLabel_2                       matlab.ui.control.Label
        cmLabel_3                       matlab.ui.control.Label
        WLabel                          matlab.ui.control.Label
        LengthEditField                 matlab.ui.control.NumericEditField
        LengthEditFieldLabel            matlab.ui.control.Label
        MassEditFieldLabel              matlab.ui.control.Label
        MassEditField                   matlab.ui.control.NumericEditField
        WidthEditFieldLabel             matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.NumericEditField
        HeightEditFieldLabel            matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        PowerEditFieldLabel             matlab.ui.control.Label
        PowerEditField                  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        num
        
        xb_wheel
        yb_wheel
        zb_wheel
        mass_rw=0;
        m = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, val, flag)
            app.num = val;
            aux = rem(floor(flag/10^(str2double(app.num)-1)),10);
            
            textLabel = sprintf('Reaction Wheel %s - Settings', app.num);
            app.ReactionWheelSettingsLabel.Text = textLabel;
            
            if aux == 1
                v_wheel = evalin('base',['V_wheel_',app.num]);
                app.MaximumvoltageVEditField.Value = v_wheel;
                
                inertia_wheel = evalin('base',['Inertia_wheel_',app.num]);
                app.WheelInertiakgm2EditField.Value = inertia_wheel;
                
                max_mom_stg_wheel = evalin('base',['Max_mom_stg_wheel_',app.num]);
                app.MaxmomentumstorageEditField.Value = max_mom_stg_wheel;
                
                mom_stg_coef_wheel = evalin('base',['Mom_stg_coef_wheel_',app.num]);
                app.MomentumstoragecoeffEditField.Value = mom_stg_coef_wheel;
                
                max_t_wheel = evalin('base',['Max_t_wheel_',app.num]);
                app.MaxtorqueNmEditField.Value = max_t_wheel;
                
                max_rot_wheel = evalin('base',['Max_rot_wheel_',app.num]);
                app.MaxrotationalspeedradsEditField.Value = max_rot_wheel;
                
                ang_accel_wheel = evalin('base',['Ang_accel_wheel_',app.num]);
                app.Angularaccelerationrads2EditField.Value = ang_accel_wheel;

                torque_const_wheel = evalin('base',['Torque_const_wheel_',app.num]);
                app.TorqueconstantNmAEditField.Value = torque_const_wheel;
                
                e_res_wheel = evalin('base',['E_res_wheel_',app.num]);
                app.ElectricresistanceOhmEditField.Value = e_res_wheel;
                
                re_const_wheel = evalin('base',['Re_const_wheel_',app.num]);
                app.RetroactiveconstantOhmsEditField.Value = re_const_wheel;
                
                c_fric_wheel = evalin('base',['C_fric_wheel_',app.num]);
                app.CoulombfrictioncoeffNmEditField.Value = c_fric_wheel;
                
                visc_wheel = evalin('base',['Visc_wheel_',app.num]);
                app.ViscousfrictioncoeffNmsEditField.Value = visc_wheel;
                
                stepper_rate = evalin('base',['Stepper_rate_',app.num]);
                app.StepperratesEditField.Value = stepper_rate;
                
                aux = evalin('base',['BM_wheel_',app.num]);
                [app.xb_wheel,app.yb_wheel,app.zb_wheel] = deal(aux(1,1),aux(1,2),aux(1,3));
                app.X_bEditField.Value = app.xb_wheel;
                app.Y_bEditField.Value = app.yb_wheel;
                app.Z_bEditField.Value = app.zb_wheel;
            end
            
            
            v_wheel = app.MaximumvoltageVEditField.Value;
            assignin('base',['V_wheel_',app.num],v_wheel)
            
            inertia_wheel = app.WheelInertiakgm2EditField.Value;
            assignin('base',['Inertia_wheel_',app.num],inertia_wheel)
            
            max_mom_stg_wheel = app.MaxmomentumstorageEditField.Value;
            assignin('base',['Max_mom_stg_wheel_',app.num],max_mom_stg_wheel)
            
            mom_stg_coef_wheel = app.MomentumstoragecoeffEditField.Value;
            assignin('base',['Mom_stg_coef_wheel_',app.num],mom_stg_coef_wheel)
            
            max_t_wheel = app.MaxtorqueNmEditField.Value;
            assignin('base',['Max_t_wheel_',app.num],max_t_wheel)
            
            max_rot_wheel = app.MaxrotationalspeedradsEditField.Value;
            assignin('base',['Max_rot_wheel_',app.num],max_rot_wheel)
            
            ang_accel_wheel = app.Angularaccelerationrads2EditField.Value;
            assignin('base',['Ang_accel_wheel_',app.num],ang_accel_wheel)
            
            torque_const_wheel = app.TorqueconstantNmAEditField.Value;
            assignin('base',['Torque_const_wheel_',app.num],torque_const_wheel)
            
            e_res_wheel = app.ElectricresistanceOhmEditField.Value;
            assignin('base',['E_res_wheel_',app.num],e_res_wheel)
            
            re_const_wheel = app.RetroactiveconstantOhmsEditField.Value;
            assignin('base',['Re_const_wheel_',app.num],re_const_wheel)
            
            c_fric_wheel = app.CoulombfrictioncoeffNmEditField.Value;
            assignin('base',['C_fric_wheel_',app.num],c_fric_wheel)
            
            visc_wheel = app.ViscousfrictioncoeffNmsEditField.Value;
            assignin('base',['Visc_wheel_',app.num],visc_wheel)
            
            stepper_rate = app.StepperratesEditField.Value;
            assignin('base',['Stepper_rate_',app.num],stepper_rate)
            
            app.xb_wheel = app.X_bEditField.Value;
            app.yb_wheel = app.Y_bEditField.Value;
            app.zb_wheel = app.Z_bEditField.Value;
            assignin('base',['BM_wheel_',app.num],[app.xb_wheel app.yb_wheel app.zb_wheel])
            
        end

        % Value changed function: MaximumvoltageVEditField
        function MaximumvoltageVEditFieldValueChanged(app, event)
            v_wheel = app.MaximumvoltageVEditField.Value;
            assignin('base',['V_wheel_',app.num],v_wheel)
        end

        % Value changed function: WheelInertiakgm2EditField
        function WheelInertiakgm2EditFieldValueChanged(app, event)
            inertia_wheel = app.WheelInertiakgm2EditField.Value;
            assignin('base',['Inertia_wheel_',app.num],inertia_wheel)
        end

        % Value changed function: MaxmomentumstorageEditField
        function MaxmomentumstorageEditFieldValueChanged(app, event)
            max_mom_stg_wheel = app.MaxmomentumstorageEditField.Value;
            assignin('base',['Max_mom_stg_wheel_',app.num],max_mom_stg_wheel)
        end

        % Value changed function: MomentumstoragecoeffEditField
        function MomentumstoragecoeffEditFieldValueChanged(app, event)
            mom_stg_coef_wheel = app.MomentumstoragecoeffEditField.Value;
            assignin('base',['Mom_stg_coef_wheel_',app.num],mom_stg_coef_wheel)
        end

        % Value changed function: MaxtorqueNmEditField
        function MaxtorqueNmEditFieldValueChanged(app, event)
            max_t_wheel = app.MaxtorqueNmEditField.Value;
            assignin('base',['Max_t_wheel_',app.num],max_t_wheel)
        end

        % Value changed function: MaxrotationalspeedradsEditField
        function MaxrotationalspeedradsEditFieldValueChanged(app, event)
            max_rot_wheel = app.MaxrotationalspeedradsEditField.Value;
            assignin('base',['Max_rot_wheel_',app.num],max_rot_wheel)
        end

        % Value changed function: Angularaccelerationrads2EditField
        function Angularaccelerationrads2EditFieldValueChanged(app, event)
            ang_accel_wheel = app.Angularaccelerationrads2EditField.Value;
            assignin('base',['Ang_accel_wheel_',app.num],ang_accel_wheel)
        end

        % Value changed function: X_bEditField
        function X_bEditFieldValueChanged(app, event)
            app.xb_wheel = app.X_bEditField.Value;
            assignin('base',['BM_wheel_',app.num],[app.xb_wheel app.yb_wheel app.zb_wheel])
        end

        % Value changed function: Y_bEditField
        function Y_bEditFieldValueChanged(app, event)
            app.yb_wheel = app.Y_bEditField.Value;
            assignin('base',['BM_wheel_',app.num],[app.xb_wheel app.yb_wheel app.zb_wheel])
        end

        % Value changed function: Z_bEditField
        function Z_bEditFieldValueChanged(app, event)
            app.zb_wheel = app.Z_bEditField.Value;
            assignin('base',['BM_wheel_',app.num],[app.xb_wheel app.yb_wheel app.zb_wheel])
        end

        % Callback function
        function T_2_1EditFieldValueChanged(app, event)
            
        end

        % Callback function
        function T_2_2EditFieldValueChanged(app, event)
            
        end

        % Callback function
        function T_2_3EditFieldValueChanged(app, event)
            
        end

        % Callback function
        function T_3_1EditFieldValueChanged(app, event)
            
        end

        % Callback function
        function T_3_2EditFieldValueChanged(app, event)
            
        end

        % Callback function
        function T_3_3EditFieldValueChanged(app, event)
            
        end

        % Value changed function: TorqueconstantNmAEditField
        function TorqueconstantNmAEditFieldValueChanged(app, event)
            torque_const_wheel = app.TorqueconstantNmAEditField.Value;
            assignin('base',['Torque_const_wheel_',app.num],torque_const_wheel)
        end

        % Value changed function: ElectricresistanceOhmEditField
        function ElectricresistanceOhmEditFieldValueChanged(app, event)
            e_res_wheel = app.ElectricresistanceOhmEditField.Value;
            assignin('base',['E_res_wheel_',app.num],e_res_wheel)
        end

        % Value changed function: RetroactiveconstantOhmsEditField
        function RetroactiveconstantOhmsEditFieldValueChanged(app, event)
            re_const_wheel = app.RetroactiveconstantOhmsEditField.Value;
            assignin('base',['Re_const_wheel_',app.num],re_const_wheel)
        end

        % Value changed function: CoulombfrictioncoeffNmEditField
        function CoulombfrictioncoeffNmEditFieldValueChanged(app, event)
            c_fric_wheel = app.CoulombfrictioncoeffNmEditField.Value;
            assignin('base',['C_fric_wheel_',app.num],c_fric_wheel)
        end

        % Value changed function: ViscousfrictioncoeffNmsEditField
        function ViscousfrictioncoeffNmsEditFieldValueChanged(app, event)
            visc_wheel = app.ViscousfrictioncoeffNmsEditField.Value;
            assignin('base',['Visc_wheel_',app.num],visc_wheel)
        end

        % Value changed function: StepperratesEditField
        function StepperratesEditFieldValueChanged(app, event)
            stepper_rate = app.StepperratesEditField.Value;
            assignin('base',['Stepper_rate_',app.num],stepper_rate)
        end

        % Value changed function: MassEditField
        function MassEditFieldValueChanged(app, event)
            app.mass_rw =app.mass_rw+app.MassEditField.Value;
            app.m=app.MassEditField.Value;  
            setappdata(0,'mass_r',app.mass_rw);
            
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReactionWheelSettingsLabel = uilabel(app.UIFigure);
            app.ReactionWheelSettingsLabel.Text = 'Reaction Wheel - Settings';
            app.MaximumvoltageVEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumvoltageVEditFieldLabel.Text = 'Maximum voltage [V]';
            app.MaximumvoltageVEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumvoltageVEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumvoltageVEditFieldValueChanged, true);
            app.MaximumvoltageVEditField.Value = 16;
            app.WheelInertiakgm2EditFieldLabel = uilabel(app.UIFigure);
            app.WheelInertiakgm2EditFieldLabel.Text = 'Wheel Inertia [kg.m^2]';
            app.WheelInertiakgm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.WheelInertiakgm2EditField.ValueChangedFcn = createCallbackFcn(app, @WheelInertiakgm2EditFieldValueChanged, true);
            app.WheelInertiakgm2EditField.Value = 2.02922e-06;
            app.MaxmomentumstorageEditFieldLabel = uilabel(app.UIFigure);
            app.MaxmomentumstorageEditFieldLabel.Text = 'Max. momentum storage';
            app.MaxmomentumstorageEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxmomentumstorageEditField.ValueChangedFcn = createCallbackFcn(app, @MaxmomentumstorageEditFieldValueChanged, true);
            app.MaxmomentumstorageEditField.Value = 0.0017;
            app.MomentumstoragecoeffEditFieldLabel = uilabel(app.UIFigure);
            app.MomentumstoragecoeffEditFieldLabel.Text = 'Momentum storage coeff.';
            app.MomentumstoragecoeffEditField = uieditfield(app.UIFigure, 'numeric');
            app.MomentumstoragecoeffEditField.ValueChangedFcn = createCallbackFcn(app, @MomentumstoragecoeffEditFieldValueChanged, true);
            app.MomentumstoragecoeffEditField.Value = 1;
            app.MaxtorqueNmEditFieldLabel = uilabel(app.UIFigure);
            app.MaxtorqueNmEditFieldLabel.Text = 'Max. torque [N.m]';
            app.MaxtorqueNmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxtorqueNmEditField.ValueChangedFcn = createCallbackFcn(app, @MaxtorqueNmEditFieldValueChanged, true);
            app.MaxtorqueNmEditField.Value = 0.00023;
            app.MaxrotationalspeedradsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxrotationalspeedradsEditFieldLabel.Text = 'Max. rotational speed [rad/s]';
            app.MaxrotationalspeedradsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxrotationalspeedradsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxrotationalspeedradsEditFieldValueChanged, true);
            app.MaxrotationalspeedradsEditField.Value = 837.758;
            app.Angularaccelerationrads2EditFieldLabel = uilabel(app.UIFigure);
            app.Angularaccelerationrads2EditFieldLabel.Text = 'Angular acceleration [rad/s^2]';
            app.Angularaccelerationrads2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Angularaccelerationrads2EditField.ValueChangedFcn = createCallbackFcn(app, @Angularaccelerationrads2EditFieldValueChanged, true);
            app.Angularaccelerationrads2EditField.Value = -100;
            app.DirectionCossineMatrixfrombodycoordinatesLabel = uilabel(app.UIFigure);
            app.DirectionCossineMatrixfrombodycoordinatesLabel.Text = {'Orientation of the wheel '; 'spin axis in the body frame:'};
            app.X_bEditFieldLabel = uilabel(app.UIFigure);
            app.X_bEditFieldLabel.Text = 'X_b';
            app.X_bEditField = uieditfield(app.UIFigure, 'numeric');
            app.X_bEditField.ValueChangedFcn = createCallbackFcn(app, @X_bEditFieldValueChanged, true);
            app.Y_bEditFieldLabel = uilabel(app.UIFigure);
            app.Y_bEditFieldLabel.Text = 'Y_b';
            app.Y_bEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y_bEditField.ValueChangedFcn = createCallbackFcn(app, @Y_bEditFieldValueChanged, true);
            app.Y_bEditField.Value = 1;
            app.Z_bEditFieldLabel = uilabel(app.UIFigure);
            app.Z_bEditFieldLabel.Text = 'Z_b';
            app.Z_bEditField = uieditfield(app.UIFigure, 'numeric');
            app.Z_bEditField.ValueChangedFcn = createCallbackFcn(app, @Z_bEditFieldValueChanged, true);
            app.TorqueconstantNmAEditFieldLabel = uilabel(app.UIFigure);
            app.TorqueconstantNmAEditFieldLabel.Text = 'Torque constant [N.m/A]';
            app.TorqueconstantNmAEditField = uieditfield(app.UIFigure, 'numeric');
            app.TorqueconstantNmAEditField.ValueChangedFcn = createCallbackFcn(app, @TorqueconstantNmAEditFieldValueChanged, true);
            app.TorqueconstantNmAEditField.Value = 0.0041;
            app.ElectricresistanceOhmEditFieldLabel = uilabel(app.UIFigure);
            app.ElectricresistanceOhmEditFieldLabel.Text = 'Electric resistance [Ohm]';
            app.ElectricresistanceOhmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElectricresistanceOhmEditField.ValueChangedFcn = createCallbackFcn(app, @ElectricresistanceOhmEditFieldValueChanged, true);
            app.ElectricresistanceOhmEditField.Value = 150;
            app.CoulombfrictioncoeffNmEditFieldLabel = uilabel(app.UIFigure);
            app.CoulombfrictioncoeffNmEditFieldLabel.Text = 'Coulomb friction coeff [N.m]';
            app.CoulombfrictioncoeffNmEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoulombfrictioncoeffNmEditField.ValueChangedFcn = createCallbackFcn(app, @CoulombfrictioncoeffNmEditFieldValueChanged, true);
            app.CoulombfrictioncoeffNmEditField.Value = 0.000111;
            app.ViscousfrictioncoeffNmsLabel = uilabel(app.UIFigure);
            app.ViscousfrictioncoeffNmsLabel.Text = 'Viscous friction coeff [N.m.s]';
            app.ViscousfrictioncoeffNmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ViscousfrictioncoeffNmsEditField.ValueChangedFcn = createCallbackFcn(app, @ViscousfrictioncoeffNmsEditFieldValueChanged, true);
            app.ViscousfrictioncoeffNmsEditField.Value = 1e-07;
            app.RetroactiveconstantOhmsEditFieldLabel = uilabel(app.UIFigure);
            app.RetroactiveconstantOhmsEditFieldLabel.Text = 'Retroactive constant [Ohm/s]';
            app.RetroactiveconstantOhmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RetroactiveconstantOhmsEditField.ValueChangedFcn = createCallbackFcn(app, @RetroactiveconstantOhmsEditFieldValueChanged, true);
            app.RetroactiveconstantOhmsEditField.Value = 869.3;
            app.DirectionCossineMatrixfrombodycoordinatesLabel_2 = uilabel(app.UIFigure);
            app.DirectionCossineMatrixfrombodycoordinatesLabel_2.Text = 'Electrical Properties:';
            app.DirectionCossineMatrixfrombodycoordinatesLabel_3 = uilabel(app.UIFigure);
            app.DirectionCossineMatrixfrombodycoordinatesLabel_3.Text = 'Mechanical Properties:';
            app.StepperratesEditFieldLabel = uilabel(app.UIFigure);
            app.StepperratesEditFieldLabel.Text = 'Stepper rate [s]';
            app.StepperratesEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepperratesEditField.ValueChangedFcn = createCallbackFcn(app, @StepperratesEditFieldValueChanged, true);
            app.StepperratesEditField.Value = 0.1;
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.KgLabel = uilabel(app.UIFigure);
            app.KgLabel.Text = 'Kg';
            app.cmLabel = uilabel(app.UIFigure);
            app.cmLabel.Text = 'cm';
            app.cmLabel_2 = uilabel(app.UIFigure);
            app.cmLabel_2.Text = 'cm';
            app.cmLabel_3 = uilabel(app.UIFigure);
            app.cmLabel_3.Text = 'cm';
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Text = 'W';
            app.LengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthEditFieldLabel = uilabel(app.UIFigure);
            app.LengthEditFieldLabel.Text = 'Length';
            app.MassEditFieldLabel = uilabel(app.UIFigure);
            app.MassEditFieldLabel.Text = ' Mass';
            app.MassEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassEditField.ValueChangedFcn = createCallbackFcn(app, @MassEditFieldValueChanged, true);
            app.WidthEditFieldLabel = uilabel(app.UIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerEditFieldLabel = uilabel(app.UIFigure);
            app.PowerEditFieldLabel.Text = ' Power';
            app.PowerEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReactionWheel_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end