classdef attitude < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ReferenceFrameDropDownLabel  matlab.ui.control.Label
        ReferenceFrameDropDown       matlab.ui.control.DropDown
        q1EditFieldLabel             matlab.ui.control.Label
        q1EditField                  matlab.ui.control.NumericEditField
        q2EditField_2Label           matlab.ui.control.Label
        q2EditField                  matlab.ui.control.NumericEditField
        q3EditFieldLabel             matlab.ui.control.Label
        q3EditField                  matlab.ui.control.NumericEditField
        q4EditFieldLabel             matlab.ui.control.Label
        q4EditField                  matlab.ui.control.NumericEditField
        d0dtEditFieldLabel           matlab.ui.control.Label
        d0dtEditField                matlab.ui.control.NumericEditField
        d0dtLabel                    matlab.ui.control.Label
        d0dtEditField_2              matlab.ui.control.NumericEditField
        d0dtLabel_2                  matlab.ui.control.Label
        d0dtEditField_3              matlab.ui.control.NumericEditField
        radsLabel                    matlab.ui.control.Label
        radsLabel_2                  matlab.ui.control.Label
        radsLabel_3                  matlab.ui.control.Label
        SaveButton                   matlab.ui.control.Button
        AttitudeLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        q1;
        q2;
        q3;
        q4;
        tht0dz;
        btt0dz;
        gmt0dz;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, aux)
              if aux > 0
                aux2 = evalin('base','qt');
                [app.q1,app.q2,app.q3,app.q4] = deal(aux2(1,1),aux2(1,2),aux2(1,3),aux2(1,4));
                app.q1EditField.Value = app.q1;
                app.q2EditField.Value = app.q2;
                app.q3EditField.Value = app.q3;
                app.q4EditField.Value = app.q4;
                
                aux2 = evalin('base','t0dz');
                [app.tht0dz,app.btt0dz,app.gmt0dz] = deal(aux2(1,1),aux2(1,2),aux2(1,3));
                app.d0dtEditField.Value = app.tht0dz;
                app.d0dtEditField_2.Value = app.btt0dz;
                app.d0dtEditField_3.Value = app.gmt0dz;
              end
            
              app.q1 = app.q1EditField.Value;
              app.q2 = app.q2EditField.Value;
              app.q3 = app.q3EditField.Value;
              app.q4 = app.q4EditField.Value;
              assignin('base','qt',[app.q1,app.q2,app.q3,app.q4])
              
             app.tht0dz = app.d0dtEditField.Value;
             app.btt0dz = app.d0dtEditField_2.Value;
             app.gmt0dz = app.d0dtEditField_3.Value;
             assignin('base','t0dz',[app.tht0dz,app.btt0dz,app.gmt0dz])
        end

        % Value changed function: q1EditField
        function q1EditFieldValueChanged(app, event)
             app.q1 = app.q1EditField.Value;
             assignin('base','qt',[app.q1,app.q2,app.q3,app.q4])            
        end

        % Value changed function: q2EditField
        function q2EditFieldValueChanged(app, event)
              app.q2 = app.q2EditField.Value;
              assignin('base','qt',[app.q1,app.q2,app.q3,app.q4])
        end

        % Value changed function: q3EditField
        function q3EditFieldValueChanged(app, event)
              app.q3 = app.q3EditField.Value;
              assignin('base','qt',[app.q1,app.q2,app.q3,app.q4])
        end

        % Value changed function: q4EditField
        function q4EditFieldValueChanged(app, event)
              app.q4 = app.q4EditField.Value;
              assignin('base','qt',[app.q1,app.q2,app.q3,app.q4])
        end

        % Value changed function: d0dtEditField
        function d0dtEditFieldValueChanged(app, event)
            app.tht0dz = app.d0dtEditField.Value;
            assignin('base','t0dz',[app.tht0dz,app.btt0dz,app.gmt0dz])
        end

        % Value changed function: d0dtEditField_2
        function d0dtEditField_2ValueChanged(app, event)
             app.btt0dz = app.d0dtEditField_2.Value;
             assignin('base','t0dz',[app.tht0dz,app.btt0dz,app.gmt0dz])
        end

        % Value changed function: d0dtEditField_3
        function d0dtEditField_3ValueChanged(app, event)
             app.gmt0dz = app.d0dtEditField_3.Value;
             assignin('base','t0dz',[app.tht0dz,app.btt0dz,app.gmt0dz])
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReferenceFrameDropDownLabel = uilabel(app.UIFigure);
            app.ReferenceFrameDropDownLabel.Text = 'Reference Frame';
            app.ReferenceFrameDropDown = uidropdown(app.UIFigure);
            app.ReferenceFrameDropDown.Items = {'Body reference frame', 'Orbit reference frame', 'ECI frame'};
            app.ReferenceFrameDropDown.Value = 'Body reference frame';
            app.q1EditFieldLabel = uilabel(app.UIFigure);
            app.q1EditFieldLabel.Text = 'q1';
            app.q1EditField = uieditfield(app.UIFigure, 'numeric');
            app.q1EditField.ValueChangedFcn = createCallbackFcn(app, @q1EditFieldValueChanged, true);
            app.q1EditField.Value = 1;
            app.q2EditField_2Label = uilabel(app.UIFigure);
            app.q2EditField_2Label.Text = 'q2';
            app.q2EditField = uieditfield(app.UIFigure, 'numeric');
            app.q2EditField.ValueChangedFcn = createCallbackFcn(app, @q2EditFieldValueChanged, true);
            app.q3EditFieldLabel = uilabel(app.UIFigure);
            app.q3EditFieldLabel.Text = 'q3';
            app.q3EditField = uieditfield(app.UIFigure, 'numeric');
            app.q3EditField.ValueChangedFcn = createCallbackFcn(app, @q3EditFieldValueChanged, true);
            app.q4EditFieldLabel = uilabel(app.UIFigure);
            app.q4EditFieldLabel.Text = 'q4';
            app.q4EditField = uieditfield(app.UIFigure, 'numeric');
            app.q4EditField.ValueChangedFcn = createCallbackFcn(app, @q4EditFieldValueChanged, true);
            app.d0dtEditFieldLabel = uilabel(app.UIFigure);
            app.d0dtEditFieldLabel.Text = 'dθ0/dt';
            app.d0dtEditField = uieditfield(app.UIFigure, 'numeric');
            app.d0dtEditField.ValueChangedFcn = createCallbackFcn(app, @d0dtEditFieldValueChanged, true);
            app.d0dtEditField.Value = 0.2;
            app.d0dtLabel = uilabel(app.UIFigure);
            app.d0dtLabel.Text = 'dβ0/dt';
            app.d0dtEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.d0dtEditField_2.ValueChangedFcn = createCallbackFcn(app, @d0dtEditField_2ValueChanged, true);
            app.d0dtEditField_2.Value = 0.2;
            app.d0dtLabel_2 = uilabel(app.UIFigure);
            app.d0dtLabel_2.Text = 'dγ0/dt';
            app.d0dtEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.d0dtEditField_3.ValueChangedFcn = createCallbackFcn(app, @d0dtEditField_3ValueChanged, true);
            app.d0dtEditField_3.Value = 0.2;
            app.radsLabel = uilabel(app.UIFigure);
            app.radsLabel.Text = 'rad/s';
            app.radsLabel_2 = uilabel(app.UIFigure);
            app.radsLabel_2.Text = 'rad/s';
            app.radsLabel_3 = uilabel(app.UIFigure);
            app.radsLabel_3.Text = 'rad/s';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AttitudeLabel = uilabel(app.UIFigure);
            app.AttitudeLabel.Text = 'Attitude';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = attitude(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end