classdef combination < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CombinationsFoundPanel  matlab.ui.container.Panel
        ThefollowingcombinationssatisfyyourlimitationsTextAreaLabel  matlab.ui.control.Label
        ThefollowingcombinationssatisfyyourlimitationsTextArea  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        mass_max = 48;
        power_max = 2630;
        volume_max = 100000;
        volume = 0;
        mass = 0;
        power = 0;
        combo = evalin('base','combo');
        %Reaction Wheels
        RW=evalin('base','RW');
        RWm=evalin('base','RWm');
        RWp=evalin('base','RWp');
        RWx=evalin('base','RWx');
        RWy=evalin('base','RWy');
        RWz=evalin('base','RWz');
        %Magnetorquer
        Mgt=evalin('base','Mgt');
        Mgtm=evalin('base','Mgtm');
        Mgtp=evalin('base','Mgtp');
        Mgtx=evalin('base','Mgtx');
        Mgty=evalin('base','Mgty');
        Mgtz=evalin('base','Mgtz');
        %Magnetometer
        Mgm=evalin('base','Mgm');
        Mgmm=evalin('base','Mgmm');
        Mgmp=evalin('base','Mgmp');
        Mgmx=evalin('base','Mgmx');
        Mgmy=evalin('base','Mgmy');
        Mgmz=evalin('base','Mgmz');
        %Gyros
        G=evalin('base','G');
        Gm=evalin('base','Gm');
        Gp=evalin('base','Gp');
        Gx=evalin('base','Gx');
        Gy=evalin('base','Gy');
        Gz=evalin('base','Gz');
        
        %FSS
        FSS=evalin('base','FSS');
        FSSm=evalin('base','FSSm');
        FSSp=evalin('base','FSSp');
        FSSx=evalin('base','FSSx');
        FSSy=evalin('base','FSSy');
        FSSz=evalin('base','FSSz');
        %CSS
        CSS=evalin('base','CSS');
        CSSm=evalin('base','CSSm');
        CSSp=evalin('base','CSSp');
        CSSx=evalin('base','CSSx');
        CSSy=evalin('base','CSSy');
        CSSz=evalin('base','CSSz'); 
        % Counters
        i=0;
        j=1;
        k=1;
        l=1;
        m=1;
        n=1;
        o=1; 
    end
    
    methods (Access = private)
        
        
        function OpeningFcn(app)
 a=exist('app.G');
 disp(a)
 disp(app.G(1,1))     
 a=size(app.G,1);
 disp(a);
 
while app.i<size(app.G,1)
    app.j=1;
    app.i=app.i+1;
    app.k=1;
    app.l=1;
    app.m=1;
    app.n=1;
 
  while app.j <= size(app.Mgm,1)
        app.k=1;
        app.l=1;
        app.m=1;
        app.n=1;
      
        while app.k <= size(app.Mgt,1)
            app.l=1;
            app.m=1;
            app.n=1;
        
            while app.l <= size(app.RW,1)
                app.m=1;
                app.n=1;
                while app.m <= size(app.FSS,1)
                    app.n=1;
                    while app.n <= size(app.CSS,1)
                        app.mass = app.Gm(app.i,:)+app.Mgmm(app.j,:)+app.Mgtm(app.k,:)+app.RWm(app.l,:)+app.FSSm(app.m,:)+app.CSSm(app.n,:);
                        app.power = app.Gp(app.i,:)+app.Mgmp(app.j,:)+app.Mgtp(app.k,:)+app.RWp(app.l,:)+app.FSSp(app.m,:)+app.CSSp(app.n,:);
                        app.volume = app.Gx(app.i,:).*app.Gy(app.i,:).*app.Gz(app.i,:)+app.Mgmx(app.j,:).*app.Mgmy(app.j,:).*app.Mgmz(app.j,:)+app.Mgtx(app.k,:).*app.Mgty(app.k,:).*app.Mgtz(app.k,:)+app.RWx(app.l,:).*app.RWy(app.l,:).*app.RWz(app.l,:)+app.FSSx(app.m,:).*app.FSSy(app.m,:).*app.FSSz(app.m,:)+app.CSSx(app.n,:).*app.CSSy(app.n,:).*app.CSSz(app.n,:);
                        if app.mass<=app.mass_max && app.power<=app.power_max && app.volume<=app.volume_max
                            app.combo(app.o,:)=strcat(app.G(app.i,:), '+', app.Mgm(app.j,:), '+', app.Mgt(app.k,:), '+', app.RW(app.l,:), '+', app.FSS(app.m,:), '+', app.CSS(app.n,:));
                            disp(app.combo(app.o,:))
                            app.o=app.o+1;
                        end
                        app.n=app.n+1;
                    end     
                app.m=app.m+1;
                end
                
                app.l=app.l+1;
                app.mass=0;
            end
          
            app.k=app.k+1; 
        end
       
        app.j=app.j+1;
  end
   
    %i=1+1;   
end

        end
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CombinationsFoundPanel = uipanel(app.UIFigure);
            app.CombinationsFoundPanel.Title = 'Combinations Found';
            app.ThefollowingcombinationssatisfyyourlimitationsTextAreaLabel = uilabel(app.CombinationsFoundPanel);
            app.ThefollowingcombinationssatisfyyourlimitationsTextAreaLabel.Text = 'The following combinations  satisfy your limitations';
            app.ThefollowingcombinationssatisfyyourlimitationsTextArea = uitextarea(app.CombinationsFoundPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = combination

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end