classdef menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainSystemTab                   matlab.ui.container.Tab
        RunSimulinkButton               matlab.ui.control.Button
        StopTimesecondsEditFieldLabel   matlab.ui.control.Label
        StopTimesecondsEditField        matlab.ui.control.NumericEditField
        SolverDropDownLabel             matlab.ui.control.Label
        SolverDropDown                  matlab.ui.control.DropDown
        ADCSSimulatorforCubeSatLabel    matlab.ui.control.Label
        StepSizesecondsEditFieldLabel   matlab.ui.control.Label
        StepSizesecondsEditField        matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.EditField
        SpacecraftandEnvironmentTab     matlab.ui.container.Tab
        PerturbationsButton             matlab.ui.control.Button
        StructureButton                 matlab.ui.control.Button
        AttitudeButton                  matlab.ui.control.Button
        OrbitButton                     matlab.ui.control.Button
        SpacecraftParametersLabel       matlab.ui.control.Label
        OrbitParametersandEnvironmentLabel  matlab.ui.control.Label
        AttitudeLabel                   matlab.ui.control.Label
        StructureLabel                  matlab.ui.control.Label
        SpacecraftOrbitLabel            matlab.ui.control.Label
        OrbitEnvironmentLabel           matlab.ui.control.Label
        SensorsActuatorsTab             matlab.ui.container.Tab
        AddSensorButton                 matlab.ui.control.Button
        AddActuatorButton               matlab.ui.control.Button
        AvailableSensorsLabel           matlab.ui.control.Label
        AvailableSensorsDropDown        matlab.ui.control.DropDown
        AvailableActuatorsDropDownLabel  matlab.ui.control.Label
        AvailableActuatorsDropDown      matlab.ui.control.DropDown
        Tree                            matlab.ui.container.Tree
        SensorsNode                     matlab.ui.container.TreeNode
        ActuatorsNode                   matlab.ui.container.TreeNode
        EditButton                      matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        SensorsActuatorsLabel           matlab.ui.control.Label
        AlgorithmsTab                   matlab.ui.container.Tab
        EstimatorLabel                  matlab.ui.control.Label
        EstimatorDropDown               matlab.ui.control.DropDown
        DetumblingModeLabel             matlab.ui.control.Label
        DetumblingModeDropDown          matlab.ui.control.DropDown
        PointingModeLabel               matlab.ui.control.Label
        PointingModeDropDown            matlab.ui.control.DropDown
        AlgorithmsLabel                 matlab.ui.control.Label
        ViewTab                         matlab.ui.container.Tab
        MeasuredBerrorCheckBox          matlab.ui.control.CheckBox
        SENSORERRORSLabel               matlab.ui.control.Label
        MeasuredSunvectorerrorCheckBox  matlab.ui.control.CheckBox
        MeasuredSunAngleerrorCheckBox   matlab.ui.control.CheckBox
        MeasuredRateserrorCheckBox      matlab.ui.control.CheckBox
        OBCERRORSLabel                  matlab.ui.control.Label
        ComputedPositionErrorCheckBox   matlab.ui.control.CheckBox
        ComputedVelocityErrorCheckBox   matlab.ui.control.CheckBox
        ComputedSunVectorErrorCheckBox  matlab.ui.control.CheckBox
        ComputedMagneticFieldErrorCheckBox  matlab.ui.control.CheckBox
        ComputedTotalTorqueErrorCheckBox  matlab.ui.control.CheckBox
        POINTINGERRORLabel              matlab.ui.control.Label
        PointingErrorYPRCheckBox        matlab.ui.control.CheckBox
        WPointingErrorCheckBox          matlab.ui.control.CheckBox
        DETERMINATIONERRORLabel         matlab.ui.control.Label
        PointingestimatederrorCheckBox  matlab.ui.control.CheckBox
        RatesestimationerrorCheckBox    matlab.ui.control.CheckBox
        POWERLabel                      matlab.ui.control.Label
        PowerusedbyactuatorsCheckBox    matlab.ui.control.CheckBox
        PoweravailableCheckBox          matlab.ui.control.CheckBox
        CombinationFinderTab            matlab.ui.container.Tab
        mWLabel                         matlab.ui.control.Label
        gLabel                          matlab.ui.control.Label
        mm3Label                        matlab.ui.control.Label
        SpacecraftLimitationsLabel      matlab.ui.control.Label
        FindCombinationButton           matlab.ui.control.Button
        CheckBox                        matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        MaxVolumeEditFieldLabel         matlab.ui.control.Label
        MaxVolumeEditField              matlab.ui.control.NumericEditField
        MaxMassEditFieldLabel           matlab.ui.control.Label
        MaxMassEditField                matlab.ui.control.NumericEditField
        MaxPowerEditFieldLabel          matlab.ui.control.Label
        MaxPowerEditField               matlab.ui.control.NumericEditField
        NumberofelementsLabel           matlab.ui.control.Label
        ReactionWheelsEditFieldLabel    matlab.ui.control.Label
        ReactionWheelsEditField         matlab.ui.control.NumericEditField
        MagnetorquerEditFieldLabel      matlab.ui.control.Label
        MagnetorquerEditField           matlab.ui.control.NumericEditField
        CoarseSunSensorEditFieldLabel   matlab.ui.control.Label
        CoarseSunSensorEditField        matlab.ui.control.NumericEditField
        MagnetometersEditFieldLabel     matlab.ui.control.Label
        MagnetometersEditField          matlab.ui.control.NumericEditField
        GyroscopesEditFieldLabel        matlab.ui.control.Label
        GyroscopesEditField             matlab.ui.control.NumericEditField
        FineSunSensorEditFieldLabel     matlab.ui.control.Label
        FineSunSensorEditField          matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        CheckBox_4                      matlab.ui.control.CheckBox
        MaxPriceEditFieldLabel          matlab.ui.control.Label
        MaxPriceEditField               matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        % Counters
        n = 1;
        n_2 = 1; % Description
        n_F_SS = 1;
        n_Magnetometer = 0;
        n_Gyro = 0;
        aux = [0,0,0,0];
        aux2 = [0,0];
        aux_environment = [0,0,0];
        mass_max=0;
        power_max=0;
        volume_max=0;
        price_max=0;
              
        % Strings
        C_SS_str;
        F_SS_str;
        Magnetometer_str;
        Gyro_str;
        
        Magnetorquer_str;
        Momentum_Wheel_str;
        
        % Simulation Parameters
        stop_time;
        solver;
        step_size;
        Sim
        
        %Plots
        b_error
        meas_sun_vec
        meas_sun_ang
        pos_error
        vel_error
        comp_sun_vec
        comp_mag_error
        comp_tot_torque_error
        pointing_error
        w_error
        meas_rate_error
        est_error
        Rates_estimation_error
        power_actuators
        power_avb
        
        %Mass, Volume, Power Specifications
        max_mass = 0;
        max_power = 0;
        max_x = 0;
        max_y = 0;
        max_z = 0;
        mass=0;        
        mass_c=0;
        mass_f=0;
        mass_mt=0;
        mass_r=0;
        mass_mm_1=0;
        mass_mm_2=0;
        mass_mm_3=0;
        mass_g_1=0;
        mass_g_2=0;
        mass_g_3=0;
        value_1=0;
        value_2=0;
        value_3=0;
        value_4=0;
        n_RW=1;
        n_Mgt=1;
        n_Mgm=1;
        n_G=1;
        n_FSS=1;
        n_CSS=1;
        
        
        %Modos
        estimator
        detumbling
        pointing_select
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %------------------------------------------------------
            %--------------------- MUDAR ISTO ---------------------
            %------------------------------------------------------
            %evalin('base','clear')
            %clc
            addpath('D:\Universidade\Engenharia Aeroespacial\5º Ano\1º Semestre\Projecto Aeroespacial\Projeto\ADCS_simulink_gui')                           
            
            app.stop_time = app.StopTimesecondsEditField.Value;
            app.solver = app.SolverDropDown.Value;
            app.step_size = app.StepSizesecondsEditField.Value;
            app.max_mass = app.MaxMassEditField.Value;
            
            
            data = load('workspace_final.mat');
            assignin('base','Data',data)
            
            % Ir logo buscar o valor dos modos
            app.estimator = app.EstimatorDropDown.Value;
            
            if strcmp(app.estimator,'None')
                assignin('base','Est_mode',0)
            elseif strcmp(app.estimator,'MEMS Rate')
                assignin('base','Est_mode',1)
            elseif strcmp(app.estimator,'Mag Rate')
                assignin('base','Est_mode',2)
            elseif strcmp(app.estimator,'Mag Rate + Pitch')
                assignin('base','Est_mode',3)
            elseif strcmp(app.estimator,'TRIAD + w measure')
                assignin('base','Est_mode',4)
            elseif strcmp(app.estimator,'TRIAD + Mag Rate')
                assignin('base','Est_mode',5)
            elseif strcmp(app.estimator,'Gyro MEKF')
                assignin('base','Est_mode',6) 
            elseif strcmp(app.estimator,'Full State AEKF')
                assignin('base','Est_mode',7)     
            end     
            
            app.detumbling = app.DetumblingModeDropDown.Value;
            
            if strcmp(app.detumbling,'Pitch-only')
                assignin('base','Detumb_mode',0)
            elseif strcmp(app.detumbling,'Y-Thomson Spin')
                assignin('base','Detumb_mode',1)
            elseif strcmp(app.detumbling,'Fast')
                assignin('base','Detumb_mode',2)
            elseif strcmp(app.detumbling,'Very Fast')
                assignin('base','Detumb_mode',3)   
            end 
            
            app.pointing_select = app.PointingModeDropDown.Value;
            
            if strcmp(app.pointing_select,'Nadir Poiting')
                assignin('base','Point_mode',1)
            elseif strcmp(app.pointing_select,'Pitch Sun Tracking')
                assignin('base','Point_mode',2)
            end            
            
            set_param('Pitch_Sun_Track/SENSOR MODEL/Enabled_Gyro_1','Commented','on')
            set_param('Pitch_Sun_Track/SENSOR MODEL/Enabled_Gyro_2','Commented','on')
            set_param('Pitch_Sun_Track/SENSOR MODEL/Enabled_Gyro_3','Commented','on')
            set_param('Pitch_Sun_Track/SENSOR MODEL/Enabled_Mag_1','Commented','on')
            set_param('Pitch_Sun_Track/SENSOR MODEL/Enabled_Mag_2','Commented','on')
            set_param('Pitch_Sun_Track/SENSOR MODEL/Enabled_Mag_3','Commented','on')
            
            assignin('base','new_Gyro_1', 0);
            assignin('base','new_Gyro_2', 0);
            assignin('base','new_Gyro_3', 0);
            assignin('base','new_Mag_1', 0);
            assignin('base','new_Mag_2', 0);
            assignin('base','new_Mag_3', 0);
        end

        % Button pushed function: AddSensorButton
        function AddSensorButtonPushed(app, event)
            % Logic for sensor Three
            
            switch app.AvailableSensorsDropDown.Value
                case 'Coarse Sun Sensor'
                    
                    str = app.SensorsNode.Children;
                    while app.n <= size(str,1)
                        str_text = str(app.n).Text(1:6);
                        str_num = char(regexp(str(app.n).Text,'\d*','Match'));
                        if strcmp(str_text,'Coarse')==1 && strcmp(str_num,num2str(app.n_2))==1
                            app.n_2 = app.n_2 + 1;
                            app.n = 1;                       
                        else
                        app.n = app.n + 1;
                        end
                    end
                    app.C_SS_str = ['Coarse Sun Sersor ', num2str(app.n_2)];
                    
                    uitreenode(app.SensorsNode,'Text',app.C_SS_str);
                    app.n = 1;
                    app.n_2 = 1;
                    
                    expand(app.Tree);
                    
                case 'Fine Sun Sensor'                
                    
                    str = app.SensorsNode.Children;                   
                    while app.n <= size(str,1)
                        str_text = str(app.n).Text(1:4);
                        str_num = char(regexp(str(app.n).Text,'\d*','Match'));
                        if strcmp(str_text,'Fine')==1 && strcmp(str_num,num2str(app.n_2))==1
                            app.n_2 = app.n_2 + 1;
                            app.n = 1;                       
                        else
                        app.n = app.n + 1;
                        end
                    end
                    app.F_SS_str = ['Fine Sun Sersor ', num2str(app.n_2)];
                    
                    uitreenode(app.SensorsNode,'Text',app.F_SS_str);
                    app.n = 1;
                    app.n_2 = 1;
                    
                    expand(app.Tree);
                    
                case 'Magnetometer'
                             
                    str = app.SensorsNode.Children;                   
                    while app.n <= size(str,1)
                        str_text = str(app.n).Text(1:9);
                        str_num = char(regexp(str(app.n).Text,'\d*','Match'));
                        if strcmp(str_text,'Magnetome')==1 && strcmp(str_num,num2str(app.n_2))==1
                            app.n_2 = app.n_2 + 1;
                            app.n = 1; 
                            app.n_Magnetometer = app.n_Magnetometer +1;
                            
                        else
                        app.n = app.n + 1;
                        app.n_Magnetometer = app.n_Magnetometer + 1;
                        end
                    end
                    app.Magnetometer_str = ['Magnetometer ', num2str(app.n_2)];
                    
                    uitreenode(app.SensorsNode,'Text',app.Magnetometer_str);
                    app.n = 1;
                    app.n_2 = 1;
                    expand(app.Tree);                  
                    
                case 'Gyroscope'
                    
                    str = app.SensorsNode.Children;                   
                    while app.n <= size(str,1)
                        str_text = str(app.n).Text(1:9);
                        str_num = char(regexp(str(app.n).Text,'\d*','Match'));
                        if strcmp(str_text,'Gyroscope')==1 && strcmp(str_num,num2str(app.n_2))==1
                            app.n_2 = app.n_2 + 1;
                            app.n = 1;
                            app.n_Gyro = app.n_Gyro + 1;
                        else
                        app.n = app.n + 1;
                        app.n_Gyro = app.n_Gyro + 1;
                        end
                    end
                    app.Gyro_str = ['Gyroscope ', num2str(app.n_2)];
                    
                    uitreenode(app.SensorsNode,'Text',app.Gyro_str);
                    app.n = 1;
                    app.n_2 = 1;         
                    expand(app.Tree);
                    
            end
        end

        % Value changed function: AvailableSensorsDropDown
        function AvailableSensorsDropDownValueChanged(app, event)
            % Pedro - Working on it
            %value_sensor_select = app.AvailableSensorsDropDown.Value;            
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
          % Logic to remove sensors from tree
            
            if isprop(app.Tree.SelectedNodes, 'Text')
                if strcmp(app.Tree.SelectedNodes.Text,'Sensors:') ~= 1 && strcmp(app.Tree.SelectedNodes.Text,'Actuators:') ~= 1
                    % If sensor clear flags
                    if strcmp(app.Tree.SelectedNodes.Text(1:6),'Coarse') == 1
                        flag = str2double(app.Tree.SelectedNodes.Text(19:end));
                        app.aux(1) = app.aux(1) - 10^(flag-1);
                        app.mass_c=0;
                    elseif strcmp(app.Tree.SelectedNodes.Text(1:4),'Fine') == 1
                        flag = str2double(app.Tree.SelectedNodes.Text(17:end));
                        app.aux(2) = app.aux(2) - 10^(flag-1);
                        app.mass_f=0;
                    elseif strcmp(app.Tree.SelectedNodes.Text(1:9),'Magnetome') == 1   
                        flag = str2double(app.Tree.SelectedNodes.Text(14:end));
                        app.aux(3) = app.aux(3) - 10^(flag-1);
                        assignin('base',['new_Mag_',app.Tree.SelectedNodes.Text(14:end)],0)
                        if str2double(app.Tree.SelectedNodes.Text(14:end)) == 1
                            app.mass_mm_1 = 0;
                        elseif str2double(app.Tree.SelectedNodes.Text(14:end)) == 2
                            app.mass_mm_2 = 0;
                        elseif str2double(app.Tree.SelectedNodes.Text(14:end)) == 3
                            app.mass_mm_3 = 0;
                        end
                           
                        
                    elseif strcmp(app.Tree.SelectedNodes.Text(1:9),'Gyroscope') == 1
                        flag = str2double(app.Tree.SelectedNodes.Text(11:end));
                        app.aux(4) = app.aux(4) - 10^(flag-1);
                        assignin('base',['new_Gyro_',app.Tree.SelectedNodes.Text(11:end)],0)
                        if str2double(app.Tree.SelectedNodes.Text(14:end)) == 1
                            app.mass_g_1 = 0;
                        elseif str2double(app.Tree.SelectedNodes.Text(14:end)) == 2
                            app.mass_g_2 = 0;
                        elseif str2double(app.Tree.SelectedNodes.Text(14:end)) == 3
                            app.mass_g_3 = 0;
                        end
                        
                        % If actuator clear flags
                    elseif strcmp(app.Tree.SelectedNodes.Text(1:9),'Magnetorq') == 1
                        flag = str2double(app.Tree.SelectedNodes.Text(14:end));
                        app.aux2(1) = app.aux2(1) - 10^(flag-1);
                        app.mass_mt=0;
                    elseif strcmp(app.Tree.SelectedNodes.Text(1:4),'Mome') == 1
                        flag = str2double(app.Tree.SelectedNodes.Text(16:end));
                        app.aux2(2) = app.aux2(2) - 10^(flag-1);
                        app.mass_r=0;
                    end
                    
                    % Delete Node
                    arrayfun(@delete,app.Tree.SelectedNodes)
                    
                elseif strcmp(app.Tree.SelectedNodes.Text,'Sensors:') == 1
                    % Clear all flags
                    app.aux = [0,0,0,0];
                    
                    % Delete Three
                    arrayfun(@delete,app.SensorsNode.Children)
                    
                elseif strcmp(app.Tree.SelectedNodes.Text,'Actuators:') == 1
                    % Clear all flags
                    app.aux2 = [0,0];
                    
                    % Delete Three
                    arrayfun(@delete,app.ActuatorsNode.Children)
                end
                
            else
                % Do nothing
            end  
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
        % Logic to edit sensors and actuators from tree
            
        if isprop(app.Tree.SelectedNodes, 'Text')
            
            % Logic to edit sensors from tree
            
            if strcmp(app.Tree.SelectedNodes.Text(1:6),'Coarse') == 1
                C_SS_gui(app.Tree.SelectedNodes.Text(19:end),app.aux(1))     %(number,flag)
                
                %Flag to control if sensor has been edited or not
                flag = str2double(app.Tree.SelectedNodes.Text(19:end));
                app.aux(1) = rem(app.aux(1),10^(flag-1)) + floor(app.aux(1)/(10^(flag)))*10^(flag) + 10^(flag-1);
                app.mass_c= getappdata(0,'mass_c');
            elseif strcmp(app.Tree.SelectedNodes.Text(1:4),'Fine') == 1
                F_SS_gui(app.Tree.SelectedNodes.Text(17:end),app.aux(2))      %(number,flag)          
                
                %Flag to control if sensor has been edited or not
                flag = str2double(app.Tree.SelectedNodes.Text(17:end));
                app.aux(2) = rem(app.aux(2),10^(flag-1)) + floor(app.aux(2)/(10^(flag)))*10^(flag) + 10^(flag-1);
                app.mass_f= getappdata(0,'mass_f');
            elseif strcmp(app.Tree.SelectedNodes.Text(1:9),'Magnetome') == 1    
                Magnetometer_gui(app.Tree.SelectedNodes.Text(14:end),app.aux(3))   %(number,flag)
                
                %Flag to control if sensor has been edited or not
                flag = str2double(app.Tree.SelectedNodes.Text(14:end));
                app.aux(3) = rem(app.aux(3),10^(flag-1)) + floor(app.aux(3)/(10^(flag)))*10^(flag) + 10^(flag-1);
                app.mass_mm_1 = getappdata(0,'mass_mm_1');
                app.mass_mm_2 = getappdata(0,'mass_mm_2');
                app.mass_mm_3 = getappdata(0,'mass_mm_3');
            elseif strcmp(app.Tree.SelectedNodes.Text(1:9),'Gyroscope') == 1
                Gyro_gui(app.Tree.SelectedNodes.Text(11:end),app.aux(4))   %(number,flag)
                
                %Flag to control if sensor has been edited or not
                flag = str2double(app.Tree.SelectedNodes.Text(11:end));
                app.aux(4) = rem(app.aux(4),10^(flag-1)) + floor(app.aux(4)/(10^(flag)))*10^(flag) + 10^(flag-1);                
                app.mass_g_1 = getappdata(0,'mass_g_1');
                app.mass_g_2 = getappdata(0,'mass_g_2');
                app.mass_g_3 = getappdata(0,'mass_g_3');
            % Logic to edit Actuators from tree
            
            elseif strcmp(app.Tree.SelectedNodes.Text(1:9),'Magnetorq') == 1
                Magnetorquer_gui(app.Tree.SelectedNodes.Text(14:end),app.aux2(1))     %(number,flag)
                
                %Flag to control if actuator has been edited or not
                flag = str2double(app.Tree.SelectedNodes.Text(14:end));
                app.aux2(1) = rem(app.aux2(1),10^(flag-1)) + floor(app.aux2(1)/(10^(flag)))*10^(flag) + 10^(flag-1);
                app.mass_mt= getappdata(0,'mass_mt');
            elseif strcmp(app.Tree.SelectedNodes.Text(1:4),'Mome') == 1
                ReactionWheel_gui(app.Tree.SelectedNodes.Text(16:end),app.aux2(2))     %(number,flag)          
                app.mass_r= getappdata(0,'mass_r');
                %Flag to control if actuator has been edited or not
                flag = str2double(app.Tree.SelectedNodes.Text(16:end));
                app.aux2(2) = rem(app.aux2(2),10^(flag-1)) + floor(app.aux2(2)/(10^(flag)))*10^(flag) + 10^(flag-1);             
            end
        else
            % Do nothing
        end
        end

        % Button pushed function: RunSimulinkButton
        function RunSimulinkButtonPushed(app, event)
            % ------------Run Simulation-----------------
            
            app.EditField.Value = 'Processing...';
            drawnow()  % <---- tell matlab to update now
            
            evalin('base', 'Controller_gains_definition')
            
            % ATENCAO AO NOME DO FICHEIRO SIMULINK TEM DE SER IGUAL A ESTE
            %for Ts = 0:app.step_size:app.stop_time
            %app.Sim = sim('Pitch_Sun_Track','solver',app.solver,'FixedStep', num2str(app.step_size));
            %set_param('Pitch_Sun_Track','StartTime','0','StopTime',num2str(app.stop_time))
            app.Sim = sim('Pitch_Sun_Track','solver',app.solver,"StartTime","0",'StopTime',num2str(app.stop_time),'FixedStep',num2str(app.step_size));
            %end
            % -------------------------------------------
                  
            app.EditField.Value = 'Complete!';
            drawnow()
            
            
            if app.b_error
                Plot_1_var(app.Sim.ScopeData1,"Measured B error")
            end    
            if app.meas_sun_vec
                Plot_3_var(app.Sim.ScopeData2,"Measured Sun Vector error","Yaw","Pitch","Roll")
            end    
            if app.meas_sun_ang
                Plot_3_var(app.Sim.ScopeData3,"Measured Rates error","Yaw","Pitch","Roll")
            end    
            if app.meas_rate_error
                Plot_3_var(app.Sim.ScopeData4,"Measured Rate error","Yaw","Pitch","Roll")
            end      
            if app.pos_error
                Plot_3_var(app.Sim.ScopeData5,"Computed Position Error","Yaw","Pitch","Roll")
            end    
            if app.vel_error
                Plot_3_var(app.Sim.ScopeData6,"Computed Velocity Error","Yaw","Pitch","Roll")
            end                    
            if app.comp_sun_vec
                Plot_3_var(app.Sim.ScopeData7,"Computed Sun Vector Error","Yaw","Pitch","Roll")
            end    
            if app.comp_mag_error
                Plot_3_var(app.Sim.ScopeData8,"Computed Magnetic Field Error","Yaw","Pitch","Roll")
            end    
            if app.comp_tot_torque_error
                Plot_3_var(app.Sim.ScopeData9,"Computed Total Torque Error","Yaw","Pitch","Roll")
            end    
            if app.pointing_error
                Plot_3_var(app.Sim.ScopeData10,"Pointing Error YPR","Yaw","Pitch","Roll")
            end    
            if app.w_error
                Plot_3_var(app.Sim.ScopeData11,"W Pointing Error","Yaw","Pitch","Roll")
            end    
            if app.Rates_estimation_error
                Plot_3_var(app.Sim.ScopeData12,"Rates_estimation_error","M_x","M_y","M_z")
            end
            if app.est_error
                Plot_1_var(app.Sim.est_err,"Estimation Error","Estimation error")
            end
            if app.power_actuators
                Plot_1_var(app.Sim.ScopeData13,"Power used by actuators","Power used by actuators")
            end
            if app.power_avb
                Plot_1_var(app.Sim.ScopeData14,"Power available","Power available")
            end
        end

        % Value changed function: StopTimesecondsEditField
        function StopTimesecondsEditFieldValueChanged(app, event)
            % Stop time
            app.stop_time = app.StopTimesecondsEditField.Value;  
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Do when close GUI
            delete(app)
        end

        % Value changed function: MeasuredBerrorCheckBox
        function MeasuredBerrorCheckBoxValueChanged(app, event)
            % B Error Check Box
            app.b_error = app.MeasuredBerrorCheckBox.Value;%           
        end

        % Value changed function: MeasuredSunvectorerrorCheckBox
        function MeasuredSunvectorerrorCheckBoxValueChanged(app, event)
            % Measured Sun Vector Check Box
            app.meas_sun_vec = app.MeasuredSunvectorerrorCheckBox.Value;%            
        end

        % Value changed function: MeasuredSunAngleerrorCheckBox
        function MeasuredSunAngleerrorCheckBoxValueChanged(app, event)
            % Measured Sun angle Check Box
            app.meas_sun_ang = app.MeasuredSunAngleerrorCheckBox.Value;%           
        end

        % Value changed function: ComputedPositionErrorCheckBox
        function ComputedPositionErrorCheckBoxValueChanged(app, event)
            % Measured Position Error Check Box
            app.pos_error = app.ComputedPositionErrorCheckBox.Value;%            
        end

        % Value changed function: ComputedVelocityErrorCheckBox
        function ComputedVelocityErrorCheckBoxValueChanged(app, event)
            % Measured Velocity Error Check Box
            app.vel_error = app.ComputedVelocityErrorCheckBox.Value;%            
        end

        % Value changed function: ComputedSunVectorErrorCheckBox
        function ComputedSunVectorErrorCheckBoxValueChanged(app, event)
            % Computed Sun Vector Check Box
            app.comp_sun_vec = app.ComputedSunVectorErrorCheckBox.Value;%           
        end

        % Value changed function: ComputedMagneticFieldErrorCheckBox
        function ComputedMagneticFieldErrorCheckBoxValueChanged(app, event)
            % Computed Magnetic Field Error Check Box
            app.comp_mag_error = app.ComputedMagneticFieldErrorCheckBox.Value;%            
        end

        % Value changed function: ComputedTotalTorqueErrorCheckBox
        function ComputedTotalTorqueErrorCheckBoxValueChanged(app, event)
            % Computed Total Torque Error Check Box
            app.comp_tot_torque_error = app.ComputedTotalTorqueErrorCheckBox.Value;%            
        end

        % Value changed function: PointingErrorYPRCheckBox
        function PointingErrorYPRCheckBoxValueChanged(app, event)
            % Pointing Error Check Box
            app.pointing_error = app.PointingErrorYPRCheckBox.Value;%           
        end

        % Value changed function: WPointingErrorCheckBox
        function WPointingErrorCheckBoxValueChanged(app, event)
            % W Error Check Box
            app.w_error = app.WPointingErrorCheckBox.Value;%            
        end

        % Value changed function: MeasuredRateserrorCheckBox
        function MeasuredRateserrorCheckBoxValueChanged(app, event)
            % Measured Rate Error Check Box
            app.meas_rate_error = app.MeasuredRateserrorCheckBox.Value;%           
        end

        % Value changed function: PointingestimatederrorCheckBox
        function PointingestimatederrorCheckBoxValueChanged(app, event)
            % Estimated Error Check Box
            app.est_error = app.PointingestimatederrorCheckBox.Value;%          
        end

        % Button pushed function: AddActuatorButton
        function AddActuatorButtonPushed(app, event)
            % Logic for actuator Three
            
            switch app.AvailableActuatorsDropDown.Value
                case 'Magnetorquer'
                    
                    str = app.ActuatorsNode.Children;
                    while app.n <= size(str,1)
                        str_text = str(app.n).Text(1:9);
                        str_num = char(regexp(str(app.n).Text,'\d*','Match'));
                        if strcmp(str_text,'Magnetorq')==1 && strcmp(str_num,num2str(app.n_2))==1
                            app.n_2 = app.n_2 + 1;
                            app.n = 1;                       
                        else
                        app.n = app.n + 1;
                        end
                    end
                    app.Magnetorquer_str = ['Magnetorquer ', num2str(app.n_2)];
                    
                    uitreenode(app.ActuatorsNode,'Text',app.Magnetorquer_str);
                    app.n = 1;
                    app.n_2 = 1;
                    
                    expand(app.Tree);
               
                case 'Momentum Wheel'
                    
                    str = app.ActuatorsNode.Children;
                    while app.n <= size(str,1)
                        str_text = str(app.n).Text(1:4);
                        str_num = char(regexp(str(app.n).Text,'\d*','Match'));
                        if strcmp(str_text,'Mome')==1 && strcmp(str_num,num2str(app.n_2))==1
                            app.n_2 = app.n_2 + 1;
                            app.n = 1;                       
                        else
                        app.n = app.n + 1;
                        end
                    end
                    app.Momentum_Wheel_str = ['Momentum Wheel ', num2str(app.n_2)];
                    
                    uitreenode(app.ActuatorsNode,'Text',app.Momentum_Wheel_str);
                    app.n = 1;
                    app.n_2 = 1;
                    
                    expand(app.Tree);     
            end
        end

        % Value changed function: RatesestimationerrorCheckBox
        function RatesestimationerrorCheckBoxValueChanged(app, event)
           app.Rates_estimation_error = app.RatesestimationerrorCheckBox.Value;   
        end

        % Value changed function: SolverDropDown
        function SolverDropDownValueChanged(app, event)
            app.solver = app.SolverDropDown.Value;
        end

        % Button pushed function: PerturbationsButton
        function PerturbationsButtonPushed2(app, event)
            % Button Perturbations
            EnvironmentandPerturbations
        end

        % Value changed function: StepSizesecondsEditField
        function StepSizesecondsEditFieldValueChanged(app, event)
            app.step_size = app.StepSizesecondsEditField.Value;
            
        end

        % Button pushed function: OrbitButton
        function OrbitButtonPushed(app, event)
            orbit(app.aux_environment(1,3))
            app.aux_environment = app.aux_environment + [0,0,1];
        end

        % Button pushed function: AttitudeButton
        function AttitudeButtonPushed(app, event)
            attitude(app.aux_environment(1,2))
            app.aux_environment = app.aux_environment + [0,1,0];
        end

        % Button pushed function: StructureButton
        function StructureButtonPushed(app, event)
            structure(app.aux_environment(1,1))
            app.aux_environment = app.aux_environment + [1,0,0];
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            %value = app.EditField.Value;
            
        end

        % Value changed function: EstimatorDropDown
        function EstimatorDropDownValueChanged(app, event)
            app.estimator = app.EstimatorDropDown.Value;
            
            if strcmp(app.estimator,'None')
                assignin('base','Est_mode',0)
            elseif strcmp(app.estimator,'MEMS Gyro Rate')
                assignin('base','Est_mode',1)
            elseif strcmp(app.estimator,'Mag Rate')
                assignin('base','Est_mode',2)
            elseif strcmp(app.estimator,'TRIAD + MEMS Gyro Rate')
                assignin('base','Est_mode',4)
            elseif strcmp(app.estimator,'TRIAD + Mag Rate')
                assignin('base','Est_mode',5)
            elseif strcmp(app.estimator,'Gyro MEKF')
                assignin('base','Est_mode',6) 
            elseif strcmp(app.estimator,'Full State AEKF')
                assignin('base','Est_mode',7)     
            end             
        end

        % Value changed function: DetumblingModeDropDown
        function DetumblingModeDropDownValueChanged(app, event)
            app.detumbling = app.DetumblingModeDropDown.Value;
            
            if strcmp(app.detumbling,'Pitch-only')
                assignin('base','Detumb_mode',0)
            elseif strcmp(app.detumbling,'Y-Thomson Spin')
                assignin('base','Detumb_mode',1)
            elseif strcmp(app.detumbling,'Fast')
                assignin('base','Detumb_mode',2)
            elseif strcmp(app.detumbling,'Very Fast')
                assignin('base','Detumb_mode',3)   
            end   
        end

        % Value changed function: PointingModeDropDown
        function PointingModeDropDownValueChanged(app, event)
            app.pointing_select = app.PointingModeDropDown.Value;
            
            if strcmp(app.pointing_select,'Nadir Poiting')
                assignin('base','Point_mode',1)
            elseif strcmp(app.pointing_select,'Pitch Sun Tracking')
                assignin('base','Point_mode',2)
            end  
        end

        % Button pushed function: FindCombinationButton
        function FindCombinationButtonPushed(app, event)
           disp(app.mass_max)
           disp(app.volume_max)
           disp(app.power_max)
           disp(app.price_max)
           trying(app.mass_max, app.volume_max, app.power_max, app.n_RW, app.n_Mgt, app.n_Mgm, app.n_G, app.n_FSS, app.n_CSS,app.price_max)
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            app.value_1 = app.CheckBox.Value;
            if app.value_1 ==1
                app.mass_max = app.MaxMassEditField.Value;
            else
                app.mass_max = 1000000000000;
            end
            
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            app.value_2 = app.CheckBox_2.Value;
            if app.value_2 ==1
                app.volume_max = app.MaxVolumeEditField.Value;
            else
                app.volume_max = 1000000000000;
            end
            
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            app.value_3 = app.CheckBox_3.Value;
            if app.value_3 ==1
                app.power_max = app.MaxPowerEditField.Value;
            else
                app.power_max = 1000000000000;
            end
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
               app.value_4 = app.CheckBox_4.Value;
            if app.value_4 ==1
                app.price_max = app.MaxPriceEditField.Value;
            else
                app.price_max = 1000000000000000;
            end
            
        end

        % Value changed function: MaxMassEditField
        function MaxMassEditFieldValueChanged(app, event)
            app.max_mass = app.MaxMassEditField.Value;
            if app.value_1==1
                app.mass_max = app.MaxMassEditField.Value;
            else
                app.mass_max = 1000000000000;
            end
        end

        % Value changed function: MaxVolumeEditField
        function MaxVolumeEditFieldValueChanged(app, event)
            if app.value_2==1
                app.volume_max = app.MaxVolumeEditField.Value;
            else 
               app.volume_max =1000000000000;
            end
        end

        % Value changed function: MaxPowerEditField
        function MaxPowerEditFieldValueChanged(app, event)
           if app.value_3==1
                app.power_max = app.MaxPowerEditField.Value;
            else 
               app.power_max =1000000000000;
            end 
            
        end

        % Value changed function: MaxPriceEditField
        function MaxPriceEditFieldValueChanged(app, event)
            if app.value_4==1
                app.price_max = app.MaxPriceEditField.Value;
            else 
               app.price_max =1000000000000000;
            end 
        end

        % Value changed function: ReactionWheelsEditField
        function ReactionWheelsEditFieldValueChanged(app, event)
            app.n_RW = app.ReactionWheelsEditField.Value;
            
        end

        % Value changed function: MagnetorquerEditField
        function MagnetorquerEditFieldValueChanged(app, event)
            app.n_Mgt = app.MagnetorquerEditField.Value;
            
        end

        % Value changed function: MagnetometersEditField
        function MagnetometersEditFieldValueChanged(app, event)
            app.n_Mgm = app.MagnetometersEditField.Value;
            
        end

        % Value changed function: CoarseSunSensorEditField
        function CoarseSunSensorEditFieldValueChanged(app, event)
            app.n_CSS = app.CoarseSunSensorEditField.Value;
            
        end

        % Value changed function: GyroscopesEditField
        function GyroscopesEditFieldValueChanged(app, event)
            app.n_G = app.GyroscopesEditField.Value;
            
        end

        % Value changed function: FineSunSensorEditField
        function FineSunSensorEditFieldValueChanged(app, event)
            app.n_FSS = app.FineSunSensorEditField.Value;
            
        end

        % Value changed function: PowerusedbyactuatorsCheckBox
        function PowerusedbyactuatorsCheckBoxValueChanged(app, event)
            % Power used by actuators
            app.power_actuators = app.PowerusedbyactuatorsCheckBox.Value;         
        end

        % Value changed function: PoweravailableCheckBox
        function PoweravailableCheckBoxValueChanged(app, event)
            % Power available
            app.power_avb = app.PoweravailableCheckBox.Value;           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainSystemTab = uitab(app.TabGroup);
            app.MainSystemTab.Title = 'Main System';
            app.RunSimulinkButton = uibutton(app.MainSystemTab, 'push');
            app.RunSimulinkButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulinkButtonPushed, true);
            app.RunSimulinkButton.Text = 'Run Simulink';
            app.StopTimesecondsEditFieldLabel = uilabel(app.MainSystemTab);
            app.StopTimesecondsEditFieldLabel.Text = 'Stop Time (seconds)';
            app.StopTimesecondsEditField = uieditfield(app.MainSystemTab, 'numeric');
            app.StopTimesecondsEditField.ValueChangedFcn = createCallbackFcn(app, @StopTimesecondsEditFieldValueChanged, true);
            app.StopTimesecondsEditField.Value = 200;
            app.SolverDropDownLabel = uilabel(app.MainSystemTab);
            app.SolverDropDownLabel.Text = 'Solver';
            app.SolverDropDown = uidropdown(app.MainSystemTab);
            app.SolverDropDown.Items = {'ode8', 'ode5', 'ode4', 'ode3', 'ode2', 'ode1', 'ode14x', 'ode1be'};
            app.SolverDropDown.ValueChangedFcn = createCallbackFcn(app, @SolverDropDownValueChanged, true);
            app.SolverDropDown.Value = 'ode8';
            app.ADCSSimulatorforCubeSatLabel = uilabel(app.MainSystemTab);
            app.ADCSSimulatorforCubeSatLabel.Text = 'ADCS Simulator for CubeSat';
            app.StepSizesecondsEditFieldLabel = uilabel(app.MainSystemTab);
            app.StepSizesecondsEditFieldLabel.Text = 'Step Size (seconds)';
            app.StepSizesecondsEditField = uieditfield(app.MainSystemTab, 'numeric');
            app.StepSizesecondsEditField.ValueChangedFcn = createCallbackFcn(app, @StepSizesecondsEditFieldValueChanged, true);
            app.StepSizesecondsEditField.Value = 0.1;
            app.EditField = uieditfield(app.MainSystemTab, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.SpacecraftandEnvironmentTab = uitab(app.TabGroup);
            app.SpacecraftandEnvironmentTab.Title = 'Spacecraft and Environment';
            app.PerturbationsButton = uibutton(app.SpacecraftandEnvironmentTab, 'push');
            app.PerturbationsButton.ButtonPushedFcn = createCallbackFcn(app, @PerturbationsButtonPushed2, true);
            app.PerturbationsButton.Text = 'Perturbations';
            app.StructureButton = uibutton(app.SpacecraftandEnvironmentTab, 'push');
            app.StructureButton.ButtonPushedFcn = createCallbackFcn(app, @StructureButtonPushed, true);
            app.StructureButton.Text = 'Structure';
            app.AttitudeButton = uibutton(app.SpacecraftandEnvironmentTab, 'push');
            app.AttitudeButton.ButtonPushedFcn = createCallbackFcn(app, @AttitudeButtonPushed, true);
            app.AttitudeButton.Text = 'Attitude';
            app.OrbitButton = uibutton(app.SpacecraftandEnvironmentTab, 'push');
            app.OrbitButton.ButtonPushedFcn = createCallbackFcn(app, @OrbitButtonPushed, true);
            app.OrbitButton.Text = 'Orbit';
            app.SpacecraftParametersLabel = uilabel(app.SpacecraftandEnvironmentTab);
            app.SpacecraftParametersLabel.Text = 'Spacecraft Parameters';
            app.OrbitParametersandEnvironmentLabel = uilabel(app.SpacecraftandEnvironmentTab);
            app.OrbitParametersandEnvironmentLabel.Text = 'Orbit Parameters and Environment';
            app.AttitudeLabel = uilabel(app.SpacecraftandEnvironmentTab);
            app.AttitudeLabel.Text = 'Attitude:';
            app.StructureLabel = uilabel(app.SpacecraftandEnvironmentTab);
            app.StructureLabel.Text = 'Structure:';
            app.SpacecraftOrbitLabel = uilabel(app.SpacecraftandEnvironmentTab);
            app.SpacecraftOrbitLabel.Text = 'Spacecraft Orbit:';
            app.OrbitEnvironmentLabel = uilabel(app.SpacecraftandEnvironmentTab);
            app.OrbitEnvironmentLabel.Text = 'Orbit Environment:';
            app.SensorsActuatorsTab = uitab(app.TabGroup);
            app.SensorsActuatorsTab.Title = 'Sensors / Actuators';
            app.AddSensorButton = uibutton(app.SensorsActuatorsTab, 'push');
            app.AddSensorButton.ButtonPushedFcn = createCallbackFcn(app, @AddSensorButtonPushed, true);
            app.AddSensorButton.Text = 'Add Sensor';
            app.AddActuatorButton = uibutton(app.SensorsActuatorsTab, 'push');
            app.AddActuatorButton.ButtonPushedFcn = createCallbackFcn(app, @AddActuatorButtonPushed, true);
            app.AddActuatorButton.Text = 'Add Actuator';
            app.AvailableSensorsLabel = uilabel(app.SensorsActuatorsTab);
            app.AvailableSensorsLabel.Text = 'Available Sensors:';
            app.AvailableSensorsDropDown = uidropdown(app.SensorsActuatorsTab);
            app.AvailableSensorsDropDown.Items = {'Coarse Sun Sensor', 'Fine Sun Sensor', 'Gyroscope', 'Magnetometer'};
            app.AvailableSensorsDropDown.ValueChangedFcn = createCallbackFcn(app, @AvailableSensorsDropDownValueChanged, true);
            app.AvailableSensorsDropDown.Value = 'Coarse Sun Sensor';
            app.AvailableActuatorsDropDownLabel = uilabel(app.SensorsActuatorsTab);
            app.AvailableActuatorsDropDownLabel.Text = 'Available Actuators:';
            app.AvailableActuatorsDropDown = uidropdown(app.SensorsActuatorsTab);
            app.AvailableActuatorsDropDown.Items = {'Magnetorquer', 'Momentum Wheel'};
            app.AvailableActuatorsDropDown.Value = 'Magnetorquer';
            app.Tree = uitree(app.SensorsActuatorsTab);
            app.SensorsNode = uitreenode(app.Tree);
            app.SensorsNode.Tag = 'Sensors';
            app.SensorsNode.Text = 'Sensors:';
            app.ActuatorsNode = uitreenode(app.Tree);
            app.ActuatorsNode.Text = 'Actuators:';
            app.EditButton = uibutton(app.SensorsActuatorsTab, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.RemoveButton = uibutton(app.SensorsActuatorsTab, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.SensorsActuatorsLabel = uilabel(app.SensorsActuatorsTab);
            app.SensorsActuatorsLabel.Text = 'Sensors / Actuators';
            app.AlgorithmsTab = uitab(app.TabGroup);
            app.AlgorithmsTab.Title = 'Algorithms';
            app.EstimatorLabel = uilabel(app.AlgorithmsTab);
            app.EstimatorLabel.Text = 'Estimator:';
            app.EstimatorDropDown = uidropdown(app.AlgorithmsTab);
            app.EstimatorDropDown.Items = {'None', 'MEMS Gyro Rate', 'Mag Rate', 'TRIAD + MEMS Gyro Rate', 'TRIAD + Mag Rate', 'Gyro MEKF', 'Full State AEKF'};
            app.EstimatorDropDown.ValueChangedFcn = createCallbackFcn(app, @EstimatorDropDownValueChanged, true);
            app.EstimatorDropDown.Value = 'None';
            app.DetumblingModeLabel = uilabel(app.AlgorithmsTab);
            app.DetumblingModeLabel.Text = 'Detumbling Mode:';
            app.DetumblingModeDropDown = uidropdown(app.AlgorithmsTab);
            app.DetumblingModeDropDown.Items = {'Pitch-only', 'Y-Thomson Spin', 'Fast', 'Very Fast'};
            app.DetumblingModeDropDown.ValueChangedFcn = createCallbackFcn(app, @DetumblingModeDropDownValueChanged, true);
            app.DetumblingModeDropDown.Value = 'Pitch-only';
            app.PointingModeLabel = uilabel(app.AlgorithmsTab);
            app.PointingModeLabel.Text = 'Pointing Mode:';
            app.PointingModeDropDown = uidropdown(app.AlgorithmsTab);
            app.PointingModeDropDown.Items = {'Nadir Poiting', 'Pitch Sun Tracking'};
            app.PointingModeDropDown.ValueChangedFcn = createCallbackFcn(app, @PointingModeDropDownValueChanged, true);
            app.PointingModeDropDown.Value = 'Nadir Poiting';
            app.AlgorithmsLabel = uilabel(app.AlgorithmsTab);
            app.AlgorithmsLabel.Text = 'Algorithms';
            app.ViewTab = uitab(app.TabGroup);
            app.ViewTab.Title = 'View';
            app.MeasuredBerrorCheckBox = uicheckbox(app.ViewTab);
            app.MeasuredBerrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @MeasuredBerrorCheckBoxValueChanged, true);
            app.MeasuredBerrorCheckBox.Text = 'Measured B error';
            app.SENSORERRORSLabel = uilabel(app.ViewTab);
            app.SENSORERRORSLabel.Text = 'SENSOR ERRORS';
            app.MeasuredSunvectorerrorCheckBox = uicheckbox(app.ViewTab);
            app.MeasuredSunvectorerrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @MeasuredSunvectorerrorCheckBoxValueChanged, true);
            app.MeasuredSunvectorerrorCheckBox.Text = 'Measured Sun vector error';
            app.MeasuredSunAngleerrorCheckBox = uicheckbox(app.ViewTab);
            app.MeasuredSunAngleerrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @MeasuredSunAngleerrorCheckBoxValueChanged, true);
            app.MeasuredSunAngleerrorCheckBox.Text = 'Measured Sun Angle error';
            app.MeasuredRateserrorCheckBox = uicheckbox(app.ViewTab);
            app.MeasuredRateserrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @MeasuredRateserrorCheckBoxValueChanged, true);
            app.MeasuredRateserrorCheckBox.Text = 'Measured Rates error';
            app.OBCERRORSLabel = uilabel(app.ViewTab);
            app.OBCERRORSLabel.Text = 'OBC ERRORS';
            app.ComputedPositionErrorCheckBox = uicheckbox(app.ViewTab);
            app.ComputedPositionErrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComputedPositionErrorCheckBoxValueChanged, true);
            app.ComputedPositionErrorCheckBox.Text = 'Computed Position Error';
            app.ComputedVelocityErrorCheckBox = uicheckbox(app.ViewTab);
            app.ComputedVelocityErrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComputedVelocityErrorCheckBoxValueChanged, true);
            app.ComputedVelocityErrorCheckBox.Text = 'Computed Velocity Error';
            app.ComputedSunVectorErrorCheckBox = uicheckbox(app.ViewTab);
            app.ComputedSunVectorErrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComputedSunVectorErrorCheckBoxValueChanged, true);
            app.ComputedSunVectorErrorCheckBox.Text = 'Computed Sun Vector Error';
            app.ComputedMagneticFieldErrorCheckBox = uicheckbox(app.ViewTab);
            app.ComputedMagneticFieldErrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComputedMagneticFieldErrorCheckBoxValueChanged, true);
            app.ComputedMagneticFieldErrorCheckBox.Text = 'Computed Magnetic Field Error';
            app.ComputedTotalTorqueErrorCheckBox = uicheckbox(app.ViewTab);
            app.ComputedTotalTorqueErrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComputedTotalTorqueErrorCheckBoxValueChanged, true);
            app.ComputedTotalTorqueErrorCheckBox.Text = 'Computed Total Torque Error';
            app.POINTINGERRORLabel = uilabel(app.ViewTab);
            app.POINTINGERRORLabel.Text = 'POINTING ERROR';
            app.PointingErrorYPRCheckBox = uicheckbox(app.ViewTab);
            app.PointingErrorYPRCheckBox.ValueChangedFcn = createCallbackFcn(app, @PointingErrorYPRCheckBoxValueChanged, true);
            app.PointingErrorYPRCheckBox.Text = 'Pointing Error YPR';
            app.WPointingErrorCheckBox = uicheckbox(app.ViewTab);
            app.WPointingErrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @WPointingErrorCheckBoxValueChanged, true);
            app.WPointingErrorCheckBox.Text = 'W Pointing Error';
            app.DETERMINATIONERRORLabel = uilabel(app.ViewTab);
            app.DETERMINATIONERRORLabel.Text = 'DETERMINATION ERROR';
            app.PointingestimatederrorCheckBox = uicheckbox(app.ViewTab);
            app.PointingestimatederrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @PointingestimatederrorCheckBoxValueChanged, true);
            app.PointingestimatederrorCheckBox.Text = 'Pointing estimated error';
            app.RatesestimationerrorCheckBox = uicheckbox(app.ViewTab);
            app.RatesestimationerrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @RatesestimationerrorCheckBoxValueChanged, true);
            app.RatesestimationerrorCheckBox.Text = 'Rates estimation error';
            app.POWERLabel = uilabel(app.ViewTab);
            app.POWERLabel.Text = 'POWER';
            app.PowerusedbyactuatorsCheckBox = uicheckbox(app.ViewTab);
            app.PowerusedbyactuatorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PowerusedbyactuatorsCheckBoxValueChanged, true);
            app.PowerusedbyactuatorsCheckBox.Text = 'Power used by actuators';
            app.PoweravailableCheckBox = uicheckbox(app.ViewTab);
            app.PoweravailableCheckBox.ValueChangedFcn = createCallbackFcn(app, @PoweravailableCheckBoxValueChanged, true);
            app.PoweravailableCheckBox.Text = 'Power available';
            app.CombinationFinderTab = uitab(app.TabGroup);
            app.CombinationFinderTab.Title = 'Combination Finder';
            app.mWLabel = uilabel(app.CombinationFinderTab);
            app.mWLabel.Text = 'mW';
            app.gLabel = uilabel(app.CombinationFinderTab);
            app.gLabel.Text = 'g';
            app.mm3Label = uilabel(app.CombinationFinderTab);
            app.mm3Label.Text = 'mm^3';
            app.SpacecraftLimitationsLabel = uilabel(app.CombinationFinderTab);
            app.SpacecraftLimitationsLabel.Text = 'Spacecraft Limitations:';
            app.FindCombinationButton = uibutton(app.CombinationFinderTab, 'push');
            app.FindCombinationButton.ButtonPushedFcn = createCallbackFcn(app, @FindCombinationButtonPushed, true);
            app.FindCombinationButton.Text = 'Find Combination';
            app.CheckBox = uicheckbox(app.CombinationFinderTab);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.CombinationFinderTab);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.CombinationFinderTab);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.MaxVolumeEditFieldLabel = uilabel(app.CombinationFinderTab);
            app.MaxVolumeEditFieldLabel.Text = 'Max Volume';
            app.MaxVolumeEditField = uieditfield(app.CombinationFinderTab, 'numeric');
            app.MaxVolumeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxVolumeEditFieldValueChanged, true);
            app.MaxMassEditFieldLabel = uilabel(app.CombinationFinderTab);
            app.MaxMassEditFieldLabel.Text = 'Max Mass';
            app.MaxMassEditField = uieditfield(app.CombinationFinderTab, 'numeric');
            app.MaxMassEditField.ValueChangedFcn = createCallbackFcn(app, @MaxMassEditFieldValueChanged, true);
            app.MaxPowerEditFieldLabel = uilabel(app.CombinationFinderTab);
            app.MaxPowerEditFieldLabel.Text = 'Max Power';
            app.MaxPowerEditField = uieditfield(app.CombinationFinderTab, 'numeric');
            app.MaxPowerEditField.ValueChangedFcn = createCallbackFcn(app, @MaxPowerEditFieldValueChanged, true);
            app.NumberofelementsLabel = uilabel(app.CombinationFinderTab);
            app.NumberofelementsLabel.Text = 'Number of elements:';
            app.ReactionWheelsEditFieldLabel = uilabel(app.CombinationFinderTab);
            app.ReactionWheelsEditFieldLabel.Text = 'Reaction Wheels';
            app.ReactionWheelsEditField = uieditfield(app.CombinationFinderTab, 'numeric');
            app.ReactionWheelsEditField.Limits = [0 Inf];
            app.ReactionWheelsEditField.ValueChangedFcn = createCallbackFcn(app, @ReactionWheelsEditFieldValueChanged, true);
            app.ReactionWheelsEditField.Value = 1;
            app.MagnetorquerEditFieldLabel = uilabel(app.CombinationFinderTab);
            app.MagnetorquerEditFieldLabel.Text = 'Magnetorquer';
            app.MagnetorquerEditField = uieditfield(app.CombinationFinderTab, 'numeric');
            app.MagnetorquerEditField.Limits = [0 Inf];
            app.MagnetorquerEditField.ValueChangedFcn = createCallbackFcn(app, @MagnetorquerEditFieldValueChanged, true);
            app.MagnetorquerEditField.Value = 1;
            app.CoarseSunSensorEditFieldLabel = uilabel(app.CombinationFinderTab);
            app.CoarseSunSensorEditFieldLabel.Text = 'Coarse Sun Sensor';
            app.CoarseSunSensorEditField = uieditfield(app.CombinationFinderTab, 'numeric');
            app.CoarseSunSensorEditField.Limits = [0 Inf];
            app.CoarseSunSensorEditField.ValueChangedFcn = createCallbackFcn(app, @CoarseSunSensorEditFieldValueChanged, true);
            app.CoarseSunSensorEditField.Value = 1;
            app.MagnetometersEditFieldLabel = uilabel(app.CombinationFinderTab);
            app.MagnetometersEditFieldLabel.Text = 'Magnetometers';
            app.MagnetometersEditField = uieditfield(app.CombinationFinderTab, 'numeric');
            app.MagnetometersEditField.Limits = [0 Inf];
            app.MagnetometersEditField.ValueChangedFcn = createCallbackFcn(app, @MagnetometersEditFieldValueChanged, true);
            app.MagnetometersEditField.Value = 1;
            app.GyroscopesEditFieldLabel = uilabel(app.CombinationFinderTab);
            app.GyroscopesEditFieldLabel.Text = 'Gyroscopes';
            app.GyroscopesEditField = uieditfield(app.CombinationFinderTab, 'numeric');
            app.GyroscopesEditField.Limits = [0 Inf];
            app.GyroscopesEditField.ValueChangedFcn = createCallbackFcn(app, @GyroscopesEditFieldValueChanged, true);
            app.GyroscopesEditField.Value = 1;
            app.FineSunSensorEditFieldLabel = uilabel(app.CombinationFinderTab);
            app.FineSunSensorEditFieldLabel.Text = 'Fine Sun Sensor';
            app.FineSunSensorEditField = uieditfield(app.CombinationFinderTab, 'numeric');
            app.FineSunSensorEditField.Limits = [0 Inf];
            app.FineSunSensorEditField.ValueChangedFcn = createCallbackFcn(app, @FineSunSensorEditFieldValueChanged, true);
            app.FineSunSensorEditField.Value = 1;
            app.Label = uilabel(app.CombinationFinderTab);
            app.Label.Text = '$';
            app.CheckBox_4 = uicheckbox(app.CombinationFinderTab);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '';
            app.MaxPriceEditFieldLabel = uilabel(app.CombinationFinderTab);
            app.MaxPriceEditFieldLabel.Text = 'Max Price';
            app.MaxPriceEditField = uieditfield(app.CombinationFinderTab, 'numeric');
            app.MaxPriceEditField.ValueChangedFcn = createCallbackFcn(app, @MaxPriceEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end