classdef orbit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        YearEditFieldLabel        matlab.ui.control.Label
        YearEditField             matlab.ui.control.NumericEditField
        MonthEditFieldLabel       matlab.ui.control.Label
        MonthEditField            matlab.ui.control.NumericEditField
        DayEditFieldLabel         matlab.ui.control.Label
        DayEditField              matlab.ui.control.NumericEditField
        HourEditFieldLabel        matlab.ui.control.Label
        HourEditField             matlab.ui.control.NumericEditField
        MinuteEditFieldLabel      matlab.ui.control.Label
        MinuteEditField           matlab.ui.control.NumericEditField
        SecondEditFieldLabel      matlab.ui.control.Label
        SecondEditField           matlab.ui.control.NumericEditField
        aEditFieldLabel           matlab.ui.control.Label
        aEditField                matlab.ui.control.NumericEditField
        eEditFieldLabel           matlab.ui.control.Label
        eEditField                matlab.ui.control.NumericEditField
        iEditFieldLabel           matlab.ui.control.Label
        iEditField                matlab.ui.control.NumericEditField
        EditFieldLabel            matlab.ui.control.Label
        omegaEditField            matlab.ui.control.NumericEditField
        EditField_2Label          matlab.ui.control.Label
        smallomegaEditField       matlab.ui.control.NumericEditField
        MEditFieldLabel           matlab.ui.control.Label
        MEditField                matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        Label_5                   matlab.ui.control.Label
        kmLabel                   matlab.ui.control.Label
        EpochFormatDropDownLabel  matlab.ui.control.Label
        EpochFormatDropDown       matlab.ui.control.DropDown
        OrbitalSetupValuesLabel   matlab.ui.control.Label
        SaveButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, aux)
            if aux > 0
                minute = evalin('base','minute');
                app.MinuteEditField.Value = minute;
                
                second = evalin('base','second');
                app.SecondEditField.Value = second;
                
                hour = evalin('base','hour');
                app.HourEditField.Value = hour;
                
                day = evalin('base','day');
                app.DayEditField.Value = day;
                
                month = evalin('base','month');
                app.MonthEditField.Value = month;
                
                year = evalin('base','year');
                app.YearEditField.Value = year;
                
                exc = evalin('base','exc');
                app.eEditField.Value = exc;
                
                aa = evalin('base','aa');
                app.aEditField.Value = aa;
                
                inc = evalin('base','inc');
                app.iEditField.Value = inc;
                
                omega = evalin('base','omega');
                app.omegaEditField.Value = omega;
                
                w = evalin('base','w');
                app.smallomegaEditField.Value = w;
                
                M = evalin('base','M');
                app.MEditField.Value = M;
            end
            
            
            minute = app.MinuteEditField.Value;
            assignin('base','minute',minute) 
            
            second = app.SecondEditField.Value;
            assignin('base','second',second)
             
            hour = app.HourEditField.Value;
            assignin('base','hour',hour)
            
            day = app.DayEditField.Value;
            assignin('base','day',day) 
            
            month = app.MonthEditField.Value;
            assignin('base','month',month)
            
            year = app.YearEditField.Value;
            assignin('base','year',year) 
            
            exc = app.eEditField.Value;
             assignin('base','exc',exc)
            
             aa = app.aEditField.Value;
             assignin('base','aa',aa)
             
              inc = app.iEditField.Value;
             assignin('base','inc',inc)
             
              omega = app.omegaEditField.Value;
             assignin('base','omega',omega)
             
             w = app.smallomegaEditField.Value;
             assignin('base','w',w)
             
               M = app.MEditField.Value;
             assignin('base','M',M)
             
        end

        % Value changed function: YearEditField
        function YearEditFieldValueChanged(app, event)
            year = app.YearEditField.Value;
            assignin('base','year',year) 
        end

        % Value changed function: MonthEditField
        function MonthEditFieldValueChanged(app, event)
            month = app.MonthEditField.Value;
            assignin('base','month',month)
        end

        % Value changed function: DayEditField
        function DayEditFieldValueChanged(app, event)
           day = app.DayEditField.Value;
           assignin('base','day',day) 
        end

        % Value changed function: HourEditField
        function HourEditFieldValueChanged(app, event)
            hour = app.HourEditField.Value;
            assignin('base','hour',hour)
        end

        % Value changed function: MinuteEditField
        function MinuteEditFieldValueChanged(app, event)
            minute = app.MinuteEditField.Value;
            assignin('base','minute',minute) 
        end

        % Value changed function: SecondEditField
        function SecondEditFieldValueChanged(app, event)
            second = app.SecondEditField.Value;
            assignin('base','second',second)
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
             aa = app.aEditField.Value;
             assignin('base','aa',aa)
        end

        % Value changed function: eEditField
        function eEditFieldValueChanged(app, event)
             exc = app.eEditField.Value;
             assignin('base','exc',exc)
        end

        % Value changed function: iEditField
        function iEditFieldValueChanged(app, event)
             inc = app.iEditField.Value;
             assignin('base','inc',inc)
        end

        % Value changed function: omegaEditField
        function omegaEditFieldValueChanged(app, event)
             omega = app.omegaEditField.Value;
             assignin('base','omega',omega)
        end

        % Value changed function: smallomegaEditField
        function smallomegaEditFieldValueChanged(app, event)
             w = app.smallomegaEditField.Value;
             assignin('base','w',w)
        end

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)
             M = app.MEditField.Value;
             assignin('base','M',M)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.YearEditFieldLabel = uilabel(app.UIFigure);
            app.YearEditFieldLabel.Text = 'Year';
            app.YearEditField = uieditfield(app.UIFigure, 'numeric');
            app.YearEditField.ValueChangedFcn = createCallbackFcn(app, @YearEditFieldValueChanged, true);
            app.YearEditField.Value = 2022;
            app.MonthEditFieldLabel = uilabel(app.UIFigure);
            app.MonthEditFieldLabel.Text = 'Month';
            app.MonthEditField = uieditfield(app.UIFigure, 'numeric');
            app.MonthEditField.ValueChangedFcn = createCallbackFcn(app, @MonthEditFieldValueChanged, true);
            app.MonthEditField.Value = 1;
            app.DayEditFieldLabel = uilabel(app.UIFigure);
            app.DayEditFieldLabel.Text = 'Day';
            app.DayEditField = uieditfield(app.UIFigure, 'numeric');
            app.DayEditField.ValueChangedFcn = createCallbackFcn(app, @DayEditFieldValueChanged, true);
            app.DayEditField.Value = 1;
            app.HourEditFieldLabel = uilabel(app.UIFigure);
            app.HourEditFieldLabel.Text = 'Hour';
            app.HourEditField = uieditfield(app.UIFigure, 'numeric');
            app.HourEditField.ValueChangedFcn = createCallbackFcn(app, @HourEditFieldValueChanged, true);
            app.HourEditField.Value = 19;
            app.MinuteEditFieldLabel = uilabel(app.UIFigure);
            app.MinuteEditFieldLabel.Text = 'Minute';
            app.MinuteEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinuteEditField.ValueChangedFcn = createCallbackFcn(app, @MinuteEditFieldValueChanged, true);
            app.SecondEditFieldLabel = uilabel(app.UIFigure);
            app.SecondEditFieldLabel.Text = 'Second';
            app.SecondEditField = uieditfield(app.UIFigure, 'numeric');
            app.SecondEditField.ValueChangedFcn = createCallbackFcn(app, @SecondEditFieldValueChanged, true);
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.aEditField.Value = 6768.24;
            app.eEditFieldLabel = uilabel(app.UIFigure);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.UIFigure, 'numeric');
            app.eEditField.ValueChangedFcn = createCallbackFcn(app, @eEditFieldValueChanged, true);
            app.eEditField.Value = 5e-05;
            app.iEditFieldLabel = uilabel(app.UIFigure);
            app.iEditFieldLabel.Text = 'i';
            app.iEditField = uieditfield(app.UIFigure, 'numeric');
            app.iEditField.ValueChangedFcn = createCallbackFcn(app, @iEditFieldValueChanged, true);
            app.iEditField.Value = 51.64;
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Ω';
            app.omegaEditField = uieditfield(app.UIFigure, 'numeric');
            app.omegaEditField.ValueChangedFcn = createCallbackFcn(app, @omegaEditFieldValueChanged, true);
            app.omegaEditField.Value = 117.8;
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = 'ω';
            app.smallomegaEditField = uieditfield(app.UIFigure, 'numeric');
            app.smallomegaEditField.ValueChangedFcn = createCallbackFcn(app, @smallomegaEditFieldValueChanged, true);
            app.smallomegaEditField.Value = 34.8;
            app.MEditFieldLabel = uilabel(app.UIFigure);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.UIFigure, 'numeric');
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
            app.MEditField.Value = 60;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'º';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'º';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'º';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'º';
            app.kmLabel = uilabel(app.UIFigure);
            app.kmLabel.Text = 'km';
            app.EpochFormatDropDownLabel = uilabel(app.UIFigure);
            app.EpochFormatDropDownLabel.Text = 'Epoch Format';
            app.EpochFormatDropDown = uidropdown(app.UIFigure);
            app.EpochFormatDropDown.Items = {'A1ModJulian', 'UTCGregorian', 'UTCModJulian', 'TDBModJulian'};
            app.EpochFormatDropDown.Value = 'A1ModJulian';
            app.OrbitalSetupValuesLabel = uilabel(app.UIFigure);
            app.OrbitalSetupValuesLabel.Text = 'Orbital Setup Values';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = orbit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end