classdef structure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Am2Label            matlab.ui.control.Label
        SaveButton          matlab.ui.control.Button
        cmLabel             matlab.ui.control.Label
        cmLabel_2           matlab.ui.control.Label
        Kgm2Label           matlab.ui.control.Label
        Kgm2Label_2         matlab.ui.control.Label
        Kgm2Label_3         matlab.ui.control.Label
        KgLabel             matlab.ui.control.Label
        cmLabel_3           matlab.ui.control.Label
        VEditField          matlab.ui.control.NumericEditField
        VEditField_2        matlab.ui.control.NumericEditField
        Kgm2Label_4         matlab.ui.control.Label
        Kgm2Label_5         matlab.ui.control.Label
        Kgm2Label_6         matlab.ui.control.Label
        Am2Label_2          matlab.ui.control.Label
        Am2Label_3          matlab.ui.control.Label
        PdMyLabel           matlab.ui.control.Label
        PdMLabel            matlab.ui.control.Label
        PdMxEditFieldLabel  matlab.ui.control.Label
        PdMxEditField       matlab.ui.control.NumericEditField
        SxEditFieldLabel    matlab.ui.control.Label
        SxEditField         matlab.ui.control.NumericEditField
        IxxEditFieldLabel   matlab.ui.control.Label
        IxxEditField        matlab.ui.control.NumericEditField
        IyyEditFieldLabel   matlab.ui.control.Label
        IyyEditField        matlab.ui.control.NumericEditField
        IzzEditFieldLabel   matlab.ui.control.Label
        IzzEditField        matlab.ui.control.NumericEditField
        SyEditFieldLabel    matlab.ui.control.Label
        SyEditField         matlab.ui.control.NumericEditField
        mEditFieldLabel     matlab.ui.control.Label
        mEditField          matlab.ui.control.NumericEditField
        SzEditFieldLabel    matlab.ui.control.Label
        SzEditField         matlab.ui.control.NumericEditField
        IxzEditFieldLabel   matlab.ui.control.Label
        IxzEditField        matlab.ui.control.NumericEditField
        IxyEditFieldLabel   matlab.ui.control.Label
        IxyEditField        matlab.ui.control.NumericEditField
        IyzEditFieldLabel   matlab.ui.control.Label
        IyzEditField        matlab.ui.control.NumericEditField
        StructureLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        Ixx;
        Ixy;
        Iyy;
        Iyz;
        Ixz;
        Izz;
        PdMx;
        PdMy;
        PdMz;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, aux)
            if aux > 0 
                mass = evalin('base','mass');
                app.mEditField.Value = mass; 
                
                aux2 = evalin('base','Iij');
                [app.Ixx,app.Ixy,app.Ixz,app.Ixy,app.Iyy,app.Iyz,app.Ixz,app.Iyz,app.Izz] = deal(aux2(1,1),aux2(1,2),aux2(1,3),aux2(2,1),aux2(2,2),aux2(2,3),aux2(3,1),aux2(3,2),aux2(3,3));
                app.IxxEditField.Value = app.Ixx;
                app.IyyEditField.Value = app.Iyy;
                app.IzzEditField.Value = app.Izz;
                app.IxyEditField.Value = app.Ixy;
                app.IxzEditField.Value = app.Ixz;
                app.IyzEditField.Value = app.Iyz;
                
                aux2 = evalin('base','PdM');
                [app.PdMx,app.PdMy,app.PdMz] = deal(aux2(1,1),aux2(1,2),aux2(1,3));
                app.PdMxEditField.Value = app.PdMx;
                app.VEditField.Value = app.PdMy;
                app.VEditField_2.Value = app.PdMz;
                
                Sxx = evalin('base','Sxx');
                app.SxEditField.Value = Sxx;
                
                Syy = evalin('base','Syy');
                app.SyEditField.Value = Syy;
                
                Szz = evalin('base','Szz');
                app.SzEditField.Value = Szz;
            end
            
            mass = app.mEditField.Value;
             assignin('base','mass',mass)
            
              
             app.Ixx = app.IxxEditField.Value;
             app.Iyy = app.IyyEditField.Value;
             app.Izz = app.IzzEditField.Value;
             app.Ixy = app.IxyEditField.Value;
             app.Ixz = app.IxzEditField.Value;
             app.Iyz = app.IyzEditField.Value;
             assignin('base','Iij',[app.Ixx app.Ixy app.Ixz; app.Ixy app.Iyy app.Iyz; app.Ixz app.Iyz app.Izz])
             
             app.PdMx = app.PdMxEditField.Value;
             app.PdMy = app.VEditField.Value;
             app.PdMz = app.VEditField_2.Value;
             assignin('base','PdM',[app.PdMx,app.PdMy,app.PdMz])
             
             Sxx = app.SxEditField.Value;
             assignin('base','Sxx',Sxx)
            
             Syy = app.SyEditField.Value;
             assignin('base','Syy',Syy)
            
             Szz = app.SzEditField.Value;
             assignin('base','Szz',Szz)
        end

        % Value changed function: mEditField
        function mEditFieldValueChanged(app, event)
             mass = app.mEditField.Value;
            assignin('base','mass',mass)
        end

        % Value changed function: SxEditField
        function SxEditFieldValueChanged(app, event)
            Sxx = app.SxEditField.Value;
             assignin('base','Sxx',Sxx)
        end

        % Value changed function: SyEditField
        function SyEditFieldValueChanged(app, event)
             Syy = app.SyEditField.Value;
            assignin('base','Syy',Syy)
        end

        % Value changed function: SzEditField
        function SzEditFieldValueChanged(app, event)
            Szz = app.SzEditField.Value;
            assignin('base','Szz',Szz)
        end

        % Value changed function: PdMxEditField
        function PdMxEditFieldValueChanged(app, event)
            app.PdMx = app.PdMxEditField.Value;
            assignin('base','PdM',[app.PdMx,app.PdMy,app.PdMz])
        end

        % Value changed function: VEditField
        function VEditFieldValueChanged(app, event)
             app.PdMy = app.VEditField.Value;
             assignin('base','PdM',[app.PdMx,app.PdMy,app.PdMz])
            
        end

        % Value changed function: VEditField_2
        function VEditField_2ValueChanged(app, event)
            app.PdMz = app.VEditField_2.Value;
            assignin('base','PdM',[app.PdMx,app.PdMy,app.PdMz])
        end

        % Value changed function: IxxEditField
        function IxxEditFieldValueChanged(app, event)
           app.Ixx = app.IxxEditField.Value;
           assignin('base','Iij',[app.Ixx app.Ixy app.Ixz; app.Ixy app.Iyy app.Iyz; app.Ixz app.Iyz app.Izz])
        end

        % Value changed function: IyyEditField
        function IyyEditFieldValueChanged(app, event)
             app.Iyy = app.IyyEditField.Value;
             assignin('base','Iij',[app.Ixx app.Ixy app.Ixz; app.Ixy app.Iyy app.Iyz; app.Ixz app.Iyz app.Izz])
        end

        % Value changed function: IzzEditField
        function IzzEditFieldValueChanged(app, event)
             app.Izz = app.IzzEditField.Value;
             assignin('base','Iij',[app.Ixx app.Ixy app.Ixz; app.Ixy app.Iyy app.Iyz; app.Ixz app.Iyz app.Izz])
        end

        % Value changed function: IxyEditField
        function IxyEditFieldValueChanged(app, event)
             app.Ixy = app.IxyEditField.Value;
             assignin('base','Iij',[app.Ixx app.Ixy app.Ixz; app.Ixy app.Iyy app.Iyz; app.Ixz app.Iyz app.Izz])
        end

        % Value changed function: IxzEditField
        function IxzEditFieldValueChanged(app, event)
            app.Ixz = app.IxzEditField.Value;
            assignin('base','Iij',[app.Ixx app.Ixy app.Ixz; app.Ixy app.Iyy app.Iyz; app.Ixz app.Iyz app.Izz])
        end

        % Value changed function: IyzEditField
        function IyzEditFieldValueChanged(app, event)
            
             app.Iyz = app.IyzEditField.Value;
             assignin('base','Iij',[app.Ixx app.Ixy app.Ixz; app.Ixy app.Iyy app.Iyz; app.Ixz app.Iyz app.Izz])
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Am2Label = uilabel(app.UIFigure);
            app.Am2Label.Text = 'Am2';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.cmLabel = uilabel(app.UIFigure);
            app.cmLabel.Text = 'cm';
            app.cmLabel_2 = uilabel(app.UIFigure);
            app.cmLabel_2.Text = 'cm';
            app.Kgm2Label = uilabel(app.UIFigure);
            app.Kgm2Label.Text = 'Kgm2';
            app.Kgm2Label_2 = uilabel(app.UIFigure);
            app.Kgm2Label_2.Text = 'Kgm2';
            app.Kgm2Label_3 = uilabel(app.UIFigure);
            app.Kgm2Label_3.Text = 'Kgm2';
            app.KgLabel = uilabel(app.UIFigure);
            app.KgLabel.Text = 'Kg';
            app.cmLabel_3 = uilabel(app.UIFigure);
            app.cmLabel_3.Text = 'cm';
            app.VEditField = uieditfield(app.UIFigure, 'numeric');
            app.VEditField.ValueChangedFcn = createCallbackFcn(app, @VEditFieldValueChanged, true);
            app.VEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.VEditField_2.ValueChangedFcn = createCallbackFcn(app, @VEditField_2ValueChanged, true);
            app.VEditField_2.Value = 0.00707;
            app.Kgm2Label_4 = uilabel(app.UIFigure);
            app.Kgm2Label_4.Text = 'Kgm2';
            app.Kgm2Label_5 = uilabel(app.UIFigure);
            app.Kgm2Label_5.Text = 'Kgm2';
            app.Kgm2Label_6 = uilabel(app.UIFigure);
            app.Kgm2Label_6.Text = 'Kgm2';
            app.Am2Label_2 = uilabel(app.UIFigure);
            app.Am2Label_2.Text = 'Am2';
            app.Am2Label_3 = uilabel(app.UIFigure);
            app.Am2Label_3.Text = 'Am2';
            app.PdMyLabel = uilabel(app.UIFigure);
            app.PdMyLabel.Text = 'PdMy';
            app.PdMLabel = uilabel(app.UIFigure);
            app.PdMLabel.Text = 'PdM';
            app.PdMxEditFieldLabel = uilabel(app.UIFigure);
            app.PdMxEditFieldLabel.Text = 'PdMx';
            app.PdMxEditField = uieditfield(app.UIFigure, 'numeric');
            app.PdMxEditField.ValueChangedFcn = createCallbackFcn(app, @PdMxEditFieldValueChanged, true);
            app.PdMxEditField.Value = 0.00707;
            app.SxEditFieldLabel = uilabel(app.UIFigure);
            app.SxEditFieldLabel.Text = 'Sx';
            app.SxEditField = uieditfield(app.UIFigure, 'numeric');
            app.SxEditField.ValueChangedFcn = createCallbackFcn(app, @SxEditFieldValueChanged, true);
            app.SxEditField.Value = 0.1;
            app.IxxEditFieldLabel = uilabel(app.UIFigure);
            app.IxxEditFieldLabel.Text = 'Ixx';
            app.IxxEditField = uieditfield(app.UIFigure, 'numeric');
            app.IxxEditField.ValueChangedFcn = createCallbackFcn(app, @IxxEditFieldValueChanged, true);
            app.IxxEditField.Value = 0.003;
            app.IyyEditFieldLabel = uilabel(app.UIFigure);
            app.IyyEditFieldLabel.Text = 'Iyy';
            app.IyyEditField = uieditfield(app.UIFigure, 'numeric');
            app.IyyEditField.ValueChangedFcn = createCallbackFcn(app, @IyyEditFieldValueChanged, true);
            app.IyyEditField.Value = 0.007;
            app.IzzEditFieldLabel = uilabel(app.UIFigure);
            app.IzzEditFieldLabel.Text = 'Izz';
            app.IzzEditField = uieditfield(app.UIFigure, 'numeric');
            app.IzzEditField.ValueChangedFcn = createCallbackFcn(app, @IzzEditFieldValueChanged, true);
            app.IzzEditField.Value = 0.008;
            app.SyEditFieldLabel = uilabel(app.UIFigure);
            app.SyEditFieldLabel.Text = 'Sy';
            app.SyEditField = uieditfield(app.UIFigure, 'numeric');
            app.SyEditField.ValueChangedFcn = createCallbackFcn(app, @SyEditFieldValueChanged, true);
            app.SyEditField.Value = 0.1;
            app.mEditFieldLabel = uilabel(app.UIFigure);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.UIFigure, 'numeric');
            app.mEditField.ValueChangedFcn = createCallbackFcn(app, @mEditFieldValueChanged, true);
            app.mEditField.Value = 3.6;
            app.SzEditFieldLabel = uilabel(app.UIFigure);
            app.SzEditFieldLabel.Text = 'Sz';
            app.SzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SzEditField.ValueChangedFcn = createCallbackFcn(app, @SzEditFieldValueChanged, true);
            app.SzEditField.Value = 0.2;
            app.IxzEditFieldLabel = uilabel(app.UIFigure);
            app.IxzEditFieldLabel.Text = 'Ixz';
            app.IxzEditField = uieditfield(app.UIFigure, 'numeric');
            app.IxzEditField.ValueChangedFcn = createCallbackFcn(app, @IxzEditFieldValueChanged, true);
            app.IxyEditFieldLabel = uilabel(app.UIFigure);
            app.IxyEditFieldLabel.Text = 'Ixy';
            app.IxyEditField = uieditfield(app.UIFigure, 'numeric');
            app.IxyEditField.ValueChangedFcn = createCallbackFcn(app, @IxyEditFieldValueChanged, true);
            app.IyzEditFieldLabel = uilabel(app.UIFigure);
            app.IyzEditFieldLabel.Text = 'Iyz';
            app.IyzEditField = uieditfield(app.UIFigure, 'numeric');
            app.IyzEditField.ValueChangedFcn = createCallbackFcn(app, @IyzEditFieldValueChanged, true);
            app.StructureLabel = uilabel(app.UIFigure);
            app.StructureLabel.Text = 'Structure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = structure(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end