classdef trying < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        CombinationsPanel  matlab.ui.container.Panel
        ThefollowingcombinationssatisfyyourlimitationsLabel  matlab.ui.control.Label
        combinations       matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        %mass_max = 48;
        %power_max = 2630;
        %volume_max = 100000;
        volume = 0;
        mass = 0;
        power = 0;
        price = 0;
        combo = evalin('base','Data.combo');
        string= '';
        %Reaction Wheels
        RW=evalin('base','Data.RW');
        RWm=evalin('base','Data.RWm');
        RWp=evalin('base','Data.RWp');
        RWx=evalin('base','Data.RWx');
        RWy=evalin('base','Data.RWy');
        RWz=evalin('base','Data.RWz');
        RW_price=evalin('base', 'Data.RW_price');
        %Magnetorquer
        Mgt=evalin('base','Data.Mgt');
        Mgtm=evalin('base','Data.Mgtm');
        Mgtp=evalin('base','Data.Mgtp');
        Mgtx=evalin('base','Data.Mgtx');
        Mgty=evalin('base','Data.Mgty');
        Mgtz=evalin('base','Data.Mgtz');
        Mgt_price=evalin('base', 'Data.Mgt_price');
        %Magnetometer
        Mgm=evalin('base','Data.Mgm');
        Mgmm=evalin('base','Data.Mgmm');
        Mgmp=evalin('base','Data.Mgmp');
        Mgmx=evalin('base','Data.Mgmx');
        Mgmy=evalin('base','Data.Mgmy');
        Mgmz=evalin('base','Data.Mgmz');
        Mgm_price=evalin('base', 'Data.Mgm_price');
        %Gyros
        G=evalin('base','Data.G');
        Gm=evalin('base','Data.Gm');
        Gp=evalin('base','Data.Gp');
        Gx=evalin('base','Data.Gx');
        Gy=evalin('base','Data.Gy');
        Gz=evalin('base','Data.Gz');
        G_price=evalin('base', 'Data.G_price');
        %FSS
        FSS=evalin('base','Data.FSS');
        FSSm=evalin('base','Data.FSSm');
        FSSp=evalin('base','Data.FSSp');
        FSSx=evalin('base','Data.FSSx');
        FSSy=evalin('base','Data.FSSy');
        FSSz=evalin('base','Data.FSSz');
        FSS_price=evalin('base', 'Data.FSS_price');
        %CSS
        CSS=evalin('base','Data.CSS');
        CSSm=evalin('base','Data.CSSm');
        CSSp=evalin('base','Data.CSSp');
        CSSx=evalin('base','Data.CSSx');
        CSSy=evalin('base','Data.CSSy');
        CSSz=evalin('base','Data.CSSz'); 
        CSS_price=evalin('base', 'Data.CSS_price');
        % Counters
        i=0;
        j=1;
        k=1;
        l=1;
        m=1;
        n=1;
        o=1; 
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mass_max, volume_max, power_max, n_RW, n_Mgt, n_Mgm, n_G, n_FSS, n_CSS, price_max)
            aux=0;
        %disp(mass_max);
        %disp(volume_max);
        %disp(power_max);
 %a=exist('app.G');
 %disp(a)
 %disp(app.G(1,1))     
 %a=size(app.G,1);
 %disp(a);
 
while app.i<size(app.G,1)
    app.j=1;
    app.i=app.i+1;
    app.k=1;
    app.l=1;
    app.m=1;
    app.n=1;
 
  while app.j <= size(app.Mgm,1)
        app.k=1;
        app.l=1;
        app.m=1;
        app.n=1;
      
        while app.k <= size(app.Mgt,1)
            app.l=1;
            app.m=1;
            app.n=1;
        
            while app.l <= size(app.RW,1)
                app.m=1;
                app.n=1;
                while app.m <= size(app.FSS,1)
                    app.n=1;
                    while app.n <= size(app.CSS,1)
                        app.mass = n_G*app.Gm(app.i,:)+n_Mgm*app.Mgmm(app.j,:)+n_Mgt*app.Mgtm(app.k,:)+n_RW*app.RWm(app.l,:)+n_FSS*app.FSSm(app.m,:)+n_CSS*app.CSSm(app.n,:);
                        app.power = n_G*app.Gp(app.i,:)+n_Mgm*app.Mgmp(app.j,:)+n_Mgt*app.Mgtp(app.k,:)+n_RW*app.RWp(app.l,:)+n_FSS*app.FSSp(app.m,:)+n_CSS*app.CSSp(app.n,:);
                        app.volume = n_G*app.Gx(app.i,:).*app.Gy(app.i,:).*app.Gz(app.i,:)+n_Mgm*app.Mgmx(app.j,:).*app.Mgmy(app.j,:).*app.Mgmz(app.j,:)+n_Mgt*app.Mgtx(app.k,:).*app.Mgty(app.k,:).*app.Mgtz(app.k,:)+n_RW*app.RWx(app.l,:).*app.RWy(app.l,:).*app.RWz(app.l,:)+n_FSS*app.FSSx(app.m,:).*app.FSSy(app.m,:).*app.FSSz(app.m,:)+n_CSS*app.CSSx(app.n,:).*app.CSSy(app.n,:).*app.CSSz(app.n,:);
                        app.price = n_G*app.G_price(app.i,:)+n_Mgm*app.Mgm_price(app.j,:)+n_Mgt*app.Mgt_price(app.k,:)+n_RW*app.RW_price(app.l,:)+n_FSS*app.FSS_price(app.m,:)+n_CSS*app.CSS_price(app.n,:);
                     
                        
                        if app.mass<=mass_max 
                            %disp(app.mass);
                        if app.power<=power_max 
                            %disp(app.power);
                        if app.volume<=volume_max
                        if app.price<=price_max
                             
                        disp('here');
                        
                            app.combo(app.o,:)=strcat('-',app.G(app.i,:), '+', app.Mgm(app.j,:), '+', app.Mgt(app.k,:), '+', app.RW(app.l,:), '+', app.FSS(app.m,:), '+', app.CSS(app.n,:));
                             app.string = vertcat(app.string, app.combo(app.o,:));
                            %disp(app.combo(app.o,:))
                            app.o=app.o+1;
                            aux=1;
                        end
                        end
                        end
                        end
                        app.n=app.n+1;
                    end     
                app.m=app.m+1;
                end
                
                app.l=app.l+1;
                app.mass=0;
            end
          
            app.k=app.k+1; 
        end
       
        app.j=app.j+1;
  end
   
    %i=1+1;   
end
 if aux==0
                app.combinations.Value='No Combinations Available';
 end
            app.combinations.Value=app.string;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CombinationsPanel = uipanel(app.UIFigure);
            app.CombinationsPanel.Title = 'Combinations';
            app.ThefollowingcombinationssatisfyyourlimitationsLabel = uilabel(app.CombinationsPanel);
            app.ThefollowingcombinationssatisfyyourlimitationsLabel.Text = 'The following combinations satisfy your limitations:';
            app.combinations = uitextarea(app.CombinationsPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trying(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end