classdef C_SS_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CoarseSunSensorSettingsLabel   matlab.ui.control.Label
        AverageSunpowerdensityWm2EditFieldLabel  matlab.ui.control.Label
        AverageSunpowerdensityWm2EditField  matlab.ui.control.NumericEditField
        UpdaterateHzEditFieldLabel     matlab.ui.control.Label
        UpdaterateHzEditField          matlab.ui.control.NumericEditField
        NoisestddeviationradEditFieldLabel  matlab.ui.control.Label
        NoisestddeviationradEditField  matlab.ui.control.NumericEditField
        ConfirmButton                  matlab.ui.control.Button
        KgLabel                        matlab.ui.control.Label
        cmLabel                        matlab.ui.control.Label
        cmLabel_2                      matlab.ui.control.Label
        cmLabel_3                      matlab.ui.control.Label
        LengthEditField                matlab.ui.control.NumericEditField
        LengthEditFieldLabel           matlab.ui.control.Label
        MassEditFieldLabel             matlab.ui.control.Label
        MassEditField                  matlab.ui.control.NumericEditField
        WidthEditFieldLabel            matlab.ui.control.Label
        WidthEditField                 matlab.ui.control.NumericEditField
        HeightEditFieldLabel           matlab.ui.control.Label
        HeightEditField                matlab.ui.control.NumericEditField
        PowerEditFieldLabel            matlab.ui.control.Label
        PowerEditField                 matlab.ui.control.NumericEditField
        WLabel                         matlab.ui.control.Label
    end

    
    properties (Access = private)
        num
        mass_css
        m =0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, val, flag)
            app.num = val;
            aux = rem(floor(flag/10^(str2double(app.num)-1)),10);
            
            textLabel = sprintf('Coarse Sun Sensor %s - Settings', app.num);
            app.CoarseSunSensorSettingsLabel.Text = textLabel;
            
            if aux == 1
                sun_power_C_SS = evalin('base',['Sun_power_C_SS_',app.num]);
                app.AverageSunpowerdensityWm2EditField.Value = sun_power_C_SS;
                
                rate_C_SS = evalin('base',['Rate_C_SS_',app.num]);
                app.UpdaterateHzEditField.Value = rate_C_SS;
                
                noise_C_SS = evalin('base',['Noise_C_SS_',app.num]);
                app.NoisestddeviationradEditField.Value = noise_C_SS;
            end
            
            sun_power_C_SS = app.AverageSunpowerdensityWm2EditField.Value;
            assignin('base',['Sun_power_C_SS_',app.num],sun_power_C_SS)
            
            rate_C_SS = app.UpdaterateHzEditField.Value;
            assignin('base',['Rate_C_SS_',app.num],rate_C_SS)
            
            noise_C_SS = app.NoisestddeviationradEditField.Value;
            assignin('base',['Noise_C_SS_',app.num],noise_C_SS)
        end

        % Value changed function: AverageSunpowerdensityWm2EditField
        function AverageSunpowerdensityWm2EditFieldValueChanged(app, event)
            sun_power_C_SS = app.AverageSunpowerdensityWm2EditField.Value;
            assignin('base',['Sun_power_C_SS_',app.num],sun_power_C_SS)
        end

        % Value changed function: UpdaterateHzEditField
        function UpdaterateHzEditFieldValueChanged(app, event)
            rate_C_SS = app.UpdaterateHzEditField.Value;
            assignin('base',['Rate_C_SS_',app.num],rate_C_SS)
        end

        % Value changed function: NoisestddeviationradEditField
        function NoisestddeviationradEditFieldValueChanged(app, event)
            noise_C_SS = app.NoisestddeviationradEditField.Value;
            assignin('base',['Noise_C_SS_',app.num],noise_C_SS)
        end

        % Value changed function: MassEditField
        function MassEditFieldValueChanged(app, event)
            app.mass_css =app.mass_css+app.MassEditField.Value;
            app.m=app.MassEditField.Value;
            setappdata(0,'mass_c',app.mass_css);
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CoarseSunSensorSettingsLabel = uilabel(app.UIFigure);
            app.CoarseSunSensorSettingsLabel.Text = 'Coarse Sun Sensor - Settings';
            app.AverageSunpowerdensityWm2EditFieldLabel = uilabel(app.UIFigure);
            app.AverageSunpowerdensityWm2EditFieldLabel.Text = 'Average Sun power density [W/m^2]';
            app.AverageSunpowerdensityWm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.AverageSunpowerdensityWm2EditField.ValueChangedFcn = createCallbackFcn(app, @AverageSunpowerdensityWm2EditFieldValueChanged, true);
            app.AverageSunpowerdensityWm2EditField.Value = 1396;
            app.UpdaterateHzEditFieldLabel = uilabel(app.UIFigure);
            app.UpdaterateHzEditFieldLabel.Text = 'Update rate [Hz]';
            app.UpdaterateHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpdaterateHzEditField.ValueChangedFcn = createCallbackFcn(app, @UpdaterateHzEditFieldValueChanged, true);
            app.UpdaterateHzEditField.Value = 0.1;
            app.NoisestddeviationradEditFieldLabel = uilabel(app.UIFigure);
            app.NoisestddeviationradEditFieldLabel.Text = 'Noise std deviation [rad]';
            app.NoisestddeviationradEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoisestddeviationradEditField.ValueChangedFcn = createCallbackFcn(app, @NoisestddeviationradEditFieldValueChanged, true);
            app.NoisestddeviationradEditField.Value = 0.174532925199433;
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.KgLabel = uilabel(app.UIFigure);
            app.KgLabel.Text = 'Kg';
            app.cmLabel = uilabel(app.UIFigure);
            app.cmLabel.Text = 'cm';
            app.cmLabel_2 = uilabel(app.UIFigure);
            app.cmLabel_2.Text = 'cm';
            app.cmLabel_3 = uilabel(app.UIFigure);
            app.cmLabel_3.Text = 'cm';
            app.LengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthEditFieldLabel = uilabel(app.UIFigure);
            app.LengthEditFieldLabel.Text = 'Length';
            app.MassEditFieldLabel = uilabel(app.UIFigure);
            app.MassEditFieldLabel.Text = ' Mass';
            app.MassEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassEditField.ValueChangedFcn = createCallbackFcn(app, @MassEditFieldValueChanged, true);
            app.WidthEditFieldLabel = uilabel(app.UIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerEditFieldLabel = uilabel(app.UIFigure);
            app.PowerEditFieldLabel.Text = ' Power';
            app.PowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Text = 'W';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = C_SS_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end