classdef Magnetometer_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SensitivityLSBTEditFieldLabel  matlab.ui.control.Label
        SensitivityLSBTEditField       matlab.ui.control.NumericEditField
        SaturationTLabel               matlab.ui.control.Label
        UpperLimitEditFieldLabel       matlab.ui.control.Label
        UpperLimitEditField            matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel       matlab.ui.control.Label
        LowerLimitEditField            matlab.ui.control.NumericEditField
        NoiseStdDeviationTEditFieldLabel  matlab.ui.control.Label
        NoiseStdDeviationTEditField    matlab.ui.control.NumericEditField
        UpdateRatesEditFieldLabel      matlab.ui.control.Label
        UpdateRatesEditField           matlab.ui.control.NumericEditField
        StaticBiasTLabel               matlab.ui.control.Label
        XdirectionEditFieldLabel       matlab.ui.control.Label
        XdirectionEditField            matlab.ui.control.NumericEditField
        YdirectionEditFieldLabel       matlab.ui.control.Label
        YdirectionEditField            matlab.ui.control.NumericEditField
        ZdirectionEditFieldLabel       matlab.ui.control.Label
        ZdirectionEditField            matlab.ui.control.NumericEditField
        ScaleFactorsLabel              matlab.ui.control.Label
        g_1EditFieldLabel              matlab.ui.control.Label
        g_1EditField                   matlab.ui.control.NumericEditField
        g_2EditFieldLabel              matlab.ui.control.Label
        g_2EditField                   matlab.ui.control.NumericEditField
        g_3EditFieldLabel              matlab.ui.control.Label
        g_3EditField                   matlab.ui.control.NumericEditField
        MisalignmentAnglesdegLabel     matlab.ui.control.Label
        EditFieldLabel                 matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        EditField_2Label               matlab.ui.control.Label
        EditField_2                    matlab.ui.control.NumericEditField
        EditField_3Label               matlab.ui.control.Label
        EditField_3                    matlab.ui.control.NumericEditField
        EditField_4Label               matlab.ui.control.Label
        EditField_4                    matlab.ui.control.NumericEditField
        EditField_5Label               matlab.ui.control.Label
        EditField_5                    matlab.ui.control.NumericEditField
        EditField_6Label               matlab.ui.control.Label
        EditField_6                    matlab.ui.control.NumericEditField
        DefaultvaluescanbeleftifnoinformationisavailableLabel  matlab.ui.control.Label
        MagnetometerSettingsLabel      matlab.ui.control.Label
        ConfirmButton                  matlab.ui.control.Button
        KgLabel                        matlab.ui.control.Label
        cmLabel                        matlab.ui.control.Label
        cmLabel_2                      matlab.ui.control.Label
        cmLabel_3                      matlab.ui.control.Label
        WLabel                         matlab.ui.control.Label
        LengthEditField                matlab.ui.control.NumericEditField
        LengthEditFieldLabel           matlab.ui.control.Label
        MassEditFieldLabel             matlab.ui.control.Label
        MassEditField                  matlab.ui.control.NumericEditField
        WidthEditFieldLabel            matlab.ui.control.Label
        WidthEditField                 matlab.ui.control.NumericEditField
        HeightEditFieldLabel           matlab.ui.control.Label
        HeightEditField                matlab.ui.control.NumericEditField
        PowerEditFieldLabel            matlab.ui.control.Label
        PowerEditField                 matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        num
        
        bias_x;
        bias_y;
        bias_z;
        g_1;
        g_2;
        g_3;
        theta_1;
        theta_2;
        theta_3;
        phi_1;
        phi_2;
        phi_3;
        mass_mgm_1;
        mass_mgm_2;
        mass_mgm_3;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, val, flag)
            app.num = val;
            aux = rem(floor(flag/10^(str2double(app.num)-1)),10);
            
            textLabel = sprintf('Magnetometer %s - Settings', app.num);
            app.MagnetometerSettingsLabel.Text = textLabel;
            
            if aux == 1
                sensitivity_mag = evalin('base',['Sensitivity_mag_',app.num]);
                app.SensitivityLSBTEditField.Value = sensitivity_mag;
                
                upper_mag = evalin('base',['Upper_mag_',app.num]);
                app.UpperLimitEditField.Value = upper_mag;
                
                lower_mag = evalin('base',['Lower_mag_',app.num]);
                app.LowerLimitEditField.Value = lower_mag;
                
                noise_mag = evalin('base',['Noise_mag_',app.num]);
                app.NoiseStdDeviationTEditField.Value = noise_mag;
                
                rate_mag = evalin('base',['Rate_mag_',app.num]);
                app.UpdateRatesEditField.Value = rate_mag;
                
                aux = evalin('base',['Bias_mag_',app.num]);
                [app.bias_x,app.bias_y,app.bias_z] = deal(aux(1),aux(2),aux(3));
                app.XdirectionEditField.Value = app.bias_x;
                app.YdirectionEditField.Value = app.bias_y;
                app.ZdirectionEditField.Value = app.bias_z;
                
                aux = evalin('base',['Scale_mag_',app.num]);
                [app.g_1,app.g_2,app.g_3,app.theta_1,app.theta_2,app.theta_3,app.phi_1,app.phi_2,app.phi_3] = deal(aux(1,1),aux(1,2),aux(1,3),aux(2,1),aux(2,2),aux(2,3),aux(3,1),aux(3,2),aux(3,3));
                app.g_1EditField.Value = app.g_1;
                app.g_2EditField.Value = app.g_2;
                app.g_3EditField.Value = app.g_3;
                app.EditField.Value = app.theta_1;
                app.EditField_2.Value = app.theta_2;
                app.EditField_3.Value= app.theta_3;
                app.EditField_4.Value = app.phi_1;
                app.EditField_5.Value = app.phi_2;
                app.EditField_6.Value = app.phi_3;
            end
            
            sensitivity_mag = app.SensitivityLSBTEditField.Value;
            assignin('base',['Sensitivity_mag_',app.num],sensitivity_mag)
            
            upper_mag = app.UpperLimitEditField.Value;
            assignin('base',['Upper_mag_',app.num],upper_mag)
            
            lower_mag = app.LowerLimitEditField.Value;
            assignin('base',['Lower_mag_',app.num],lower_mag)
            
            noise_mag = app.NoiseStdDeviationTEditField.Value;
            assignin('base',['Noise_mag_',app.num],noise_mag)
            
            rate_mag = app.UpdateRatesEditField.Value;
            assignin('base',['Rate_mag_',app.num],rate_mag)
            
            app.bias_x = app.XdirectionEditField.Value;
            app.bias_y = app.YdirectionEditField.Value;
            app.bias_z = app.ZdirectionEditField.Value;
            assignin('base',['Bias_mag_',app.num],[app.bias_x, app.bias_y, app.bias_z])
            
            app.g_1 = app.g_1EditField.Value;
            app.g_2 = app.g_2EditField.Value;
            app.g_3 = app.g_3EditField.Value;
            app.theta_1 = app.EditField.Value;
            app.theta_2 = app.EditField_2.Value;
            app.theta_3 = app.EditField_3.Value;
            app.phi_1 = app.EditField_4.Value;
            app.phi_2 = app.EditField_5.Value;
            app.phi_3 = app.EditField_6.Value;
            assignin('base',['Scale_mag_',app.num],[app.g_1 app.g_2 app.g_3; app.theta_1 app.theta_2 app.theta_3; app.phi_1 app.phi_2 app.phi_3])
            
            assignin('base',['new_Mag_',app.num],1)
            set_param(['Pitch_Sun_Track/SENSOR MODEL/Enabled_Mag_',app.num],'Commented','off')
        end

        % Value changed function: SensitivityLSBTEditField
        function SensitivityLSBTEditFieldValueChanged(app, event)
            sensitivity_mag = app.SensitivityLSBTEditField.Value;
            assignin('base',['Sensitivity_mag_',app.num],sensitivity_mag)
        end

        % Value changed function: UpperLimitEditField
        function UpperLimitEditFieldValueChanged(app, event)
            upper_mag = app.UpperLimitEditField.Value;
            assignin('base',['Upper_mag_',app.num],upper_mag)
        end

        % Value changed function: LowerLimitEditField
        function LowerLimitEditFieldValueChanged(app, event)
            lower_mag = app.LowerLimitEditField.Value;
            assignin('base',['Lower_mag_',app.num],lower_mag)
        end

        % Value changed function: NoiseStdDeviationTEditField
        function NoiseStdDeviationTEditFieldValueChanged(app, event)
            noise_mag = app.NoiseStdDeviationTEditField.Value;
            assignin('base',['Noise_mag_',app.num],noise_mag)
        end

        % Value changed function: UpdateRatesEditField
        function UpdateRatesEditFieldValueChanged(app, event)
            rate_mag = app.UpdateRatesEditField.Value;
            assignin('base',['Rate_mag_',app.num],rate_mag)
        end

        % Value changed function: XdirectionEditField
        function XdirectionEditFieldValueChanged(app, event)
            app.bias_x = app.XdirectionEditField.Value;
            assignin('base',['Bias_mag_',app.num],[app.bias_x, app.bias_y, app.bias_z])
        end

        % Value changed function: YdirectionEditField
        function YdirectionEditFieldValueChanged(app, event)
            app.bias_y = app.YdirectionEditField.Value;
            assignin('base',['Bias_mag_',app.num],[app.bias_x, app.bias_y, app.bias_z])
        end

        % Value changed function: ZdirectionEditField
        function ZdirectionEditFieldValueChanged(app, event)
            app.bias_z = app.ZdirectionEditField.Value;
            assignin('base',['Bias_mag_',app.num],[app.bias_x, app.bias_y, app.bias_z])
        end

        % Value changed function: g_1EditField
        function g_1EditFieldValueChanged(app, event)
            app.g_1 = app.g_1EditField.Value;
            assignin('base',['Scale_mag_',app.num],[app.g_1 app.g_2 app.g_3; app.theta_1 app.theta_2 app.theta_3; app.phi_1 app.phi_2 app.phi_3])
        end

        % Value changed function: g_2EditField
        function g_2EditFieldValueChanged(app, event)
            app.g_2 = app.g_2EditField.Value;
            assignin('base',['Scale_mag_',app.num],[app.g_1 app.g_2 app.g_3; app.theta_1 app.theta_2 app.theta_3; app.phi_1 app.phi_2 app.phi_3])
        end

        % Value changed function: g_3EditField
        function g_3EditFieldValueChanged(app, event)
            app.g_3 = app.g_3EditField.Value;
            assignin('base',['Scale_mag_',app.num],[app.g_1 app.g_2 app.g_3; app.theta_1 app.theta_2 app.theta_3; app.phi_1 app.phi_2 app.phi_3])
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.theta_1 = app.EditField.Value;
            assignin('base',['Scale_mag_',app.num],[app.g_1 app.g_2 app.g_3; app.theta_1 app.theta_2 app.theta_3; app.phi_1 app.phi_2 app.phi_3])
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.theta_2 = app.EditField_2.Value;
            assignin('base',['Scale_mag_',app.num],[app.g_1 app.g_2 app.g_3; app.theta_1 app.theta_2 app.theta_3; app.phi_1 app.phi_2 app.phi_3])
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            app.theta_3 = app.EditField_3.Value;
            assignin('base',['Scale_mag_',app.num],[app.g_1 app.g_2 app.g_3; app.theta_1 app.theta_2 app.theta_3; app.phi_1 app.phi_2 app.phi_3])
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            app.phi_1 = app.EditField_4.Value;
            assignin('base',['Scale_mag_',app.num],[app.g_1 app.g_2 app.g_3; app.theta_1 app.theta_2 app.theta_3; app.phi_1 app.phi_2 app.phi_3])
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            app.phi_2 = app.EditField_5.Value;
            assignin('base',['Scale_mag_',app.num],[app.g_1 app.g_2 app.g_3; app.theta_1 app.theta_2 app.theta_3; app.phi_1 app.phi_2 app.phi_3])
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            app.phi_3 = app.EditField_6.Value;
            assignin('base',['Scale_mag_',app.num],[app.g_1 app.g_2 app.g_3; app.theta_1 app.theta_2 app.theta_3; app.phi_1 app.phi_2 app.phi_3])
        end

        % Value changed function: MassEditField
        function MassEditFieldValueChanged(app, event)
            if app.num==1
            app.mass_mgm_1 = app.MassEditField.Value;
            app.mass_mgm_2 = 0;
            app.mass_mgm_3 = 0;
            elseif app.num==2
                app.mass_mgm_2 = app.MassEditField.Value;
                app.mass_mgm_1 = 0;
                app.mass_mgm_3 = 0;
                
            else
                app.mass_mgm_3 = app.MassEditField.Value;
                app.mass_mgm_1 = 0;
                app.mass_mgm_2 = 0;
                
            end    
            setappdata(0,'mass_mm_1',app.mass_mgm_1);
            setappdata(0,'mass_mm_2',app.mass_mgm_2);
            setappdata(0,'mass_mm_3',app.mass_mgm_3);
            
            
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SensitivityLSBTEditFieldLabel = uilabel(app.UIFigure);
            app.SensitivityLSBTEditFieldLabel.Text = 'Sensitivity [LSB/T]';
            app.SensitivityLSBTEditField = uieditfield(app.UIFigure, 'numeric');
            app.SensitivityLSBTEditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityLSBTEditFieldValueChanged, true);
            app.SensitivityLSBTEditField.Value = 71428571.4285714;
            app.SaturationTLabel = uilabel(app.UIFigure);
            app.SaturationTLabel.Text = 'Saturation [T]:';
            app.UpperLimitEditFieldLabel = uilabel(app.UIFigure);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.UpperLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperLimitEditFieldValueChanged, true);
            app.UpperLimitEditField.Value = 32767;
            app.LowerLimitEditFieldLabel = uilabel(app.UIFigure);
            app.LowerLimitEditFieldLabel.Text = 'Lower Limit';
            app.LowerLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @LowerLimitEditFieldValueChanged, true);
            app.LowerLimitEditField.Value = -32768;
            app.NoiseStdDeviationTEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseStdDeviationTEditFieldLabel.Text = 'Noise Std Deviation [T]';
            app.NoiseStdDeviationTEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoiseStdDeviationTEditField.ValueChangedFcn = createCallbackFcn(app, @NoiseStdDeviationTEditFieldValueChanged, true);
            app.NoiseStdDeviationTEditField.Value = 5e-09;
            app.UpdateRatesEditFieldLabel = uilabel(app.UIFigure);
            app.UpdateRatesEditFieldLabel.Text = 'Update Rate [s]';
            app.UpdateRatesEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpdateRatesEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateRatesEditFieldValueChanged, true);
            app.UpdateRatesEditField.Value = 0.1;
            app.StaticBiasTLabel = uilabel(app.UIFigure);
            app.StaticBiasTLabel.Text = 'Static Bias [T]:';
            app.XdirectionEditFieldLabel = uilabel(app.UIFigure);
            app.XdirectionEditFieldLabel.Text = 'X direction';
            app.XdirectionEditField = uieditfield(app.UIFigure, 'numeric');
            app.XdirectionEditField.ValueChangedFcn = createCallbackFcn(app, @XdirectionEditFieldValueChanged, true);
            app.XdirectionEditField.Value = 1.32234e-08;
            app.YdirectionEditFieldLabel = uilabel(app.UIFigure);
            app.YdirectionEditFieldLabel.Text = 'Y direction';
            app.YdirectionEditField = uieditfield(app.UIFigure, 'numeric');
            app.YdirectionEditField.ValueChangedFcn = createCallbackFcn(app, @YdirectionEditFieldValueChanged, true);
            app.YdirectionEditField.Value = 4.6323e-08;
            app.ZdirectionEditFieldLabel = uilabel(app.UIFigure);
            app.ZdirectionEditFieldLabel.Text = 'Z direction';
            app.ZdirectionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZdirectionEditField.ValueChangedFcn = createCallbackFcn(app, @ZdirectionEditFieldValueChanged, true);
            app.ZdirectionEditField.Value = 1.5323e-09;
            app.ScaleFactorsLabel = uilabel(app.UIFigure);
            app.ScaleFactorsLabel.Text = 'Scale Factors*:';
            app.g_1EditFieldLabel = uilabel(app.UIFigure);
            app.g_1EditFieldLabel.Text = 'g_1';
            app.g_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.g_1EditField.ValueChangedFcn = createCallbackFcn(app, @g_1EditFieldValueChanged, true);
            app.g_1EditField.Value = 1;
            app.g_2EditFieldLabel = uilabel(app.UIFigure);
            app.g_2EditFieldLabel.Text = 'g_2';
            app.g_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.g_2EditField.ValueChangedFcn = createCallbackFcn(app, @g_2EditFieldValueChanged, true);
            app.g_3EditFieldLabel = uilabel(app.UIFigure);
            app.g_3EditFieldLabel.Text = 'g_3';
            app.g_3EditField = uieditfield(app.UIFigure, 'numeric');
            app.g_3EditField.ValueChangedFcn = createCallbackFcn(app, @g_3EditFieldValueChanged, true);
            app.MisalignmentAnglesdegLabel = uilabel(app.UIFigure);
            app.MisalignmentAnglesdegLabel.Text = 'Misalignment Angles* [deg]:';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'θ_1';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = 'θ_2';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 1;
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = 'θ_3';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = 'φ_1';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5Label = uilabel(app.UIFigure);
            app.EditField_5Label.Text = 'φ_1';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6Label = uilabel(app.UIFigure);
            app.EditField_6Label.Text = 'φ_1';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Value = 1;
            app.DefaultvaluescanbeleftifnoinformationisavailableLabel = uilabel(app.UIFigure);
            app.DefaultvaluescanbeleftifnoinformationisavailableLabel.Text = '*Default values can be left if no information is available';
            app.MagnetometerSettingsLabel = uilabel(app.UIFigure);
            app.MagnetometerSettingsLabel.Text = 'Magnetometer - Settings';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.KgLabel = uilabel(app.UIFigure);
            app.KgLabel.Text = 'Kg';
            app.cmLabel = uilabel(app.UIFigure);
            app.cmLabel.Text = 'cm';
            app.cmLabel_2 = uilabel(app.UIFigure);
            app.cmLabel_2.Text = 'cm';
            app.cmLabel_3 = uilabel(app.UIFigure);
            app.cmLabel_3.Text = 'cm';
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Text = 'W';
            app.LengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthEditFieldLabel = uilabel(app.UIFigure);
            app.LengthEditFieldLabel.Text = 'Length';
            app.MassEditFieldLabel = uilabel(app.UIFigure);
            app.MassEditFieldLabel.Text = ' Mass';
            app.MassEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassEditField.ValueChangedFcn = createCallbackFcn(app, @MassEditFieldValueChanged, true);
            app.WidthEditFieldLabel = uilabel(app.UIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerEditFieldLabel = uilabel(app.UIFigure);
            app.PowerEditFieldLabel.Text = ' Power';
            app.PowerEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Magnetometer_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end