classdef Magnetorquer_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        XaxisCoilRodLabel              matlab.ui.control.Label
        MagnetorquerSettingsLabel      matlab.ui.control.Label
        VoltageVEditFieldLabel         matlab.ui.control.Label
        VoltageVEditField              matlab.ui.control.NumericEditField
        MagneticDipoleSaturationLimitAm2Label  matlab.ui.control.Label
        MagneticDipoleSaturationLimitAm2EditField  matlab.ui.control.NumericEditField
        SaturationCurrentAEditFieldLabel  matlab.ui.control.Label
        SaturationCurrentAEditField    matlab.ui.control.NumericEditField
        YaxisCoilRodLabel              matlab.ui.control.Label
        MagneticDipoleSaturationLimitAm2EditField_2Label  matlab.ui.control.Label
        MagneticDipoleSaturationLimitAm2EditField_2  matlab.ui.control.NumericEditField
        SaturationCurrentAEditField_2Label  matlab.ui.control.Label
        SaturationCurrentAEditField_2  matlab.ui.control.NumericEditField
        ZaxisCoilRodLabel              matlab.ui.control.Label
        MagneticDipoleSaturationLimitAm2EditField_3Label  matlab.ui.control.Label
        MagneticDipoleSaturationLimitAm2EditField_3  matlab.ui.control.NumericEditField
        SaturationCurrentAEditField_3Label  matlab.ui.control.Label
        SaturationCurrentAEditField_3  matlab.ui.control.NumericEditField
        DirectionCossineMatrixfrombodycoordinatesLabel  matlab.ui.control.Label
        T_1_1EditFieldLabel            matlab.ui.control.Label
        T_1_1EditField                 matlab.ui.control.NumericEditField
        T_1_2EditFieldLabel            matlab.ui.control.Label
        T_1_2EditField                 matlab.ui.control.NumericEditField
        T_1_3EditFieldLabel            matlab.ui.control.Label
        T_1_3EditField                 matlab.ui.control.NumericEditField
        T_2_1EditFieldLabel            matlab.ui.control.Label
        T_2_1EditField                 matlab.ui.control.NumericEditField
        T_2_2EditFieldLabel            matlab.ui.control.Label
        T_2_2EditField                 matlab.ui.control.NumericEditField
        T_2_3EditFieldLabel            matlab.ui.control.Label
        T_2_3EditField                 matlab.ui.control.NumericEditField
        T_3_1EditFieldLabel            matlab.ui.control.Label
        T_3_1EditField                 matlab.ui.control.NumericEditField
        T_3_2EditFieldLabel            matlab.ui.control.Label
        T_3_2EditField                 matlab.ui.control.NumericEditField
        T_3_3EditFieldLabel            matlab.ui.control.Label
        T_3_3EditField                 matlab.ui.control.NumericEditField
        ConfirmButton                  matlab.ui.control.Button
        KgLabel                        matlab.ui.control.Label
        cmLabel                        matlab.ui.control.Label
        cmLabel_2                      matlab.ui.control.Label
        cmLabel_3                      matlab.ui.control.Label
        WLabel                         matlab.ui.control.Label
        LengthEditField                matlab.ui.control.NumericEditField
        LengthEditFieldLabel           matlab.ui.control.Label
        MassEditFieldLabel             matlab.ui.control.Label
        MassEditField                  matlab.ui.control.NumericEditField
        WidthEditFieldLabel            matlab.ui.control.Label
        WidthEditField                 matlab.ui.control.NumericEditField
        HeightEditFieldLabel           matlab.ui.control.Label
        HeightEditField                matlab.ui.control.NumericEditField
        PowerEditFieldLabel            matlab.ui.control.Label
        PowerEditField                 matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        num
        
        t_1_1
        t_1_2
        t_1_3
        t_2_1
        t_2_2
        t_2_3
        t_3_1
        t_3_2
        t_3_3
        mass_mgt;
        m=0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, val, flag)
            app.num = val;
            aux = rem(floor(flag/10^(str2double(app.num)-1)),10);
            
            textLabel = sprintf('Magnetorquer %s - Settings', app.num);
            app.MagnetorquerSettingsLabel.Text = textLabel;
            
            if aux == 1
                v_magt = evalin('base',['V_magt_',app.num]);
                app.VoltageVEditField.Value = v_magt;
                
                lim_x_magt = evalin('base',['Lim_x_magt_',app.num]);
                app.MagneticDipoleSaturationLimitAm2EditField.Value = lim_x_magt;
                
                sat_x_magt = evalin('base',['Sat_x_magt_',app.num]);
                app.SaturationCurrentAEditField.Value = sat_x_magt;
                
                lim_y_magt = evalin('base',['Lim_y_magt_',app.num]);
                app.MagneticDipoleSaturationLimitAm2EditField_2.Value = lim_y_magt;
                
                sat_y_magt = evalin('base',['Sat_y_magt_',app.num]);
                app.SaturationCurrentAEditField_2.Value = sat_y_magt;
                
                lim_z_magt = evalin('base',['Lim_z_magt_',app.num]);
                app.MagneticDipoleSaturationLimitAm2EditField_3.Value = lim_z_magt;
                
                sat_z_magt = evalin('base',['Sat_z_magt_',app.num]);
                app.SaturationCurrentAEditField_3.Value = sat_z_magt;

                
                aux = evalin('base',['T_magt_',app.num]);
                [app.t_1_1,app.t_1_2,app.t_1_3,app.t_2_1,app.t_2_2,app.t_2_3,app.t_3_1,app.t_3_2,app.t_3_3] = deal(aux(1,1),aux(1,2),aux(1,3),aux(2,1),aux(2,2),aux(2,3),aux(3,1),aux(3,2),aux(3,3));
                app.T_1_1EditField.Value = app.t_1_1;
                app.T_1_2EditField.Value = app.t_1_2;
                app.T_1_3EditField.Value = app.t_1_3;
                app.T_2_1EditField.Value = app.t_2_1;
                app.T_2_2EditField.Value = app.t_2_2;
                app.T_2_3EditField.Value = app.t_2_3;
                app.T_3_1EditField.Value = app.t_3_1;
                app.T_3_2EditField.Value = app.t_3_2;
                app.T_3_3EditField.Value = app.t_3_3;
            end
            
            
            v_magt = app.VoltageVEditField.Value;
            assignin('base',['V_magt_',app.num],v_magt)
            
            lim_x_magt = app.MagneticDipoleSaturationLimitAm2EditField.Value;
            assignin('base',['Lim_x_magt_',app.num],lim_x_magt)
            
            sat_x_magt = app.SaturationCurrentAEditField.Value;
            assignin('base',['Sat_x_magt_',app.num],sat_x_magt)
            
            lim_y_magt = app.MagneticDipoleSaturationLimitAm2EditField_2.Value;
            assignin('base',['Lim_y_magt_',app.num],lim_y_magt)
            
            sat_y_magt = app.SaturationCurrentAEditField_2.Value;
            assignin('base',['Sat_y_magt_',app.num],sat_y_magt)
            
            lim_z_magt = app.MagneticDipoleSaturationLimitAm2EditField_3.Value;
            assignin('base',['Lim_z_magt_',app.num],lim_z_magt)
            
            sat_z_magt = app.SaturationCurrentAEditField_3.Value;
            assignin('base',['Sat_z_magt_',app.num],sat_z_magt)
            
            app.t_1_1 = app.T_1_1EditField.Value;
            app.t_1_2 = app.T_1_2EditField.Value;
            app.t_1_3 = app.T_1_3EditField.Value;
            app.t_2_1 = app.T_2_1EditField.Value;
            app.t_2_2 = app.T_2_2EditField.Value;
            app.t_2_3 = app.T_2_3EditField.Value;
            app.t_3_1 = app.T_3_1EditField.Value;
            app.t_3_2 = app.T_3_2EditField.Value;
            app.t_3_3 = app.T_3_3EditField.Value;
            assignin('base',['T_magt_',app.num],[app.t_1_1 app.t_1_2 app.t_1_3; app.t_2_1 app.t_2_2 app.t_2_3; app.t_3_1 app.t_3_2 app.t_3_3])
            
        end

        % Value changed function: VoltageVEditField
        function VoltageVEditFieldValueChanged(app, event)
            v_magt = app.VoltageVEditField.Value;
            assignin('base',['V_magt_',app.num],v_magt)
        end

        % Value changed function: 
        % MagneticDipoleSaturationLimitAm2EditField
        function MagneticDipoleSaturationLimitAm2EditFieldValueChanged(app, event)
            lim_x_magt = app.MagneticDipoleSaturationLimitAm2EditField.Value;
            assignin('base',['Lim_x_magt_',app.num],lim_x_magt)
        end

        % Value changed function: SaturationCurrentAEditField
        function SaturationCurrentAEditFieldValueChanged(app, event)
            sat_x_magt = app.SaturationCurrentAEditField.Value;
            assignin('base',['Sat_x_magt_',app.num],sat_x_magt)
        end

        % Value changed function: 
        % MagneticDipoleSaturationLimitAm2EditField_2
        function MagneticDipoleSaturationLimitAm2EditField_2ValueChanged(app, event)
            lim_y_magt = app.MagneticDipoleSaturationLimitAm2EditField_2.Value;
            assignin('base',['Lim_y_magt_',app.num],lim_y_magt)
        end

        % Value changed function: SaturationCurrentAEditField_2
        function SaturationCurrentAEditField_2ValueChanged(app, event)
            sat_y_magt = app.SaturationCurrentAEditField_2.Value;
            assignin('base',['Sat_y_magt_',app.num],sat_y_magt)
        end

        % Value changed function: 
        % MagneticDipoleSaturationLimitAm2EditField_3
        function MagneticDipoleSaturationLimitAm2EditField_3ValueChanged(app, event)
            lim_z_magt = app.MagneticDipoleSaturationLimitAm2EditField_3.Value;
            assignin('base',['Lim_z_magt_',app.num],lim_z_magt)
        end

        % Value changed function: SaturationCurrentAEditField_3
        function SaturationCurrentAEditField_3ValueChanged(app, event)
            sat_z_magt = app.SaturationCurrentAEditField_3.Value;
            assignin('base',['Sat_z_magt_',app.num],sat_z_magt)
        end

        % Value changed function: T_1_1EditField
        function T_1_1EditFieldValueChanged(app, event)
            app.t_1_1 = app.T_1_1EditField.Value;
            assignin('base',['T_magt_',app.num],[app.t_1_1 app.t_1_2 app.t_1_3; app.t_2_1 app.t_2_2 app.t_2_3; app.t_3_1 app.t_3_2 app.t_3_3])
        end

        % Value changed function: T_1_2EditField
        function T_1_2EditFieldValueChanged(app, event)
            app.t_1_2 = app.T_1_2EditField.Value;
            assignin('base',['T_magt_',app.num],[app.t_1_1 app.t_1_2 app.t_1_3; app.t_2_1 app.t_2_2 app.t_2_3; app.t_3_1 app.t_3_2 app.t_3_3])
        end

        % Value changed function: T_1_3EditField
        function T_1_3EditFieldValueChanged(app, event)
            app.t_1_3 = app.T_1_3EditField.Value;
            assignin('base',['T_magt_',app.num],[app.t_1_1 app.t_1_2 app.t_1_3; app.t_2_1 app.t_2_2 app.t_2_3; app.t_3_1 app.t_3_2 app.t_3_3])
        end

        % Value changed function: T_2_1EditField
        function T_2_1EditFieldValueChanged(app, event)
            app.t_2_1 = app.T_2_1EditField.Value;
            assignin('base',['T_magt_',app.num],[app.t_1_1 app.t_1_2 app.t_1_3; app.t_2_1 app.t_2_2 app.t_2_3; app.t_3_1 app.t_3_2 app.t_3_3])
        end

        % Value changed function: T_2_2EditField
        function T_2_2EditFieldValueChanged(app, event)
            app.t_2_2 = app.T_2_2EditField.Value;
            assignin('base',['T_magt_',app.num],[app.t_1_1 app.t_1_2 app.t_1_3; app.t_2_1 app.t_2_2 app.t_2_3; app.t_3_1 app.t_3_2 app.t_3_3])
        end

        % Value changed function: T_2_3EditField
        function T_2_3EditFieldValueChanged(app, event)
            app.t_2_3 = app.T_2_3EditField.Value;
            assignin('base',['T_magt_',app.num],[app.t_1_1 app.t_1_2 app.t_1_3; app.t_2_1 app.t_2_2 app.t_2_3; app.t_3_1 app.t_3_2 app.t_3_3])
        end

        % Value changed function: T_3_1EditField
        function T_3_1EditFieldValueChanged(app, event)
            app.t_3_1 = app.T_3_1EditField.Value;
            assignin('base',['T_magt_',app.num],[app.t_1_1 app.t_1_2 app.t_1_3; app.t_2_1 app.t_2_2 app.t_2_3; app.t_3_1 app.t_3_2 app.t_3_3])
        end

        % Value changed function: T_3_2EditField
        function T_3_2EditFieldValueChanged(app, event)
            app.t_3_2 = app.T_3_2EditField.Value;
            assignin('base',['T_magt_',app.num],[app.t_1_1 app.t_1_2 app.t_1_3; app.t_2_1 app.t_2_2 app.t_2_3; app.t_3_1 app.t_3_2 app.t_3_3])
        end

        % Value changed function: T_3_3EditField
        function T_3_3EditFieldValueChanged(app, event)
            app.t_3_3 = app.T_3_3EditField.Value;
            assignin('base',['T_magt_',app.num],[app.t_1_1 app.t_1_2 app.t_1_3; app.t_2_1 app.t_2_2 app.t_2_3; app.t_3_1 app.t_3_2 app.t_3_3])
        end

        % Value changed function: MassEditField
        function MassEditFieldValueChanged(app, event)
            app.mass_mgt =app.mass_mgt + app.MassEditField.Value;
            app.m=app.MassEditField.Value;  
            setappdata(0,'mass_mt',app.mass_mgt);
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.XaxisCoilRodLabel = uilabel(app.UIFigure);
            app.XaxisCoilRodLabel.Text = 'X-axis Coil/Rod:';
            app.MagnetorquerSettingsLabel = uilabel(app.UIFigure);
            app.MagnetorquerSettingsLabel.Text = 'Magnetorquer - Settings';
            app.VoltageVEditFieldLabel = uilabel(app.UIFigure);
            app.VoltageVEditFieldLabel.Text = 'Voltage [V]';
            app.VoltageVEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoltageVEditField.ValueChangedFcn = createCallbackFcn(app, @VoltageVEditFieldValueChanged, true);
            app.VoltageVEditField.Value = 5;
            app.MagneticDipoleSaturationLimitAm2Label = uilabel(app.UIFigure);
            app.MagneticDipoleSaturationLimitAm2Label.Text = {'Magnetic Dipole Saturation '; 'Limit [Am^2] '};
            app.MagneticDipoleSaturationLimitAm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.MagneticDipoleSaturationLimitAm2EditField.ValueChangedFcn = createCallbackFcn(app, @MagneticDipoleSaturationLimitAm2EditFieldValueChanged, true);
            app.MagneticDipoleSaturationLimitAm2EditField.Value = 0.13;
            app.SaturationCurrentAEditFieldLabel = uilabel(app.UIFigure);
            app.SaturationCurrentAEditFieldLabel.Text = 'Saturation Current [A]';
            app.SaturationCurrentAEditField = uieditfield(app.UIFigure, 'numeric');
            app.SaturationCurrentAEditField.ValueChangedFcn = createCallbackFcn(app, @SaturationCurrentAEditFieldValueChanged, true);
            app.SaturationCurrentAEditField.Value = 0.064;
            app.YaxisCoilRodLabel = uilabel(app.UIFigure);
            app.YaxisCoilRodLabel.Text = 'Y-axis Coil/Rod:';
            app.MagneticDipoleSaturationLimitAm2EditField_2Label = uilabel(app.UIFigure);
            app.MagneticDipoleSaturationLimitAm2EditField_2Label.Text = {'Magnetic Dipole Saturation '; 'Limit [Am^2] '};
            app.MagneticDipoleSaturationLimitAm2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.MagneticDipoleSaturationLimitAm2EditField_2.ValueChangedFcn = createCallbackFcn(app, @MagneticDipoleSaturationLimitAm2EditField_2ValueChanged, true);
            app.MagneticDipoleSaturationLimitAm2EditField_2.Value = 0.48;
            app.SaturationCurrentAEditField_2Label = uilabel(app.UIFigure);
            app.SaturationCurrentAEditField_2Label.Text = 'Saturation Current [A]';
            app.SaturationCurrentAEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SaturationCurrentAEditField_2.ValueChangedFcn = createCallbackFcn(app, @SaturationCurrentAEditField_2ValueChanged, true);
            app.SaturationCurrentAEditField_2.Value = 0.08;
            app.ZaxisCoilRodLabel = uilabel(app.UIFigure);
            app.ZaxisCoilRodLabel.Text = 'Z-axis Coil/Rod:';
            app.MagneticDipoleSaturationLimitAm2EditField_3Label = uilabel(app.UIFigure);
            app.MagneticDipoleSaturationLimitAm2EditField_3Label.Text = {'Magnetic Dipole Saturation '; 'Limit [Am^2] '};
            app.MagneticDipoleSaturationLimitAm2EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.MagneticDipoleSaturationLimitAm2EditField_3.ValueChangedFcn = createCallbackFcn(app, @MagneticDipoleSaturationLimitAm2EditField_3ValueChanged, true);
            app.MagneticDipoleSaturationLimitAm2EditField_3.Value = 0.48;
            app.SaturationCurrentAEditField_3Label = uilabel(app.UIFigure);
            app.SaturationCurrentAEditField_3Label.Text = 'Saturation Current [A]';
            app.SaturationCurrentAEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.SaturationCurrentAEditField_3.ValueChangedFcn = createCallbackFcn(app, @SaturationCurrentAEditField_3ValueChanged, true);
            app.SaturationCurrentAEditField_3.Value = 0.08;
            app.DirectionCossineMatrixfrombodycoordinatesLabel = uilabel(app.UIFigure);
            app.DirectionCossineMatrixfrombodycoordinatesLabel.Text = 'Transformation Matrix:';
            app.T_1_1EditFieldLabel = uilabel(app.UIFigure);
            app.T_1_1EditFieldLabel.Text = 'T_1_1';
            app.T_1_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.T_1_1EditField.ValueChangedFcn = createCallbackFcn(app, @T_1_1EditFieldValueChanged, true);
            app.T_1_1EditField.Value = 1;
            app.T_1_2EditFieldLabel = uilabel(app.UIFigure);
            app.T_1_2EditFieldLabel.Text = 'T_1_2';
            app.T_1_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.T_1_2EditField.ValueChangedFcn = createCallbackFcn(app, @T_1_2EditFieldValueChanged, true);
            app.T_1_3EditFieldLabel = uilabel(app.UIFigure);
            app.T_1_3EditFieldLabel.Text = 'T_1_3';
            app.T_1_3EditField = uieditfield(app.UIFigure, 'numeric');
            app.T_1_3EditField.ValueChangedFcn = createCallbackFcn(app, @T_1_3EditFieldValueChanged, true);
            app.T_2_1EditFieldLabel = uilabel(app.UIFigure);
            app.T_2_1EditFieldLabel.Text = 'T_2_1';
            app.T_2_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.T_2_1EditField.ValueChangedFcn = createCallbackFcn(app, @T_2_1EditFieldValueChanged, true);
            app.T_2_2EditFieldLabel = uilabel(app.UIFigure);
            app.T_2_2EditFieldLabel.Text = 'T_2_2';
            app.T_2_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.T_2_2EditField.ValueChangedFcn = createCallbackFcn(app, @T_2_2EditFieldValueChanged, true);
            app.T_2_2EditField.Value = 1;
            app.T_2_3EditFieldLabel = uilabel(app.UIFigure);
            app.T_2_3EditFieldLabel.Text = 'T_2_3';
            app.T_2_3EditField = uieditfield(app.UIFigure, 'numeric');
            app.T_2_3EditField.ValueChangedFcn = createCallbackFcn(app, @T_2_3EditFieldValueChanged, true);
            app.T_3_1EditFieldLabel = uilabel(app.UIFigure);
            app.T_3_1EditFieldLabel.Text = 'T_3_1';
            app.T_3_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.T_3_1EditField.ValueChangedFcn = createCallbackFcn(app, @T_3_1EditFieldValueChanged, true);
            app.T_3_2EditFieldLabel = uilabel(app.UIFigure);
            app.T_3_2EditFieldLabel.Text = 'T_3_2';
            app.T_3_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.T_3_2EditField.ValueChangedFcn = createCallbackFcn(app, @T_3_2EditFieldValueChanged, true);
            app.T_3_3EditFieldLabel = uilabel(app.UIFigure);
            app.T_3_3EditFieldLabel.Text = 'T_3_3';
            app.T_3_3EditField = uieditfield(app.UIFigure, 'numeric');
            app.T_3_3EditField.ValueChangedFcn = createCallbackFcn(app, @T_3_3EditFieldValueChanged, true);
            app.T_3_3EditField.Value = 1;
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.KgLabel = uilabel(app.UIFigure);
            app.KgLabel.Text = 'Kg';
            app.cmLabel = uilabel(app.UIFigure);
            app.cmLabel.Text = 'cm';
            app.cmLabel_2 = uilabel(app.UIFigure);
            app.cmLabel_2.Text = 'cm';
            app.cmLabel_3 = uilabel(app.UIFigure);
            app.cmLabel_3.Text = 'cm';
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Text = 'W';
            app.LengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthEditFieldLabel = uilabel(app.UIFigure);
            app.LengthEditFieldLabel.Text = 'Length';
            app.MassEditFieldLabel = uilabel(app.UIFigure);
            app.MassEditFieldLabel.Text = ' Mass';
            app.MassEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassEditField.ValueChangedFcn = createCallbackFcn(app, @MassEditFieldValueChanged, true);
            app.WidthEditFieldLabel = uilabel(app.UIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerEditFieldLabel = uilabel(app.UIFigure);
            app.PowerEditFieldLabel.Text = ' Power';
            app.PowerEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Magnetorquer_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end