classdef attitude < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ReferenceFrameDropDownLabel  matlab.ui.control.Label
        ReferenceFrameDropDown       matlab.ui.control.DropDown
        headingEditFieldLabel        matlab.ui.control.Label
        headingEditField             matlab.ui.control.NumericEditField
        attitudeEditFieldLabel       matlab.ui.control.Label
        attitudeEditField            matlab.ui.control.NumericEditField
        bankEditFieldLabel           matlab.ui.control.Label
        bankEditField                matlab.ui.control.NumericEditField
        d0dtEditFieldLabel           matlab.ui.control.Label
        d0dtEditField                matlab.ui.control.NumericEditField
        d0dtLabel                    matlab.ui.control.Label
        d0dtEditField_2              matlab.ui.control.NumericEditField
        d0dtLabel_2                  matlab.ui.control.Label
        d0dtEditField_3              matlab.ui.control.NumericEditField
        radsLabel                    matlab.ui.control.Label
        radsLabel_2                  matlab.ui.control.Label
        radsLabel_3                  matlab.ui.control.Label
        SaveButton                   matlab.ui.control.Button
        AttitudeLabel                matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        q1;
        q2;
        q3;
        q4;
        roll;
        pitch;
        yaw;
        tht0dz;
        btt0dz;
        gmt0dz;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, aux)
              if aux > 0
                aux2 = evalin('base','roll');
                [app.roll] = deal(aux2(1,1));
                app.headingEditField.Value = app.roll;
                
                aux2 = evalin('base','pitch');
                [app.pitch] = deal(aux2(1,1));
                app.attitudeEditField.Value = app.pitch;
                
                aux2 = evalin('base','yaw');
                [app.yaw] = deal(aux2(1,1));
                app.bankEditField.Value = app.yaw;
                
                                
                aux2 = evalin('base','t0dz');
                [app.tht0dz,app.btt0dz,app.gmt0dz] = deal(aux2(1,1),aux2(1,2),aux2(1,3));
                app.d0dtEditField.Value = app.tht0dz;
                app.d0dtEditField_2.Value = app.btt0dz;
                app.d0dtEditField_3.Value = app.gmt0dz;
              end
            
              app.q1 = 1;
              app.q2 = 0;
              app.q3 = 0;
              app.q4 = 0;
              assignin('base','qt',[app.q1,app.q2,app.q3,app.q4])
              
              app.roll = app.headingEditField.Value;
              assignin('base','roll',[app.roll])
              
              app.pitch = app.attitudeEditField.Value;
              assignin('base','pitch',[app.pitch])
              
              app.yaw = app.bankEditField.Value;
              assignin('base','yaw',[app.yaw])
              
             app.tht0dz = app.d0dtEditField.Value;
             app.btt0dz = app.d0dtEditField_2.Value;
             app.gmt0dz = app.d0dtEditField_3.Value;
             assignin('base','t0dz',[app.tht0dz,app.btt0dz,app.gmt0dz])
        end

        % Value changed function: headingEditField
        function headingEditFieldValueChanged(app, event)
            app.roll = app.headingEditField.Value;
            assignin('base','roll',[app.roll])           
        end

        % Value changed function: attitudeEditField
        function attitudeEditFieldValueChanged(app, event)
            app.pitch = app.attitudeEditField.Value;
            assignin('base','pitch',[app.pitch])
        end

        % Value changed function: bankEditField
        function bankEditFieldValueChanged(app, event)
            app.yaw = app.bankEditField.Value;
            assignin('base','yaw',[app.yaw])
        end

        % Value changed function: d0dtEditField
        function d0dtEditFieldValueChanged(app, event)
            app.tht0dz = app.d0dtEditField.Value;
            assignin('base','t0dz',[app.tht0dz,app.btt0dz,app.gmt0dz])
        end

        % Value changed function: d0dtEditField_2
        function d0dtEditField_2ValueChanged(app, event)
             app.btt0dz = app.d0dtEditField_2.Value;
             assignin('base','t0dz',[app.tht0dz,app.btt0dz,app.gmt0dz])
        end

        % Value changed function: d0dtEditField_3
        function d0dtEditField_3ValueChanged(app, event)
             app.gmt0dz = app.d0dtEditField_3.Value;
             assignin('base','t0dz',[app.tht0dz,app.btt0dz,app.gmt0dz])
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            eutoqt(app.roll,app.pitch,app.yaw);
            
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReferenceFrameDropDownLabel = uilabel(app.UIFigure);
            app.ReferenceFrameDropDownLabel.Text = 'Reference Frame';
            app.ReferenceFrameDropDown = uidropdown(app.UIFigure);
            app.ReferenceFrameDropDown.Items = {'Body reference frame'};
            app.ReferenceFrameDropDown.Value = 'Body reference frame';
            app.headingEditFieldLabel = uilabel(app.UIFigure);
            app.headingEditFieldLabel.Text = 'heading';
            app.headingEditField = uieditfield(app.UIFigure, 'numeric');
            app.headingEditField.ValueChangedFcn = createCallbackFcn(app, @headingEditFieldValueChanged, true);
            app.attitudeEditFieldLabel = uilabel(app.UIFigure);
            app.attitudeEditFieldLabel.Text = 'attitude';
            app.attitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.attitudeEditField.ValueChangedFcn = createCallbackFcn(app, @attitudeEditFieldValueChanged, true);
            app.bankEditFieldLabel = uilabel(app.UIFigure);
            app.bankEditFieldLabel.Text = 'bank';
            app.bankEditField = uieditfield(app.UIFigure, 'numeric');
            app.bankEditField.ValueChangedFcn = createCallbackFcn(app, @bankEditFieldValueChanged, true);
            app.bankEditField.Value = 180;
            app.d0dtEditFieldLabel = uilabel(app.UIFigure);
            app.d0dtEditFieldLabel.Text = 'dθ0/dt';
            app.d0dtEditField = uieditfield(app.UIFigure, 'numeric');
            app.d0dtEditField.ValueChangedFcn = createCallbackFcn(app, @d0dtEditFieldValueChanged, true);
            app.d0dtEditField.Value = 0.2;
            app.d0dtLabel = uilabel(app.UIFigure);
            app.d0dtLabel.Text = 'dβ0/dt';
            app.d0dtEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.d0dtEditField_2.ValueChangedFcn = createCallbackFcn(app, @d0dtEditField_2ValueChanged, true);
            app.d0dtEditField_2.Value = 0.2;
            app.d0dtLabel_2 = uilabel(app.UIFigure);
            app.d0dtLabel_2.Text = 'dγ0/dt';
            app.d0dtEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.d0dtEditField_3.ValueChangedFcn = createCallbackFcn(app, @d0dtEditField_3ValueChanged, true);
            app.d0dtEditField_3.Value = 0.2;
            app.radsLabel = uilabel(app.UIFigure);
            app.radsLabel.Text = 'rad/s';
            app.radsLabel_2 = uilabel(app.UIFigure);
            app.radsLabel_2.Text = 'rad/s';
            app.radsLabel_3 = uilabel(app.UIFigure);
            app.radsLabel_3.Text = 'rad/s';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AttitudeLabel = uilabel(app.UIFigure);
            app.AttitudeLabel.Text = 'Attitude';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'º';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'º';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'º';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = attitude(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end